classdef LensCalibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        LensSliderLabel              matlab.ui.control.Label
        LensSlider                   matlab.ui.control.Slider
        CalculateButton              matlab.ui.control.Button
        LensSpinnerLabel             matlab.ui.control.Label
        LensSpinner                  matlab.ui.control.Spinner
        UIAxes2                      matlab.ui.control.UIAxes
        XShiftSpinnerLabel           matlab.ui.control.Label
        XShiftSpinner                matlab.ui.control.Spinner
        YShiftSpinnerLabel           matlab.ui.control.Label
        YShiftSpinner                matlab.ui.control.Spinner
        PhaseMaskShiftLabel          matlab.ui.control.Label
        XShiftSliderLabel            matlab.ui.control.Label
        XShiftSlider                 matlab.ui.control.Slider
        YShiftSliderLabel            matlab.ui.control.Label
        YShiftSlider                 matlab.ui.control.Slider
        UITable                      matlab.ui.control.Table
        Label                        matlab.ui.control.Label
        GerchbergSaxtonIterationsSpinnerLabel  matlab.ui.control.Label
        GerchbergSaxtonIterationsSpinner  matlab.ui.control.Spinner
        ResetButton                  matlab.ui.control.Button
        LoadFromFileButton           matlab.ui.control.Button
        CheckBox                     matlab.ui.control.CheckBox
        CheckBox_2                   matlab.ui.control.CheckBox
        CheckBox_3                   matlab.ui.control.CheckBox
        CheckBox_4                   matlab.ui.control.CheckBox
        CheckBox_5                   matlab.ui.control.CheckBox
        ZoomButton                   matlab.ui.control.StateButton
        ZoomButton_2                 matlab.ui.control.StateButton
        CalculationModeSwitchLabel   matlab.ui.control.Label
        CalculationModeSwitch        matlab.ui.control.Switch
        SaveButton                   matlab.ui.control.Button
        OnButton                     matlab.ui.control.StateButton
        Switch                       matlab.ui.control.Switch
        AstigmatismCorrectionPanel   matlab.ui.container.Panel
        EllipticitylongaxisshortaxisEditFieldLabel  matlab.ui.control.Label
        EllipticitylongaxisshortaxisEditField  matlab.ui.control.NumericEditField
        EllipticitytolerenceSpinnerLabel  matlab.ui.control.Label
        EllipticitytolerenceSpinner  matlab.ui.control.Spinner
        EllipticityLabel             matlab.ui.control.Label
        UniformityLabel              matlab.ui.control.Label
        NumberofaxesSpinnerLabel     matlab.ui.control.Label
        NumberofaxesSpinner          matlab.ui.control.Spinner
        LinemaximatolerenceSpinnerLabel  matlab.ui.control.Label
        LinemaximatolerenceSpinner   matlab.ui.control.Spinner
        ZenikepolynomialcoefficientsLabel  matlab.ui.control.Label
        CalculateAllButton           matlab.ui.control.Button
        C1_n1CheckBox                matlab.ui.control.CheckBox
        C1_1CheckBox                 matlab.ui.control.CheckBox
        C2_n2CheckBox                matlab.ui.control.CheckBox
        C2_0CheckBox                 matlab.ui.control.CheckBox
        C3_n3CheckBox                matlab.ui.control.CheckBox
        C3_n1CheckBox                matlab.ui.control.CheckBox
        C4_n4CheckBox                matlab.ui.control.CheckBox
        C4_n2CheckBox                matlab.ui.control.CheckBox
        C2_2CheckBox                 matlab.ui.control.CheckBox
        C3_1CheckBox                 matlab.ui.control.CheckBox
        C3_3CheckBox                 matlab.ui.control.CheckBox
        C4_0CheckBox                 matlab.ui.control.CheckBox
        C4_4CheckBox                 matlab.ui.control.CheckBox
        TipTiltLabel                 matlab.ui.control.Label
        AstigmatismLabel             matlab.ui.control.Label
        ComaTrefoilLabel             matlab.ui.control.Label
        SphericalLabel               matlab.ui.control.Label
        SelectCoefficientsButton     matlab.ui.control.Button
        SelectRangeButton            matlab.ui.control.Button
        C4_2CheckBox                 matlab.ui.control.CheckBox
        CalculateButton_2            matlab.ui.control.Button
        LiveImagePathEditFieldLabel  matlab.ui.control.Label
        LiveImagePathEditField       matlab.ui.control.EditField
        OutputFolderEditFieldLabel   matlab.ui.control.Label
        OutputFolderEditField        matlab.ui.control.EditField
        ChangeButton                 matlab.ui.control.Button
        ChangeButton_2               matlab.ui.control.Button
        IgnoreCentralSpotCheckBox    matlab.ui.control.CheckBox
        ConsideranROICheckBox        matlab.ui.control.CheckBox
        SelectaRegiontoMinimizeNoiseCheckBox  matlab.ui.control.CheckBox
        ManuallydrawringsCheckBox    matlab.ui.control.CheckBox
        TipSliderLabel               matlab.ui.control.Label
        TipSlider                    matlab.ui.control.Slider
        TiltSliderLabel              matlab.ui.control.Label
        TiltSlider                   matlab.ui.control.Slider
        Astigmatism1SliderLabel      matlab.ui.control.Label
        Astigmatism1Slider           matlab.ui.control.Slider
        Astigmatism2SliderLabel      matlab.ui.control.Label
        Astigmatism2Slider           matlab.ui.control.Slider
        Trefoil1SliderLabel          matlab.ui.control.Label
        Trefoil1Slider               matlab.ui.control.Slider
        Coma1SliderLabel             matlab.ui.control.Label
        Coma1Slider                  matlab.ui.control.Slider
        Coma2SliderLabel             matlab.ui.control.Label
        Coma2Slider                  matlab.ui.control.Slider
        Trefoil2SliderLabel          matlab.ui.control.Label
        Trefoil2Slider               matlab.ui.control.Slider
        Spherical1SliderLabel        matlab.ui.control.Label
        Spherical1Slider             matlab.ui.control.Slider
        Spherical2SliderLabel        matlab.ui.control.Label
        Spherical2Slider             matlab.ui.control.Slider
        Spherical3SliderLabel        matlab.ui.control.Label
        Spherical3Slider             matlab.ui.control.Slider
        Spherical4SliderLabel        matlab.ui.control.Label
        Spherical4Slider             matlab.ui.control.Slider
        EvaluatePointsButton         matlab.ui.control.Button
        ArrayofpointsButton          matlab.ui.control.Button
        StartEditFieldLabel          matlab.ui.control.Label
        StartEditField               matlab.ui.control.NumericEditField
        StopEditFieldLabel           matlab.ui.control.Label
        StopEditField                matlab.ui.control.NumericEditField
        SizeEditFieldLabel           matlab.ui.control.Label
        SizeEditField                matlab.ui.control.NumericEditField
        EvaluateVorticesButton       matlab.ui.control.Button
        CalibrateButton              matlab.ui.control.Button
        SelectTargetsButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
        xVal % x coordinate of the reference point in the image plane
        yVal % y coordinate of the reference point in the image plane
        lens % focal length of the virtual lens
        vortexMode % array of vortex modes of the beamlets
        mask % phase mask (before adding virtual lens and cropping, calculated for a double-sized SLM)
        mask_v % phase mask (after adding virtual lens and before cropping, calculated for a double-sized SLM)
        dll_name % name of the dll library used to communicate with the SLM
        board_number % identifier for the connected SLM
        xshifts % array of x-offsets of each beamlet with respect to the reference point  (in mm)
        yshifts % array of y-offsets of each beamlet with respect to the reference point  (in mm)
        xspm % number of pixels by which the phase mask is shifted in x direction
        yspm % number of pixels by which the phase mask is shifted in x direction
        slmdim % length of the SLM surface in pixels
        gsitr % number of Gerchberg-Saxton iterations used for calculation
        slm_on % Flag to indicate whether the SLM is turned on
        mask_h % handle to phase mask image
        intensity_h % handle to intensity image
        mult_factor 
        target_mult_factor
        zoom_factor
        highPixel_mode
        display_intensity
        liveimg_path % directory to find images capured during an experiment
        output_path % directory to find images capured during an experiment
        ao_automated
        central_spot
        selected_ROI
        background_ROI
        manual_ring
        zernike_init
        intensity_threshold
        lineCoordinates
        imgType % tif / tiff
        cameraToSlm % transfer function for calibration (camera to SLM)
    end

    methods (Access = private)
    
        function coeff = Astigmatism2(app,imgFolder,liveimg_savepath,imType,numBeamlets)
            coeff = 0;
            er = Inf;
            % Astigmatism 2
            ers = [];
            
            for itr = -0.02:0.002:0.02
                app.Astigmatism1Slider.Value = itr;
                app.TipSliderValueChanged;
                er_t = errorFunAO(imgFolder,liveimg_savepath,imType,numBeamlets);
                ers = [ers, er_t];
                if er_t < er
                    er = er_t;
                    coeff = itr;
                end
            end
            figure(1);
            plot(-0.02:0.002:0.02, ers);
            app.Astigmatism1Slider.Value = coeff;
            
        end
        
        function coeff = Astigmatism4(app,imgFolder,liveimg_savepath,imType,numBeamlets)
            coeff = 0;
            er = Inf;
            % Astigmatism 4
            ers = [];
            
            for itr = -0.02:0.002:0.02
                app.Astigmatism2Slider.Value = itr;
                app.TipSliderValueChanged;
                er_t = errorFunAO(imgFolder,liveimg_savepath,imType,numBeamlets);
                ers = [ers, er_t];
                if er_t < er
                    er = er_t;
                    coeff = itr;
                end
            end
            figure(2);
            plot(-0.02:0.002:0.02, ers);
            app.Astigmatism2Slider.Value = coeff;
        end
        
        function coeff = Coma(app,imgFolder,liveimg_savepath,imType,numBeamlets)
            coeff = 0;
            er = Inf;
            % Coma
            ers = [];
            for itr = -0.02:0.002:0.02
                app.Coma1Slider.Value = itr;
                app.TipSliderValueChanged;
                er_t = errorFunAO(imgFolder,liveimg_savepath,imType,numBeamlets);
                ers = [ers, er_t];
                if er_t < er
                    er = er_t;
                    coeff = itr;
                end
            end
            figure(3);
            plot(-0.02:0.002:0.02, ers);
            app.Coma1Slider.Value = coeff;
        end
        
        function coeff = Spherical2(app,imgFolder,liveimg_savepath,imType,numBeamlets)
            coeff = 0;
            er = Inf;
            % Spherical 2
            for itr = -0.02:0.002:0.02
                app.Spherical2Slider.Value = itr;
                app.TipSliderValueChanged;
                er_t = errorFunAO(imgFolder,liveimg_savepath,imType,numBeamlets);
                if er_t < er
                    er = er_t;
                    coeff = itr;
                end
            end
            app.Spherical2Slider.Value = coeff;
            
        end
        
        function coeff = Spherical4(app,imgFolder,liveimg_savepath,imType,numBeamlets)
            coeff = 0;
            er = Inf;
            % Spherical 4
            for itr = -0.02:0.002:0.02
                app.Spherical4Slider.Value = itr;
                app.TipSliderValueChanged;
                er_t = errorFunAO(imgFolder,liveimg_savepath,imType,numBeamlets);
                if er_t < er
                    er = er_t;
                    coeff = itr;
                end
            end
            app.Spherical4Slider.Value = coeff;
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dll_name, board_number, SLM_ON)
            app.mult_factor = 1/3; % scaling factor for the number of pixels (pixels in test toggle) 1536/3 - before cropping
            app.target_mult_factor = 1/4; % scaling factor for the phase mask after cropping
            app.zoom_factor = 1; % display of the beamlet plane
            app.xVal = 0;
            app.yVal = 0;
            app.lens = 0;
            app.highPixel_mode = 0; % high pixel mode --> 1, otherwise --> 0
            app.vortexMode = 10;
            app.mask = zeros(app.mult_factor*1536,app.mult_factor*1536);
            app.mask_v = zeros(app.mult_factor*1536,app.mult_factor*1536);
            app.dll_name = dll_name;
            app.board_number = board_number;
            app.xshifts = 0; % column array
            app.yshifts = 0;
            app.xspm = 0;
            app.yspm = 0;
            app.slmdim = 1536;
            app.UITable.Data = [0 0 -1; 0 0 -1; 0 0 -1; 0 0 -1; 0 0 -1;0 0 -1; 0 0 -1; 0 0 -1; 0 0 -1; 0 0 -1];
            app.gsitr = 3;
            app.slm_on = SLM_ON;
            app.mask_h = imshow(zeros(app.mult_factor*1536,app.mult_factor*1536),'Parent', app.UIAxes);
            app.intensity_h = imshow(zeros(app.mult_factor*app.zoom_factor*1536,app.mult_factor*app.zoom_factor*1536),'Parent', app.UIAxes2);
            app.display_intensity = 1;
            app.liveimg_path = 'C:\Users\Sauron\Desktop\BK Testing\06-16-21 AO test - convergence\';
            app.LiveImagePathEditField.Value = app.liveimg_path;
            app.output_path = 'C:\Users\Sauron\Desktop\BK Testing\New Folder\';
            app.OutputFolderEditField.Value = app.output_path;
            app.ao_automated = 0;
            app.central_spot = [];
            app.selected_ROI = [];
            app.background_ROI = [];
            app.zernike_init = zeros(1,14);
            app.manual_ring = [];
            app.intensity_threshold = 0;
            app.lineCoordinates = [];
            app.imgType = '*.tif';
            app.cameraToSlm = [];
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

            app.LensSpinner.Value = -1*app.lens;
            
            d = app.UITable.Data;
            xoff = [];
            yoff = [];
            vm = [];
            selections = [app.CheckBox.Value,app.CheckBox_2.Value,app.CheckBox_3.Value,app.CheckBox_4.Value,app.CheckBox_5.Value];
            
%             read data from the table
            for itr = 1:10
%                 if selections(itr)  %050421 DZ consider only the selected rows in the table
                    if d(itr,3) ~= -1
                        xoff = [xoff; d(itr,1)/1000]; % convert um to mm, need to be calibrated
                        yoff = [yoff; d(itr,2)/1000];
                        vm = [vm, d(itr,3)]; % vortex modes
                    end
%                 end 
            end

            % Array of Points
%             start = app.StartEditField.Value;
%             stop = app.StopEditField.Value;
%             size = app.SizeEditField.Value;
%             
%             xVals = linspace(start,stop, size);
%             yVals = linspace(start,stop, size);
%             for itr = 1:size
%                 for j = 1: size
%                     xoff = [xoff; xVals(itr)/1000]; % convert um to mm, need to be calibrated
%                     yoff = [yoff; yVals(j)/1000];
%                     vm = [vm, 0]; % vortex modes
%                 end 
%             end
            
            app.vortexMode = vm;
            app.xshifts = xoff;
            app.yshifts = yoff;
            
            opts=dhot_opts();
            opts.vortex_modes= app.vortexMode; 
            opts.niters = app.gsitr;
            opts.lens=app.lens;
            opts.slm_dims = [1536 1536]*app.mult_factor;
            
            x = app.xVal/(0.5*app.slmdim); % convert to [-1, 1] range
            y = app.yVal/(0.5*app.slmdim); % convert to [-1, 1] range
            
            P = [x + app.xshifts, y + app.yshifts];
            
            tstart = tic;
            [m, IMG]=dhot(P, opts, app.display_intensity);
            t = toc(tstart);
            fprintf('Time taken for dhot calculations before updating the display is %.2f seconds \n',t);
            
            app.xspm = app.YShiftSpinner.Value;
            app.yspm = app.XShiftSpinner.Value;
            
            app.mask = m;
            
            % 05/09/21 Dulara moved this section to the GUI code for speeding up the calculations
            m = add_virtualLens(app.mask, opts);
            app.mask_v = m;
            
            ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);
            ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
            msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
%             msk = m;
            
%             imshow(msk,'Parent', app.UIAxes);
            app.mask_h.CData = msk;
            
            t = toc(tstart);
            fprintf('Time taken until after updating the phase mask display in the GUI is %.2f seconds \n',t);
            
            
%             if low < high
%                 imshow(IMG,'Parent', app.UIAxes2,'DisplayRange',[low,high]); % DZ 04/26/21 (set limits for better visualization)
%             else
%                 imshow(IMG,'Parent', app.UIAxes2,'DisplayRange',[low,prctile(IMG(:),100)]); % DZ 05/09/21 to handle the error of having (low = high)
%             end


            replication = 1/app.target_mult_factor;
            M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
            imwrite(double(M), 'currentMask.tif');
            
            phaseMask = imread('currentMask.tif'); % uint8
            
            t = toc(tstart); 
            
            fprintf('Total time taken for calculations before sending the phase mask to SLM is %.2f seconds \n',t);
            
            if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established
                calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM
            end
            
            t = toc(tstart); 
            
            fprintf('Total time taken until after sending the phase mask to SLM is %.2f seconds \n',t);


            if app.display_intensity
                if app.Switch.Value == "Simulated"
                    low = 500; % lower threshold for grayscale display of the intensity
                    high = prctile(IMG(:),99.9999); % DZ 05/13/21 to have a more generalized upper threshold
                    if high < low
                        low = 0;
                    end
                
                    IM = mat2gray(IMG,double([low,high])); % DZ 04/26/21 (set limits for better visualization)            
                
                               
                    crop1 = app.mult_factor*app.slmdim*(0.5 - app.zoom_factor/2); % DZ 05/13/21 only show the middle region of the intensity plane for better visualization
                    crop2 = app.mult_factor*app.slmdim*(0.5 + app.zoom_factor/2);
                    
                    imwrite(IM,'fullSizetheoriticalPattern.tif'); % DZ 01/21/22
                    I = IM(crop1 + 1 : crop2, crop1 + 1 : crop2); % only show 1/6 th of the intensity plane for better visualization
                    theoriticalIntensity = IM(1 :app.mult_factor*app.slmdim, 1 : app.mult_factor*app.slmdim); % double check
                    imwrite(I, 'currentSimulation.tif');
                    imwrite(theoriticalIntensity, 'theoriticalIntensity.tif');
                    disp('saved');
                else
                    tiffiles = dir([app.liveimg_path, '*.tiff']);
                    I = imread([app.liveimg_path tiffiles(end).name]); 
                    I = mat2gray(I, double([0,255]));
                    
                end
                app.intensity_h.CData = I;
            end
            
%             app.TipSliderValueChanged;
            t = toc(tstart);
            fprintf('Time taken until after updating the beamlet display in the GUI is %.2f seconds \n',t);
            filename = 'testResults.txt';
            fid = fopen(filename, 'wt');
            fprintf(fid, '%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\n', 'x_center','y_center','xshift', 'yshift', 'lens', 'vortexMode','PM_xshift','PM_yshift','highPixel_mode', 'ellipticity', 'ellipticity_tol', 'num_Axis', 'C2_n2','C2_2','C4_n2','C4_2');  % header
            fclose(fid);
            
        end

        % Value changed function: LensSlider
        function LensSliderValueChanged(app, event)
            tst = tic;
            app.lens = -1*app.LensSlider.Value;
            app.LensSpinner.Value = -1*app.lens;
            
            opts=dhot_opts();
            opts.vortex_modes=app.vortexMode; 
            opts.niters = app.gsitr;
            opts.lens=app.lens;
            opts.slm_dims = [1536 1536]*app.mult_factor;
            
            app.xspm = app.YShiftSpinner.Value;
            app.yspm = app.XShiftSpinner.Value;
            
%             IMG = abs(fftshift(fft2(exp(1i*app.mask*2*pi)))); % intensity at the object plane, load the already calculated double-sized phase mask (without virtual lens)
            
            % 05/09/21 Dulara moved this section to the GUI code for speeding up the calculations
            m = add_virtualLens(app.mask, opts);
            app.mask_v = m;
            
            ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);
            ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
            msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM

            app.mask_h.CData = msk;            
                                    
            replication = 1/app.target_mult_factor;
            M = repelem(msk,replication,replication);
            imwrite(double(M), 'currentMask.tif');
            phaseMask = imread('currentMask.tif'); % uint8 (default value)
            
            if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established
                calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM
            end
            
            if app.display_intensity
                if app.Switch.Value == "Simulated"
                    IMG = abs(fftshift(fft2(exp(1i*app.mask*2*pi)))); % intensity at the object plane, load the already calculated double-sized phase mask (without virtual lens)                
                    low = 500; % lower threshold for grayscale display of the intensity
                    high = prctile(IMG(:),99.9999); % DZ 05/13/21 to have a more generalized upper threshold
                    if high < low
                        low = 0;
                    end
                
                    IM = mat2gray(IMG,double([low,high])); % DZ 04/26/21 (set limits for better visualization)            
                
                               
                    crop1 = app.mult_factor*app.slmdim*(0.5 - app.zoom_factor/2); % DZ 05/13/21 only show the middle region of the intensity plane for better visualization
                    crop2 = app.mult_factor*app.slmdim*(0.5 + app.zoom_factor/2);
                
                    I = IM(crop1 + 1 : crop2, crop1 + 1 : crop2); % only show 1/6 th of the intensity plane for better visualization
                else
                    tiffiles = dir([app.liveimg_path, app.imgType]);
                    I = imread([app.liveimg_path tiffiles(end).name]); 
                    I = mat2gray(I, double([0,255]));
                    
                end
                app.intensity_h.CData = I;
            end
            
            toc(tst)
        end

        % Value changed function: LensSpinner
        function LensSpinnerValueChanged(app, event)
            
            app.lens = -1*app.LensSpinner.Value;
            app.LensSlider.Value = -1*app.lens;

            opts=dhot_opts();
            opts.vortex_modes= app.vortexMode; 
            opts.niters = app.gsitr;
            opts.lens=app.lens;
            opts.slm_dims = [1536 1536]*app.mult_factor;
            
            app.xspm = app.YShiftSpinner.Value;
            app.yspm = app.XShiftSpinner.Value;            
                       
            % 05/09/21 Dulara moved this section to the GUI code for speeding up the calculations
            m = add_virtualLens(app.mask, opts);
            app.mask_v = m;
            
            ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);
            ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
            msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM

            app.mask_h.CData = msk;
                        
            replication = 1/app.target_mult_factor;
            M = repelem(msk,replication,replication);
            imwrite(double(M), 'currentMask.tif');
            phaseMask = imread('currentMask.tif'); % uint8
            
            if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established
                calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM
            end
            
            if app.display_intensity
                if app.Switch.Value == "Simulated"
                    IMG = abs(fftshift(fft2(exp(1i*app.mask*2*pi)))); % intensity at the object plane, load the already calculated double-sized phase mask (without virtual lens)                
                    low = 500; % lower threshold for grayscale display of the intensity
                    high = prctile(IMG(:),99.9999); % DZ 05/13/21 to have a more generalized upper threshold
                    if high < low
                        low = 0;
                    end
                
                    IM = mat2gray(IMG,double([low,high])); % DZ 04/26/21 (set limits for better visualization)            
                
                               
                    crop1 = app.mult_factor*app.slmdim*(0.5 - app.zoom_factor/2); % DZ 05/13/21 only show the middle region of the intensity plane for better visualization
                    crop2 = app.mult_factor*app.slmdim*(0.5 + app.zoom_factor/2);
                
                    I = IM(crop1 + 1 : crop2, crop1 + 1 : crop2); % only show 1/6 th of the intensity plane for better visualization
                else
                    tiffiles = dir([app.liveimg_path, app.imgType]);
                    I = imread([app.liveimg_path tiffiles(end).name]); 
                    I = mat2gray(I, double([0,255]));
                    
                end
                app.intensity_h.CData = I;
            end
        end

        % Value changed function: XShiftSlider
        function XShiftSliderValueChanged(app, event)
            tst = tic;
            app.yspm = floor(app.XShiftSlider.Value);
            app.XShiftSpinner.Value = app.yspm;           
            
            m = app.mask_v; % phase mask after adding lens
            
            ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);
            ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
            msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM

%             msk = m; % DZ 02/22/22
            app.mask_h.CData = msk;            
                        
            replication = 1/app.target_mult_factor;
            M = repelem(msk,replication,replication);
            imwrite(double(M), 'currentMask.tif');
            phaseMask = imread('currentMask.tif'); % uint8
            
            if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established
                calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM
            end
            
            if app.display_intensity
                if app.Switch.Value == "Simulated"
                    IMG = abs(fftshift(fft2(exp(1i*app.mask*2*pi)))); % intensity at the object plane, load the already calculated double-sized phase mask (without virtual lens)                
                    low = 500; % lower threshold for grayscale display of the intensity
                    high = prctile(IMG(:),99.9999); % DZ 05/13/21 to have a more generalized upper threshold
                    if high < low
                        low = 0;
                    end
                
                    IM = mat2gray(IMG,double([low,high])); % DZ 04/26/21 (set limits for better visualization)            
                
                               
                    crop1 = app.mult_factor*app.slmdim*(0.5 - app.zoom_factor/2); % DZ 05/13/21 only show the middle region of the intensity plane for better visualization
                    crop2 = app.mult_factor*app.slmdim*(0.5 + app.zoom_factor/2);
                
                    I = IM(crop1 + 1 : crop2, crop1 + 1 : crop2); % only show 1/6 th of the intensity plane for better visualization
                else
                    tiffiles = dir([app.liveimg_path, app.imgType]);
                    I = imread([app.liveimg_path tiffiles(end).name]); 
                    I = mat2gray(I, double([0,255]));
                    
                end
                app.intensity_h.CData = I;
            end
%             app.TipSliderValueChanged;
            toc(tst)
        end

        % Value changed function: YShiftSlider
        function YShiftSliderValueChanged(app, event)
            tst = tic;
            app.xspm = floor(app.YShiftSlider.Value);
            app.YShiftSpinner.Value = app.xspm;
            
            m = app.mask_v; % phase mask after adding lens
            
            ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);
            ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
            msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM

%             msk = m; % DZ 02/22/22
            app.mask_h.CData = msk;           
                        
            replication = 1/app.target_mult_factor;
            M = repelem(msk,replication,replication);
            imwrite(double(M), 'currentMask.tif');
            phaseMask = imread('currentMask.tif'); % uint8
            
            if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established
                calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM
            end
            
            if app.display_intensity
                if app.Switch.Value == "Simulated"
                    IMG = abs(fftshift(fft2(exp(1i*app.mask*2*pi)))); % intensity at the object plane, load the already calculated double-sized phase mask (without virtual lens)                
                    low = 500; % lower threshold for grayscale display of the intensity
                    high = prctile(IMG(:),99.9999); % DZ 05/13/21 to have a more generalized upper threshold
                    if high < low
                        low = 0;
                    end
                
                    IM = mat2gray(IMG,double([low,high])); % DZ 04/26/21 (set limits for better visualization)            
                
                               
                    crop1 = app.mult_factor*app.slmdim*(0.5 - app.zoom_factor/2); % DZ 05/13/21 only show the middle region of the intensity plane for better visualization
                    crop2 = app.mult_factor*app.slmdim*(0.5 + app.zoom_factor/2);
                
                    I = IM(crop1 + 1 : crop2, crop1 + 1 : crop2); % only show 1/6 th of the intensity plane for better visualization
                else
                    tiffiles = dir([app.liveimg_path, app.imgType]);
                    I = imread([app.liveimg_path tiffiles(end).name]); 
                    I = mat2gray(I, double([0,255]));
                    
                end
                app.intensity_h.CData = I;
            end
%             app.TipSliderValueChanged;
            toc(tst)
        end

        % Value changed function: XShiftSpinner
        function XShiftSpinnerValueChanged(app, event)
            
            app.yspm = floor(app.XShiftSpinner.Value);
            app.XShiftSlider.Value = app.yspm;
            
            m = app.mask_v; % phase mask after adding lens
            
            ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);
            ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
            msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM

            app.mask_h.CData = msk;          
                        
            replication = 1/app.target_mult_factor;
            M = repelem(msk,replication,replication);
            imwrite(double(M), 'currentMask.tif');
            phaseMask = imread('currentMask.tif'); % uint8
            
            if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established
                calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM
            end
            
            if app.display_intensity
                if app.Switch.Value == "Simulated"
                    IMG = abs(fftshift(fft2(exp(1i*app.mask*2*pi)))); % intensity at the object plane, load the already calculated double-sized phase mask (without virtual lens)
                    low = 500; % lower threshold for grayscale display of the intensity
                    high = prctile(IMG(:),99.9999); % DZ 05/13/21 to have a more generalized upper threshold
                    if high < low
                        low = 0;
                    end
                
                    IM = mat2gray(IMG,double([low,high])); % DZ 04/26/21 (set limits for better visualization)            
                
                               
                    crop1 = app.mult_factor*app.slmdim*(0.5 - app.zoom_factor/2); % DZ 05/13/21 only show the middle region of the intensity plane for better visualization
                    crop2 = app.mult_factor*app.slmdim*(0.5 + app.zoom_factor/2);
                
                    I = IM(crop1 + 1 : crop2, crop1 + 1 : crop2); % only show 1/6 th of the intensity plane for better visualization
                else
                    tiffiles = dir([app.liveimg_path, app.imgType]);
                    I = imread([app.liveimg_path tiffiles(end).name]); 
                    I = mat2gray(I, double([0,255]));
                    
                end
                app.intensity_h.CData = I;
            end
            
        end

        % Value changed function: YShiftSpinner
        function YShiftSpinnerValueChanged(app, event)
            
            app.xspm = floor(app.YShiftSpinner.Value);
            app.YShiftSlider.Value = app.xspm;
            
            m = app.mask_v; % phase mask after adding lens
            
            ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);
            ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
            msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM

            app.mask_h.CData = msk;            
                        
            replication = 1/app.target_mult_factor;
            M = repelem(msk,replication,replication);
            imwrite(double(M), 'currentMask.tif');
            phaseMask = imread('currentMask.tif'); % uint8
            
            if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established
                calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM
            end
            
            if app.display_intensity
                if app.Switch.Value == "Simulated"
                    IMG = abs(fftshift(fft2(exp(1i*app.mask*2*pi)))); % intensity at the object plane, load the already calculated double-sized phase mask (without virtual lens)
                    low = 500; % lower threshold for grayscale display of the intensity
                    high = prctile(IMG(:),99.9999); % DZ 05/13/21 to have a more generalized upper threshold
                    if high < low
                        low = 0;
                    end
                
                    IM = mat2gray(IMG,double([low,high])); % DZ 04/26/21 (set limits for better visualization)            
                
                               
                    crop1 = app.mult_factor*app.slmdim*(0.5 - app.zoom_factor/2); % DZ 05/13/21 only show the middle region of the intensity plane for better visualization
                    crop2 = app.mult_factor*app.slmdim*(0.5 + app.zoom_factor/2);
                
                    I = IM(crop1 + 1 : crop2, crop1 + 1 : crop2); % only show 1/6 th of the intensity plane for better visualization
                else
                    tiffiles = dir([app.liveimg_path, app.imgType]);
                    I = imread([app.liveimg_path tiffiles(end).name]); 
                    I = mat2gray(I, double([0,255]));
                    
                end
                app.intensity_h.CData = I;
            end
            
        end

        % Value changed function: GerchbergSaxtonIterationsSpinner
        function GerchbergSaxtonIterationsSpinnerValueChanged(app, event)
            app.gsitr = app.GerchbergSaxtonIterationsSpinner.Value;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.UITable.Data = [0 0 -1; 0 0 -1; 0 0 -1; 0 0 -1; 0 0 -1];
            msk = zeros(app.slmdim);
            IMG = abs(fftshift(fft2(exp(1i*msk*2*pi))));
            
            app.mask_h.CData = msk;             
            app.intensity_h.CData = IMG;
            
            imwrite(msk, 'currentMask.tif');
            phaseMask = imread('currentMask.tif'); % uint8
            
            if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established
                calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM
            end
        end

        % Button pushed function: LoadFromFileButton
        function LoadFromFileButtonPushed(app, event)
            [file,path] = uigetfile('*.tif','Please select the phase mask tif file','currentMask.tif');
            phaseMask = imread([path, file]);            
            
            app.mask_h.CData = phaseMask;            
                      
            if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established
                calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM
            end
            
            if app.display_intensity
                if app.Switch.Value == "Simulated"
                    IMG = abs(fftshift(fft2(exp(1i*double(phaseMask)*2*pi))));
                    low = 500; % lower threshold for grayscale display of the intensity
                    high = prctile(IMG(:),99.9999); % DZ 05/13/21 to have a more generalized upper threshold
                    if high < low
                        low = 0;
                    end
                
                    IM = mat2gray(IMG,double([low,high])); % DZ 04/26/21 (set limits for better visualization)            
                
                               
                    crop1 = app.mult_factor*app.slmdim*(0.5 - app.zoom_factor/2); % DZ 05/13/21 only show the middle region of the intensity plane for better visualization
                    crop2 = app.mult_factor*app.slmdim*(0.5 + app.zoom_factor/2);
                
                    I = IM(crop1 + 1 : crop2, crop1 + 1 : crop2); % only show 1/6 th of the intensity plane for better visualization
                else
                    tiffiles = dir([app.liveimg_path, app.imgType]);
                    I = imread([app.liveimg_path tiffiles(end).name]); 
                    I = mat2gray(I, double([0,255]));
                    
                end
                app.intensity_h.CData = I;
            end
        end

        % Value changed function: ZoomButton_2
        function ZoomButton_2ValueChanged(app, event)
            value = app.ZoomButton_2.Value;
            if value == 1
                pan(app.UIAxes2,'on');        
                zoom(app.UIAxes2,'on');       
            else
                pan(app.UIAxes2,'off');        
                zoom(app.UIAxes2,'off'); 
                app.UIAxes2.XLimMode = 'auto';
                app.UIAxes2.YLimMode = 'auto';
                app.UIAxes2.ZLimMode = 'auto'; 
            end
            
        end

        % Value changed function: ZoomButton
        function ZoomButtonValueChanged(app, event)
            value = app.ZoomButton.Value;
            if value == 1
                pan(app.UIAxes,'on');        
                zoom(app.UIAxes,'on');       
            else
                pan(app.UIAxes,'off');        
                zoom(app.UIAxes,'off'); 
                app.UIAxes.XLimMode = 'auto';
                app.UIAxes.YLimMode = 'auto';
                app.UIAxes.ZLimMode = 'auto'; 
            end
        end

        % Value changed function: CalculationModeSwitch
        function CalculationModeSwitchValueChanged(app, event)
            value = app.CalculationModeSwitch.Value;
            prev_valx = app.XShiftSlider.Value;
            prev_valy = app.YShiftSlider.Value;
            
            if value == "High pixel"                 
                app.mult_factor = 1.5; % DZ 02/22/22
                app.zoom_factor = 1/6;
                app.highPixel_mode = 1;
                prev_target_mult_factor = app.target_mult_factor;
                app.target_mult_factor = 1; 
                convert_ratio = app.target_mult_factor/prev_target_mult_factor;                
            else                
                app.mult_factor = 1/3;
                app.zoom_factor = 1;
                app.highPixel_mode = 0;
                prev_target_mult_factor = app.target_mult_factor;
                app.target_mult_factor = 1/4;
                convert_ratio = app.target_mult_factor/prev_target_mult_factor;
            end
            
            app.yspm = round(prev_valx*convert_ratio);
            app.xspm = round(prev_valy*convert_ratio); 
            
            lim = round(app.slmdim*(app.mult_factor-app.target_mult_factor)/2);  %DZ 02/22/22
            
            app.XShiftSlider.Limits = [-lim,lim];
            app.YShiftSlider.Limits = [-lim,lim];
            app.XShiftSpinner.Limits = [-lim,lim];
            app.YShiftSpinner.Limits = [-lim,lim];
                        
            if value == "High pixel"
                app.XShiftSlider.MajorTicks = -lim:64:lim;
                app.XShiftSlider.MajorTickLabels = string(-lim:64:lim);
                app.YShiftSlider.MajorTicks = -lim:64:lim;
                app.YShiftSlider.MajorTickLabels = string(-lim:64:lim);                            
            else
                app.XShiftSlider.MajorTicks = -lim:8:lim;
                app.XShiftSlider.MajorTickLabels = string(-lim:8:lim);
                app.YShiftSlider.MajorTicks = -lim:8:lim;
                app.YShiftSlider.MajorTickLabels = string(-lim:8:lim);                
            end
                       
            app.XShiftSlider.Value = app.yspm;
            app.YShiftSlider.Value = app.xspm;
            app.XShiftSpinner.Value = app.yspm;
            app.YShiftSpinner.Value = app.xspm;
                
            app.mask = zeros(app.mult_factor*1536,app.mult_factor*1536);
            app.mask_v = zeros(app.mult_factor*1536,app.mult_factor*1536);
                
            app.mask_h = imshow(zeros(app.target_mult_factor*1536,app.target_mult_factor*1536),'Parent', app.UIAxes);
            app.intensity_h = imshow(zeros(app.mult_factor*app.zoom_factor*1536,app.mult_factor*app.zoom_factor*1536),'Parent', app.UIAxes2);
                  
        end

        % Value changed function: OnButton
        function OnButtonValueChanged(app, event)
            value = app.OnButton.Value;
            if value
                app.display_intensity = 1;                
            else
                app.display_intensity = 0;                
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            % Calculate button callback
            app.LensSpinner.Value = -1*app.lens;
            
            d = app.UITable.Data;
            xoff = [];
            yoff = [];
            vm = [];
            selections = [app.CheckBox.Value,app.CheckBox_2.Value,app.CheckBox_3.Value,app.CheckBox_4.Value,app.CheckBox_5.Value];
            
            % read data from the table
            for itr = 1:10
                %if selections(itr)  %050421 DZ consider only the selected rows in the table
                if d(itr,3)>-1
                    xoff = [xoff; d(itr,1)/1000]; % convert um to mm, need to be calibrated
                    yoff = [yoff; d(itr,2)/1000];
                    vm = [vm, d(itr,3)]; % vortex modes
                end 
            end
            
            app.vortexMode = vm;
            app.xshifts = xoff;
            app.yshifts = yoff;
            
            opts=dhot_opts();
            opts.vortex_modes= app.vortexMode; 
            opts.niters = app.gsitr;
            opts.lens=app.lens;
            opts.slm_dims = [1536 1536]*app.mult_factor;
            
            x = app.xVal/(0.5*app.slmdim); % convert to [-1, 1] range
            y = app.yVal/(0.5*app.slmdim); % convert to [-1, 1] range
            
            P = [x + app.xshifts, y + app.yshifts];
            
            tstart = tic;
            [m, IMG]=dhot(P, opts, app.display_intensity);
            t = toc(tstart);
            fprintf('Time taken for dhot calculations before updating the display is %.2f seconds \n',t);
            
            app.xspm = app.YShiftSpinner.Value;
            app.yspm = app.XShiftSpinner.Value;
            
            app.mask = m;
            
            % 05/09/21 Dulara moved this section to the GUI code for speeding up the calculations
            m = add_virtualLens(app.mask, opts);
            app.mask_v = m;
            
            ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);
            ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
            msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
            
            app.mask_h.CData = msk;
            
            t = toc(tstart);
            fprintf('Time taken until after updating the phase mask display in the GUI is %.2f seconds \n',t);                                
                     
            replication = 1/app.target_mult_factor;
            M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
            imwrite(double(M), 'currentMask.tif');
            phaseMask = imread('currentMask.tif'); % uint8
            
            t = toc(tstart); 
            
            fprintf('Total time taken for calculations before sending the phase mask to SLM is %.2f seconds \n',t);
            
            if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established
                calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM
            end
            
            t = toc(tstart); 
            
            fprintf('Total time taken until after sending the phase mask to SLM is %.2f seconds \n',t);                     
            
            if app.display_intensity
                if value == "Simulated"
                    low = 500; % lower threshold for grayscale display of the intensity
                    high = prctile(IMG(:),99.9999); % DZ 05/13/21 to have a more generalized upper threshold
                    if high < low
                        low = 0;
                    end
                
                    IM = mat2gray(IMG,double([low,high])); % DZ 04/26/21 (set limits for better visualization)            
                
                               
                    crop1 = app.mult_factor*app.slmdim*(0.5 - app.zoom_factor/2); % DZ 05/13/21 only show the middle region of the intensity plane for better visualization
                    crop2 = app.mult_factor*app.slmdim*(0.5 + app.zoom_factor/2);
                
                    I = IM(crop1 + 1 : crop2, crop1 + 1 : crop2); % only show 1/6 th of the intensity plane for better visualization
                else
                    tiffiles = dir([app.liveimg_path, app.imgType]);
                    I = imread([app.liveimg_path tiffiles(end).name]); 
                    I = mat2gray(I, double([0,255]));
                    
                end
                app.intensity_h.CData = I;
            end            
            
            t = toc(tstart);
            fprintf('Time taken until after updating the beamlet display in the GUI is %.2f seconds \n',t);
            
        end

        % Callback function
        function TuneButtonPushed(app, event)
            
            opts=dhot_opts();
            opts.vortex_modes= app.vortexMode; 
            opts.niters = app.gsitr;
            opts.lens=app.lens;
            opts.slm_dims = [1536 1536]*app.mult_factor;
            
            x = app.xVal/(0.5*app.slmdim); % convert to [-1, 1] range
            y = app.yVal/(0.5*app.slmdim); % convert to [-1, 1] range
            
            P = [x + app.xshifts, y + app.yshifts];
            app.xspm = app.YShiftSpinner.Value;
            app.yspm = app.XShiftSpinner.Value;
            
            
            tst = tic;
            
            ellipticity = app.EllipticitylongaxisshortaxisEditField.Value;
            num_Axis = app.NumberofaxesSpinner.Value;
            el_tolerence = app.EllipticitytolerenceSpinner.Value;
            
            e = [];
                  
            opts.abberationCorrection = 1;
            opts.C2_2 = app.C2_2EditField.Value;
            opts.C3_n1 = app.C3_n1EditField.Value;
            opts.C3_1 = app.C3_1EditField.Value;
            opts.C1_n1 = app.C1_n1EditField.Value;
            opts.C1_1 = app.C1_1EditField.Value;
            
            
            disp('Initial Search'); 

            
            start = app.StartEditField.Value;
            stop = app.EndEditField.Value;
            stepSize = (stop - start)/10;
            
            for i = start:stepSize:stop
                opts.C2_n2 = i;
                [m, ~]=dhot(P, opts, 0);
                m = add_virtualLens(m, opts);           
                ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);           
                ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
                msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
                replication = 1/app.target_mult_factor;
                M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
                imwrite(double(M), 'currentMask.tif');
                phaseMask = imread('currentMask.tif'); % uint8                  
                if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established                
                    calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM            
                end
                pause(0.05);
                
                err = calcError(app.liveimg_path,app.output_path,ellipticity,num_Axis,2,app.central_spot,app.selected_ROI,app.background_ROI, app.manual_ring, app.intensity_threshold, app.lineCoordinates);
                
                tmp = [err;i];
                e = [e tmp];
            end
            
            [~,sortedInd] = sort(e(1,:));
            
            start = min(e(2,sortedInd(1)),e(2,sortedInd(2)));
            stop =  max(e(2,sortedInd(1)),e(2,sortedInd(2)));
            stepSize = (stop - start)/10;
            
            disp('Refined Search'); 
            
            C2_n2 = start;
            error = Inf; % Max possible error
            
            for i = start:stepSize:stop
                opts.C2_n2 = i;
                [m, ~]=dhot(P, opts, 0);
                m = add_virtualLens(m, opts);           
                ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);           
                ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
                msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
                replication = 1/app.target_mult_factor;
                M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
                imwrite(double(M), 'currentMask.tif');
                phaseMask = imread('currentMask.tif'); % uint8                  
                if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established                
                    calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM            
                end
                pause(0.05);
                
                err = calcError(app.liveimg_path,app.output_path,ellipticity,num_Axis,3,app.central_spot,app.selected_ROI,app.background_ROI, app.manual_ring, app.intensity_threshold, app.lineCoordinates);
                
                tmp = [err;i];
                e = [e tmp];
                
                if err<error
                    C2_n2 = i;
                end
            end
            
            % Final result
            
            opts.C2_n2 = C2_n2;
            app.C2_n2EditField.Value = C2_n2;
            [m, ~]=dhot(P, opts, 0);
            m = add_virtualLens(m, opts);           
            ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);           
            ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
            msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
            replication = 1/app.target_mult_factor;
            M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
            imwrite(double(M), 'currentMask.tif');
            phaseMask = imread('currentMask.tif'); % uint8                  
            if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established                
                calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM            
            end
            pause(0.05)

            t_itr = toc(tst);
         
            assignin('base','e',e);
            dlmwrite([app.output_path 'testResults.txt'],[app.xVal,app.yVal,app.xshifts(1),app.yshifts(1),app.lens,app.vortexMode(1), app.highPixel_mode, ellipticity, el_tolerence, num_Axis, C2_n2, opts.C2_2, opts.C3_n1, opts.C3_1, opts.C1_n1, opts.C1_1, app.yspm, app.xspm, app.ao_automated, t_itr],'delimiter','\t','-append');
            
        end

        % Button pushed function: CalculateAllButton
        function CalculateAllButtonPushed(app, event)
            optimum_coeff = [0, 0, 0, 0, 0, 0];
            app.ao_automated = 1;
            app.C2_n2EditField.Value = 0;
            app.C2_2EditField.Value = 0;
            app.C3_n1EditField.Value = 0;
            app.C3_1EditField.Value = 0;
            app.C1_n1EditField.Value = 0;
            app.C1_1EditField.Value = 0;
            
            filename = [app.output_path 'testResults.txt'];
            fid = fopen(filename, 'wt');
            fprintf(fid, '%s\t%s\t\n', 'live_imgpath',app.liveimg_path);
            fprintf(fid, '%s\t%s\t\n', 'output_directory',app.output_path);
            fprintf(fid, '%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\n', 'x_center','y_center','beamlet_x', 'beamlet_y', 'lens', 'vortexMode','highPixel_mode', 'ellipticity', 'ellipticity_tol', 'num_points_uniformity', 'C2_n2','C2_2','C3_n1','C3_1', 'C1_n1','C1_1', 'PM_xshift','PM_yshift','Automated','Elapsed_time');  % header
            fclose(fid);
            
            tiffiles = dir([app.liveimg_path, app.imgType]);
            Init = imread([app.liveimg_path tiffiles(1).name]);
            app.intensity_threshold = max(Init(:));
            
            app.TuneButtonPushed;
            optimum_coeff(1) = app.C2_n2EditField.Value;
            app.C2_n2EditField.Value = 0;
            fname = [app.output_path 'e1.mat'];
            evalin('base',['save(''' fname ''' , ''e'' )']);
            
            app.TuneButton_2Pushed;
            optimum_coeff(2) = app.C2_2EditField.Value;
            app.C2_2EditField.Value = 0;
            fname = [app.output_path 'e2.mat'];
            evalin('base',['save(''' fname ''' , ''e'' )']);
            
            app.TuneButton_3Pushed;
            optimum_coeff(3) = app.C3_n1EditField.Value;
            app.C3_n1EditField.Value = 0;
            fname = [app.output_path 'e3.mat'];
            evalin('base',['save(''' fname ''' , ''e'' )']);
            
            app.TuneButton_4Pushed;
            optimum_coeff(4) = app.C3_1EditField.Value;
            app.C3_1EditField.Value = 0;
            fname = [app.output_path 'e4.mat'];
            evalin('base',['save(''' fname ''' , ''e'' )']);
            
%             app.TuneButton_5Pushed;
%             optimum_coeff(5) = app.C1_n1EditField.Value;
%             app.C1_n1EditField.Value = 0;
%             fname = [app.output_path 'e5.mat'];
%             evalin('base',['save(''' fname ''' , ''e'' )']);
%             
%             app.TuneButton_6Pushed;
%             optimum_coeff(6) = app.C1_1EditField.Value;
%             app.C1_1EditField.Value = 0;
%             fname = [app.output_path 'e6.mat'];
%             evalin('base',['save(''' fname ''' , ''e'' )']);
            
            [~,I] = max(abs(optimum_coeff));
            
            if I == 1
                optVal = [0, 0, 0, 0, 0];
                app.C2_n2EditField.Value = optimum_coeff(1);
                
                app.TuneButton_2Pushed;
                optVal(1) = app.C2_2EditField.Value;
                app.C2_2EditField.Value = 0;
                fname = [app.output_path 'e7.mat'];
                evalin('base',['save(''' fname ''' , ''e'' )']);
                
                app.TuneButton_3Pushed;
                optVal(2) = app.C3_n1EditField.Value;
                app.C3_n1EditField.Value = 0;
                fname = [app.output_path 'e8.mat'];
                evalin('base',['save(''' fname ''' , ''e'' )']);
                
                app.TuneButton_4Pushed;
                optVal(3) = app.C3_1EditField.Value;
                app.C3_1EditField.Value = 0;
                fname = [app.output_path 'e9.mat'];
                evalin('base',['save(''' fname ''' , ''e'' )']);
                
%                 app.TuneButton_5Pushed;
%                 optVal(4) = app.C1_n1EditField.Value;
%                 app.C1_n1EditField.Value = 0;
%                 fname = [app.output_path 'e10.mat'];
%                 evalin('base',['save(''' fname ''' , ''e'' )']);
%                 
%                 app.TuneButton_6Pushed;
%                 optVal(5) = app.C1_1EditField.Value;
%                 app.C1_1EditField.Value = 0;
%                 fname = [app.output_path 'e11.mat'];
%                 evalin('base',['save(''' fname ''' , ''e'' )']);
                
                [~,J] = max(abs(optVal));
                
                if J == 1
                    app.C2_2EditField.Value = optVal(1);
                
                    app.TuneButton_3Pushed;
                    fname = [app.output_path 'e12.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                    
                    app.TuneButton_4Pushed;
                    fname = [app.output_path 'e13.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                    
%                     app.TuneButton_5Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_6Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
                    
                elseif J == 2
                    app.C3_n1EditField.Value = optVal(2);
                
                    app.TuneButton_2Pushed;
                    fname = [app.output_path 'e12.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                    
                    app.TuneButton_4Pushed;
                    fname = [app.output_path 'e13.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                    
%                     app.TuneButton_5Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_6Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
                    
                elseif J == 3
                    app.C3_1EditField.Value = optVal(3);
                
                    app.TuneButton_2Pushed;
                    fname = [app.output_path 'e12.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                    
                    app.TuneButton_3Pushed;
                    fname = [app.output_path 'e13.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                end
                    
%                     app.TuneButton_5Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_6Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
                    
%                 elseif J == 4
%                     app.C1_n1EditField.Value = optVal(4);
%                 
%                     app.TuneButton_2Pushed;
%                     fname = [app.output_path 'e12.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_3Pushed;
%                     fname = [app.output_path 'e13.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_4Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_6Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                 else
%                     app.C1_1EditField.Value = optVal(5);
%                 
%                     app.TuneButton_2Pushed;
%                     fname = [app.output_path 'e12.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_3Pushed;
%                     fname = [app.output_path 'e13.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_4Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_5Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                 end
                
            elseif I == 2
                optVal = [0, 0, 0, 0, 0];
                app.C2_2EditField.Value = optimum_coeff(2);
                
                app.TuneButtonPushed;
                optVal(1) = app.C2_n2EditField.Value;
                app.C2_n2EditField.Value = 0;
                fname = [app.output_path 'e7.mat'];
                evalin('base',['save(''' fname ''' , ''e'' )']);
                
                app.TuneButton_3Pushed;
                optVal(2) = app.C3_n1EditField.Value;
                app.C3_n1EditField.Value = 0;
                fname = [app.output_path 'e8.mat'];
                evalin('base',['save(''' fname ''' , ''e'' )']);
                
                app.TuneButton_4Pushed;
                optVal(3) = app.C3_1EditField.Value;
                app.C3_1EditField.Value = 0;
                fname = [app.output_path 'e9.mat'];
                evalin('base',['save(''' fname ''' , ''e'' )']);
                
%                 app.TuneButton_5Pushed;
%                 optVal(4) = app.C1_n1EditField.Value;
%                 app.C1_n1EditField.Value = 0;
%                 fname = [app.output_path 'e10.mat'];
%                 evalin('base',['save(''' fname ''' , ''e'' )']);
%                 
%                 app.TuneButton_6Pushed;
%                 optVal(5) = app.C1_1EditField.Value;
%                 app.C1_1EditField.Value = 0;
%                 fname = [app.output_path 'e11.mat'];
%                 evalin('base',['save(''' fname ''' , ''e'' )']);
                
                [~,J] = max(abs(optVal));
                
                if J == 1
                    app.C2_n2EditField.Value = optVal(1);
                
                    app.TuneButton_3Pushed;
                    fname = [app.output_path 'e12.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                    
                    app.TuneButton_4Pushed;
                    fname = [app.output_path 'e13.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                    
%                     app.TuneButton_5Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_6Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
                    
                elseif J == 2
                    app.C3_n1EditField.Value = optVal(2);
                
                    app.TuneButtonPushed;
                    fname = [app.output_path 'e12.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                    
                    app.TuneButton_4Pushed;
                    fname = [app.output_path 'e13.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                    
%                     app.TuneButton_5Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_6Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
                    
                elseif J == 3
                    app.C3_1EditField.Value = optVal(3);
                
                    app.TuneButtonPushed;
                    fname = [app.output_path 'e12.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                    
                    app.TuneButton_3Pushed;
                    fname = [app.output_path 'e13.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                end
                    
%                     app.TuneButton_5Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_6Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
                    
%                 elseif J == 4
%                     app.C1_n1EditField.Value = optVal(4);
%                 
%                     app.TuneButtonPushed;
%                     fname = [app.output_path 'e12.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_3Pushed;
%                     fname = [app.output_path 'e13.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_4Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_6Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                 else
%                     app.C1_1EditField.Value = optVal(5);
%                 
%                     app.TuneButtonPushed;
%                     fname = [app.output_path 'e12.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_3Pushed;
%                     fname = [app.output_path 'e13.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_4Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_5Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                 end
            elseif I == 3
                optVal = [0, 0, 0, 0, 0];
                app.C3_n1EditField.Value = optimum_coeff(3);
                
                app.TuneButtonPushed;
                optVal(1) = app.C2_n2EditField.Value;
                app.C2_n2EditField.Value = 0;
                fname = [app.output_path 'e7.mat'];
                evalin('base',['save(''' fname ''' , ''e'' )']);
                
                app.TuneButton_2Pushed;
                optVal(2) = app.C2_2EditField.Value;
                app.C2_2EditField.Value = 0;
                fname = [app.output_path 'e8.mat'];
                evalin('base',['save(''' fname ''' , ''e'' )']);
                
                app.TuneButton_4Pushed;
                optVal(3) = app.C3_1EditField.Value;
                app.C3_1EditField.Value = 0;
                fname = [app.output_path 'e9.mat'];
                evalin('base',['save(''' fname ''' , ''e'' )']);
                
%                 app.TuneButton_5Pushed;
%                 optVal(4) = app.C1_n1EditField.Value;
%                 app.C1_n1EditField.Value = 0;
%                 fname = [app.output_path 'e10.mat'];
%                 evalin('base',['save(''' fname ''' , ''e'' )']);
%                 
%                 app.TuneButton_6Pushed;
%                 optVal(5) = app.C1_1EditField.Value;
%                 app.C1_1EditField.Value = 0;
%                 fname = [app.output_path 'e11.mat'];
%                 evalin('base',['save(''' fname ''' , ''e'' )']);
                
                [~,J] = max(abs(optVal));
                
                if J == 1
                    app.C2_n2EditField.Value = optVal(1);
                
                    app.TuneButton_2Pushed;
                    fname = [app.output_path 'e12.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                    
                    app.TuneButton_4Pushed;
                    fname = [app.output_path 'e13.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                    
%                     app.TuneButton_5Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_6Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
                    
                elseif J == 2
                    app.C2_2EditField.Value = optVal(2);
                
                    app.TuneButtonPushed;
                    fname = [app.output_path 'e12.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                    
                    app.TuneButton_4Pushed;
                    fname = [app.output_path 'e13.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                    
%                     app.TuneButton_5Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_6Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
                    
                elseif J == 3
                    app.C3_1EditField.Value = optVal(3);
                
                    app.TuneButtonPushed;
                    fname = [app.output_path 'e12.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                    
                    app.TuneButton_2Pushed;
                    fname = [app.output_path 'e13.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                end
                    
%                     app.TuneButton_5Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_6Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
                    
%                 elseif J == 4
%                     app.C1_n1EditField.Value = optVal(4);
%                 
%                     app.TuneButtonPushed;
%                     fname = [app.output_path 'e12.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_2Pushed;
%                     fname = [app.output_path 'e13.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_4Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_6Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                 else
%                     app.C1_1EditField.Value = optVal(5);
%                 
%                     app.TuneButtonPushed;
%                     fname = [app.output_path 'e12.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_2Pushed;
%                     fname = [app.output_path 'e13.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_4Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_5Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                 end
            elseif I == 4
                optVal = [0, 0, 0, 0, 0];
                app.C3_1EditField.Value = optimum_coeff(4);
                
                app.TuneButtonPushed;
                optVal(1) = app.C2_n2EditField.Value;
                app.C2_n2EditField.Value = 0;
                fname = [app.output_path 'e7.mat'];
                evalin('base',['save(''' fname ''' , ''e'' )']);
                
                app.TuneButton_2Pushed;
                optVal(2) = app.C2_2EditField.Value;
                app.C2_2EditField.Value = 0;
                fname = [app.output_path 'e8.mat'];
                evalin('base',['save(''' fname ''' , ''e'' )']);
                
                app.TuneButton_3Pushed;
                optVal(3) = app.C3_n1EditField.Value;
                app.C3_n1EditField.Value = 0;
                fname = [app.output_path 'e9.mat'];
                evalin('base',['save(''' fname ''' , ''e'' )']);
                
%                 app.TuneButton_5Pushed;
%                 optVal(4) = app.C1_n1EditField.Value;
%                 app.C1_n1EditField.Value = 0;
%                 fname = [app.output_path 'e10.mat'];
%                 evalin('base',['save(''' fname ''' , ''e'' )']);
%                 
%                 app.TuneButton_6Pushed;
%                 optVal(5) = app.C1_1EditField.Value;
%                 app.C1_1EditField.Value = 0;
%                 fname = [app.output_path 'e11.mat'];
%                 evalin('base',['save(''' fname ''' , ''e'' )']);
                
                [~,J] = max(abs(optVal));
                
                if J == 1
                    app.C2_n2EditField.Value = optVal(1);
                
                    app.TuneButton_2Pushed;
                    fname = [app.output_path 'e12.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                    
                    app.TuneButton_3Pushed;
                    fname = [app.output_path 'e13.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                    
%                     app.TuneButton_5Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_6Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
                    
                elseif J == 2
                    app.C2_2EditField.Value = optVal(2);
                
                    app.TuneButtonPushed;
                    fname = [app.output_path 'e12.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                    
                    app.TuneButton_3Pushed;
                    fname = [app.output_path 'e13.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                    
%                     app.TuneButton_5Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_6Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
                    
                elseif J == 3
                    app.C3_n1EditField.Value = optVal(3);
                
                    app.TuneButtonPushed;
                    fname = [app.output_path 'e12.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                    
                    app.TuneButton_2Pushed;
                    fname = [app.output_path 'e13.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                end
            end
                    
%                     app.TuneButton_5Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_6Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
                    
%                 elseif J == 4
%                     app.C1_n1EditField.Value = optVal(4);
%                 
%                     app.TuneButtonPushed;
%                     fname = [app.output_path 'e12.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_2Pushed;
%                     fname = [app.output_path 'e13.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_3Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_6Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                 else
%                     app.C1_1EditField.Value = optVal(5);
%                 
%                     app.TuneButtonPushed;
%                     fname = [app.output_path 'e12.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_2Pushed;
%                     fname = [app.output_path 'e13.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_3Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_5Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                 end
%             elseif I == 5
%                 optVal = [0, 0, 0, 0, 0];
%                 app.C1_n1EditField.Value = optimum_coeff(5);
%                 
%                 app.TuneButtonPushed;
%                 optVal(1) = app.C2_n2EditField.Value;
%                 app.C2_n2EditField.Value = 0;
%                 fname = [app.output_path 'e7.mat'];
%                 evalin('base',['save(''' fname ''' , ''e'' )']);
%                 
%                 app.TuneButton_2Pushed;
%                 optVal(2) = app.C2_2EditField.Value;
%                 app.C2_2EditField.Value = 0;
%                 fname = [app.output_path 'e8.mat'];
%                 evalin('base',['save(''' fname ''' , ''e'' )']);
%                 
%                 app.TuneButton_3Pushed;
%                 optVal(3) = app.C3_n1EditField.Value;
%                 app.C3_n1EditField.Value = 0;
%                 fname = [app.output_path 'e9.mat'];
%                 evalin('base',['save(''' fname ''' , ''e'' )']);
%                 
%                 app.TuneButton_4Pushed;
%                 optVal(4) = app.C3_1EditField.Value;
%                 app.C3_1EditField.Value = 0;
%                 fname = [app.output_path 'e10.mat'];
%                 evalin('base',['save(''' fname ''' , ''e'' )']);
%                 
%                 app.TuneButton_6Pushed;
%                 optVal(5) = app.C1_1EditField.Value;
%                 app.C1_1EditField.Value = 0;
%                 fname = [app.output_path 'e11.mat'];
%                 evalin('base',['save(''' fname ''' , ''e'' )']);
%                 
%                 [~,J] = max(abs(optVal));
%                 
%                 if J == 1
%                     app.C2_n2EditField.Value = optVal(1);
%                 
%                     app.TuneButton_2Pushed;
%                     fname = [app.output_path 'e12.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_3Pushed;
%                     fname = [app.output_path 'e13.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_4Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_6Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                 elseif J == 2
%                     app.C2_2EditField.Value = optVal(2);
%                 
%                     app.TuneButtonPushed;
%                     fname = [app.output_path 'e12.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_3Pushed;
%                     fname = [app.output_path 'e13.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_4Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_6Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                 elseif J == 3
%                     app.C3_n1EditField.Value = optVal(3);
%                 
%                     app.TuneButtonPushed;
%                     fname = [app.output_path 'e12.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_2Pushed;
%                     fname = [app.output_path 'e13.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_4Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_6Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                 elseif J == 4
%                     app.C3_1EditField.Value = optVal(4);
%                 
%                     app.TuneButtonPushed;
%                     fname = [app.output_path 'e12.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_2Pushed;
%                     fname = [app.output_path 'e13.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_3Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_6Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                 else
%                     app.C1_1EditField.Value = optVal(5);
%                 
%                     app.TuneButtonPushed;
%                     fname = [app.output_path 'e12.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_2Pushed;
%                     fname = [app.output_path 'e13.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_3Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_4Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                 end
%             else
%                 optVal = [0, 0, 0, 0, 0];
%                 app.C1_1EditField.Value = optimum_coeff(6);
%                 
%                 app.TuneButtonPushed;
%                 optVal(1) = app.C2_n2EditField.Value;
%                 app.C2_n2EditField.Value = 0;
%                 fname = [app.output_path 'e7.mat'];
%                 evalin('base',['save(''' fname ''' , ''e'' )']);
%                 
%                 app.TuneButton_2Pushed;
%                 optVal(2) = app.C2_2EditField.Value;
%                 app.C2_2EditField.Value = 0;
%                 fname = [app.output_path 'e8.mat'];
%                 evalin('base',['save(''' fname ''' , ''e'' )']);
%                 
%                 app.TuneButton_3Pushed;
%                 optVal(3) = app.C3_n1EditField.Value;
%                 app.C3_n1EditField.Value = 0;
%                 fname = [app.output_path 'e9.mat'];
%                 evalin('base',['save(''' fname ''' , ''e'' )']);
%                 
%                 app.TuneButton_4Pushed;
%                 optVal(4) = app.C3_1EditField.Value;
%                 app.C3_1EditField.Value = 0;
%                 fname = [app.output_path 'e10.mat'];
%                 evalin('base',['save(''' fname ''' , ''e'' )']);
%                 
%                 app.TuneButton_5Pushed;
%                 optVal(5) = app.C1_n1EditField.Value;
%                 app.C1_n1EditField.Value = 0;
%                 fname = [app.output_path 'e11.mat'];
%                 evalin('base',['save(''' fname ''' , ''e'' )']);
%                 
%                 [~,J] = max(abs(optVal));
%                 
%                 if J == 1
%                     app.C2_n2EditField.Value = optVal(1);
%                 
%                     app.TuneButton_2Pushed;
%                     fname = [app.output_path 'e12.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_3Pushed;
%                     fname = [app.output_path 'e13.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_4Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_5Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                 elseif J == 2
%                     app.C2_2EditField.Value = optVal(2);
%                 
%                     app.TuneButtonPushed;
%                     fname = [app.output_path 'e12.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_3Pushed;
%                     fname = [app.output_path 'e13.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_4Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_5Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                 elseif J == 3
%                     app.C3_n1EditField.Value = optVal(3);
%                 
%                     app.TuneButtonPushed;
%                     fname = [app.output_path 'e12.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_2Pushed;
%                     fname = [app.output_path 'e13.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_4Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_5Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                 elseif J == 4
%                     app.C3_1EditField.Value = optVal(4);
%                 
%                     app.TuneButtonPushed;
%                     fname = [app.output_path 'e12.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_2Pushed;
%                     fname = [app.output_path 'e13.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_3Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_5Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                 else
%                     app.C1_n1EditField.Value = optVal(5);
%                 
%                     app.TuneButtonPushed;
%                     fname = [app.output_path 'e12.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_2Pushed;
%                     fname = [app.output_path 'e13.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_3Pushed;
%                     fname = [app.output_path 'e14.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                     app.TuneButton_4Pushed;
%                     fname = [app.output_path 'e15.mat'];
%                     evalin('base',['save(''' fname ''' , ''e'' )']);
%                     
%                 end
%             end

            app.ao_automated = 0;
            
            % Use the optimum values to display the the final result 
            opts=dhot_opts();
            opts.vortex_modes= app.vortexMode; 
            opts.niters = app.gsitr;
            opts.lens=app.lens;
            opts.slm_dims = [1536 1536]*app.mult_factor;
            
            x = app.xVal/(0.5*app.slmdim); % convert to [-1, 1] range
            y = app.yVal/(0.5*app.slmdim); % convert to [-1, 1] range
            
            P = [x + app.xshifts, y + app.yshifts];
            
            app.xspm = app.YShiftSpinner.Value;
            app.yspm = app.XShiftSpinner.Value;
            
            opts.abberationCorrection = 1;
            opts.C2_n2 = app.C2_n2EditField.Value;
            opts.C2_2 = app.C2_2EditField.Value;
            opts.C3_n1 = app.C3_n1EditField.Value;
            opts.C3_1 = app.C3_1EditField.Value;
            opts.C1_n1 = app.C1_n1EditField.Value;
            opts.C1_1 = app.C1_1EditField.Value;
            [m, ~]=dhot(P, opts, 0);
            m = add_virtualLens(m, opts);           
            ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);           
            ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
            msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
            replication = 1/app.target_mult_factor;
            M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
            imwrite(double(M), 'currentMask.tif');
            phaseMask = imread('currentMask.tif'); % uint8 
            if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established                
                calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM            
            end
            
            pause(0.1);
            tiffiles = dir([app.liveimg_path,app.imgType]);
            Init = imread([app.liveimg_path tiffiles(1).name]);
            IMG = imread([app.liveimg_path tiffiles(end).name]);
            imwrite(IMG, [app.output_path ,'IMG_final.tiff']);
            imwrite(Init, [app.output_path ,'IMG_initial.tiff']);
            figure(1); imshow(Init);
            figure(2); imshow(IMG);
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            path = uigetdir;
            app.liveimg_path = [path filesep];
            app.LiveImagePathEditField.Value = app.liveimg_path;
        end

        % Button pushed function: ChangeButton_2
        function ChangeButton_2Pushed(app, event)
            path = uigetdir;
            app.output_path = [path filesep];
            app.OutputFolderEditField.Value = app.output_path;
        end

        % Value changed function: IgnoreCentralSpotCheckBox
        function IgnoreCentralSpotCheckBoxValueChanged(app, event)
            value = app.IgnoreCentralSpotCheckBox.Value;
            if value
                tiffiles = dir([app.liveimg_path, app.imgType]);
                IMG = imread([app.liveimg_path tiffiles(end).name]);
                handleToMessageBox = msgbox('Draw a rectangle over the central spot region', 'Ignore the Central Spot','help');
                pause(3)
                if exist('handleToMessageBox', 'var')
                    delete(handleToMessageBox);
                end
                f1 = figure(1); 
                imshow(IMG);
                rectangle = imrect;
                pause(5);
                rect = rectangle.getPosition;
                app.central_spot = [rect(2), rect(2)+rect(4), rect(1), rect(1)+rect(3)]; % [y1 y2 x1 x2]
                close(f1);
            else
                app.central_spot = [];    
            end
        end

        % Value changed function: ConsideranROICheckBox
        function ConsideranROICheckBoxValueChanged(app, event)
            value = app.ConsideranROICheckBox.Value;
            if value
                tiffiles = dir([app.liveimg_path, app.imgType]);
                IMG = imread([app.liveimg_path tiffiles(end).name]);
                handleToMessageBox = msgbox('Draw a rectangle over the region of interest', 'Select an ROI','help');
                pause(3)
                if exist('handleToMessageBox', 'var')
                    delete(handleToMessageBox);
                end
                f1 = figure(1); 
                imshow(IMG);
                rectangle = imrect;
                pause(5);
                rect = rectangle.getPosition;
                app.selected_ROI = [rect(2), rect(2)+rect(4), rect(1), rect(1)+rect(3)]; % [y1 y2 x1 x2]
                close(f1);
            else
                app.selected_ROI = [];   
            end
        end

        % Value changed function: SelectaRegiontoMinimizeNoiseCheckBox
        function SelectaRegiontoMinimizeNoiseCheckBoxValueChanged(app, event)
            value = app.SelectaRegiontoMinimizeNoiseCheckBox.Value;
            if value
                tiffiles = dir([app.liveimg_path, app.imgType]);
                IMG = imread([app.liveimg_path tiffiles(end).name]);
                handleToMessageBox = msgbox('Select the region to minimze noise', 'Noise Minimization','help');
                pause(3)
                if exist('handleToMessageBox', 'var')
                    delete(handleToMessageBox);
                end
                f1 = figure(1); 
                imshow(IMG);
                rectangle = imrect;
                pause(10);
                rect = rectangle.getPosition;
                app.background_ROI = [rect(2), rect(2)+rect(4), rect(1), rect(1)+rect(3)]; % [y1 y2 x1 x2]
                close(f1);
            else
                app.background_ROI = []; 
            end   
        end

        % Value changed function: ManuallydrawringsCheckBox
        function ManuallydrawringsCheckBoxValueChanged(app, event)
            value = app.ManuallydrawringsCheckBox.Value;
            app.imgType = '*.tif';
            if value
                opts=dhot_opts();
                opts.vortex_modes= app.vortexMode; 
                opts.niters = app.gsitr;
                opts.lens=app.lens;
                opts.slm_dims = [1536 1536]*app.mult_factor;
                
                x = app.xVal/(0.5*app.slmdim); % convert to [-1, 1] range
                y = app.yVal/(0.5*app.slmdim); % convert to [-1, 1] range
                
                P = [x + app.xshifts, y + app.yshifts];
                
                app.xspm = app.YShiftSpinner.Value;
                app.yspm = app.XShiftSpinner.Value;
                
                % Iterate through all the zernike polynomial coefficients
                
                opts.abberationCorrection = 1;
                
                opts.zernike = [0 0 0 0 0 0 0 0 0 0 0 0 0 0]; %[-0.0337 0.0153 0 0 0 0 0 0 0 0 0 0 0 0];
                [m, ~]=dhot(P, opts, 0);
                m = add_virtualLens(m, opts);           
                ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);           
                ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
                msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
                replication = 1/app.target_mult_factor;
                M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
                imwrite(double(M), 'currentMask.tif');
                phaseMask = imread('currentMask.tif'); % uint8 
                if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established                
                    calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM            
                end
                pause(0.1);
                    
                tiffiles = dir([app.liveimg_path, app.imgType]);
                IMG = imread([app.liveimg_path tiffiles(end).name]);
                handleToMessageBox = msgbox('Draw a circle around the ring', 'Manually Identify Ring Boundary','help');
                pause(3)
                if exist('handleToMessageBox', 'var')
                    delete(handleToMessageBox);
                end
                f1 = figure(1); 
                imshow(IMG, [4000, 20000]);
                el = imellipse;
                pause(25);
                app.manual_ring = el.getPosition; % [yc xc ry rx]
                close(f1);
            else
                app.manual_ring = []; 
            end   
        end

        % Callback function
        function DrawLineButtonPushed(app, event)
             tiffiles = dir([app.liveimg_path, app.imgType]);
             IMG = imread([app.liveimg_path tiffiles(end).name]);
             figure(1); 
             imshow(IMG);
             roi = imline;
             pause(10);
             app.lineCoordinates = roi.getPosition; % [x1 y1; x2 y2]
        end

        % Button pushed function: SelectCoefficientsButton
        function SelectCoefficientsButtonPushed(app, event)
            opts=dhot_opts();
            opts.vortex_modes= app.vortexMode; 
            opts.niters = app.gsitr;
            opts.lens=app.lens;
            opts.slm_dims = [1536 1536]*app.mult_factor;
            
            x = app.xVal/(0.5*app.slmdim); % convert to [-1, 1] range
            y = app.yVal/(0.5*app.slmdim); % convert to [-1, 1] range
            
            P = [x + app.xshifts, y + app.yshifts];
            
            app.xspm = app.YShiftSpinner.Value;
            app.yspm = app.XShiftSpinner.Value;
            
            % Iterate through all the zernike polynomial coefficients
            
            opts.abberationCorrection = 1;
            zernike_list = zeros(1,14);
            chkboxes = [app.C1_n1CheckBox, app.C1_1CheckBox, app.C2_n2CheckBox, app.C2_0CheckBox, app.C2_2CheckBox, app.C3_n3CheckBox, app.C3_n1CheckBox, app.C3_1CheckBox, app.C3_3CheckBox, app.C4_n4CheckBox, app.C4_n2CheckBox, app.C4_0CheckBox, app.C4_2CheckBox, app.C4_4CheckBox]; 
            for itr = 1:14
                for c = -0.1:0.01:0.1
                    zernike_list(itr) = c;
                    opts.zernike = zernike_list;
                    [m, ~]=dhot(P, opts, 0);
                    m = add_virtualLens(m, opts);           
                    ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);           
                    ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
                    msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
                    replication = 1/app.target_mult_factor;
                    M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
                    imwrite(double(M), 'currentMask.tif');
                    phaseMask = imread('currentMask.tif'); % uint8 
                    if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established                
                        calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM            
                    end
                    pause(0.1);
                    tiffiles = dir([app.liveimg_path, app.imgType]);
                    IMG = imread([app.liveimg_path tiffiles(end).name]);
                    f1 = figure(1);
                    imshow(IMG, [5000, 20000]);
                    pause(2)
                end
                handleToMessageBox = msgbox('Press Enter if you want to select this coefficient. Otherwise press Delete', 'Coefficient Selection','help');
                pause(2)
                if exist('handleToMessageBox', 'var')
                    delete(handleToMessageBox);
                end
                waitforbuttonpress;
                val = double(get(gcf,'CurrentCharacter'));
                if val == 13
                    chkboxes(itr).Value = 1;
                else
                    chkboxes(itr).Value = 0;
                end
                close(f1)
                zernike_list(itr) = 0;
            end
        end

        % Button pushed function: SelectRangeButton
        function SelectRangeButtonPushed(app, event)
            chkboxes = [app.C1_n1CheckBox, app.C1_1CheckBox, app.C2_n2CheckBox, app.C2_0CheckBox, app.C2_2CheckBox, app.C3_n3CheckBox, app.C3_n1CheckBox, app.C3_1CheckBox, app.C3_3CheckBox, app.C4_n4CheckBox, app.C4_n2CheckBox, app.C4_0CheckBox, app.C4_2CheckBox, app.C4_4CheckBox]; 
            zernike_list = zeros(1,14);
            zernike_init = zeros(1,14);
            opts=dhot_opts();
            opts.vortex_modes= app.vortexMode; 
            opts.niters = app.gsitr;
            opts.lens=app.lens;
            opts.slm_dims = [1536 1536]*app.mult_factor;
            
            x = app.xVal/(0.5*app.slmdim); % convert to [-1, 1] range
            y = app.yVal/(0.5*app.slmdim); % convert to [-1, 1] range
            
            P = [x + app.xshifts, y + app.yshifts];
            
            app.xspm = app.YShiftSpinner.Value;
            app.yspm = app.XShiftSpinner.Value;
            
            % Iterate through all the zernike polynomial coefficients
            
            opts.abberationCorrection = 1;
            
            for itr = 1:14
                if chkboxes(itr).Value
                    handleToMessageBox = msgbox('Only keep the best output figure, and close other figures', 'Range for different coefficients','help');
                    pause(2)
                    if exist('handleToMessageBox', 'var')
                        delete(handleToMessageBox);
                    end
                    for val = -0.02:0.004:0.02
                        zernike_list(itr) = val;
                        opts.zernike = zernike_list;
                        [m, ~]=dhot(P, opts, 0);
                        m = add_virtualLens(m, opts);           
                        ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);           
                        ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
                        msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
                        replication = 1/app.target_mult_factor;
                        M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
                        imwrite(double(M), 'currentMask.tif');
                        phaseMask = imread('currentMask.tif'); % uint8 
                        if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established                
                            calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM            
                        end
                        pause(0.2);
                        tiffiles = dir([app.liveimg_path, app.imgType]);
                        IMG = imread([app.liveimg_path tiffiles(end).name]);
                        figNum = round(val/0.004 + 6);
                        f(figNum) = figure(figNum);
                        imshow(IMG, [4000, 10000]);
                    end
                    
                    wait = 0;
                    while wait<100
                        tot = 0;
                        for i = 1:11
                            if ishandle(f(i)) && strcmp(get(f(i), 'type'), 'figure')
                                zernike_init(itr) = -0.02 + 0.004*(i-1); 
                                tot = tot + 1;
                            end
                        end
                        
                        if tot == 1
                            break;
                        elseif tot == 0
                            zernike_init(itr) = 0; % default initial value
                        end
                        pause(0.5)
                    end
                    
                    for i = 1:11
                        if ishandle(f(i)) && strcmp(get(f(i), 'type'), 'figure')
                            close(f(i))
                        end
                    end
                        
                end   
            end
            app.zernike_init = zernike_init;
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            zernike_selections = zeros(1,14);
            zernike_list = zeros(1,14);
            app.zernike_init = zeros(1,14);
            chkboxes = [app.C1_n1CheckBox, app.C1_1CheckBox, app.C2_n2CheckBox, app.C2_0CheckBox, app.C2_2CheckBox, app.C3_n3CheckBox, app.C3_n1CheckBox, app.C3_1CheckBox, app.C3_3CheckBox, app.C4_n4CheckBox, app.C4_n2CheckBox, app.C4_0CheckBox, app.C4_2CheckBox, app.C4_4CheckBox]; 
            for itr = 1:14
                zernike_selections(itr) = chkboxes(itr).Value;
            end
            
            opts=dhot_opts();
            opts.vortex_modes= app.vortexMode; 
            opts.niters = app.gsitr;
            opts.lens=app.lens;
            opts.slm_dims = [1536 1536]*app.mult_factor;
            
            x = app.xVal/(0.5*app.slmdim); % convert to [-1, 1] range
            y = app.yVal/(0.5*app.slmdim); % convert to [-1, 1] range
            
            P = [x + app.xshifts, y + app.yshifts];
            app.xspm = app.YShiftSpinner.Value;
            app.yspm = app.XShiftSpinner.Value;
            
            
            ellipticity = app.EllipticitylongaxisshortaxisEditField.Value;
            num_Axis = app.NumberofaxesSpinner.Value;
            el_tolerence = app.EllipticitytolerenceSpinner.Value;
            
            e = [];
                  
            opts.abberationCorrection = 1;
            opts.zernike = zernike_list;
            
            opt_coefficients = zeros(1,14);
            
            filename = [app.output_path 'testResults.txt'];
            fid = fopen(filename, 'wt');
            fprintf(fid, '%s\t%s\t\n', 'live_imgpath',app.liveimg_path);
            fprintf(fid, '%s\t%s\t\n', 'output_directory',app.output_path);
            fprintf(fid, '%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\n', 'x_center','y_center','beamlet_x', 'beamlet_y', 'lens', 'vortexMode','highPixel_mode', 'ellipticity', 'ellipticity_tol', 'num_points_uniformity', 'C1_n1','C1_1','C2_n2','C2_0', 'C2_2','C3_n3','C3_n1','C3_1','C3_3','C4_n4', 'C4_n2','C4_0','C4_2','C4_4', 'PM_xshift','PM_yshift','Automated','Elapsed_time');  % header
            fclose(fid);
            
            zernike_list(1) = 0; %-0.0337;
            zernike_list(2) = 0; %0.0153;
            disp('Round 1'); 

            tst = tic;
            for itr = 1:14
                if zernike_selections(itr)
                    
                    start = app.zernike_init(itr) - 0.01;
                    stop = app.zernike_init(itr) + 0.01;
                    stepSize = 0.001;
                    
                    e = [];
                    error = Inf;
                    
                    for i = start:stepSize:stop
                        zernike_list(itr) = i;
                        opts.zernike = zernike_list;
                        [m, ~]=dhot(P, opts, 0);
                        m = add_virtualLens(m, opts);           
                        ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);           
                        ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
                        msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
                        replication = 1/app.target_mult_factor;
                        M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
                        imwrite(double(M), 'currentMask.tif');
                        pause(0.05)
                        phaseMask = imread('currentMask.tif'); % uint8                  
                        if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established                
                            calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM            
                        end
                        pause(0.1);
                        
                        err = calcError(app.liveimg_path,app.output_path,ellipticity,num_Axis,2,app.central_spot,app.selected_ROI,app.background_ROI, app.manual_ring, app.intensity_threshold, app.lineCoordinates);
                        
                        if err<error
                            opt_coefficients(itr) = i;  
                            error = err;
                        end
                        
                        tmp = [err;i];
                        e = [e tmp];
                    end
                    figure(itr);
                    plot(e(2,:),e(1,:));
                    zernike_list(itr) = 0;
                    assignin('base','e',e);
                    fname = [app.output_path 'e' num2str(itr) '.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                end
            end
            
            [~,J] = max(abs(opt_coefficients));
            zernike_selections(J) = 0;
            zernike_list(J) = opt_coefficients(J);
            
            t_itr = toc(tst);
            
            dlmwrite([app.output_path 'testResults.txt'],[app.xVal,app.yVal,app.xshifts(1),app.yshifts(1),app.lens,app.vortexMode(1), app.highPixel_mode, ellipticity, el_tolerence, num_Axis, zernike_list(1), zernike_list(2),zernike_list(3),zernike_list(4),zernike_list(5),zernike_list(6),zernike_list(7),zernike_list(8),zernike_list(9),zernike_list(10),zernike_list(11),zernike_list(12),zernike_list(13),zernike_list(14), app.yspm, app.xspm, app.ao_automated, t_itr],'delimiter','\t','-append');
            
            tst = tic;
            
            disp('Round 2');
            
            opt_coefficients = zeros(1,14);
            
            for itr = 1:14
                if zernike_selections(itr)
                    
                    start = app.zernike_init(itr) - 0.01;
                    stop = app.zernike_init(itr) + 0.01;
                    stepSize = 0.001;
                    
                    e = [];
                    error = Inf;
                    
                    for i = start:stepSize:stop
                        zernike_list(itr) = i;
                        opts.zernike = zernike_list;
                        [m, ~]=dhot(P, opts, 0);
                        m = add_virtualLens(m, opts);           
                        ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);           
                        ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
                        msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
                        replication = 1/app.target_mult_factor;
                        M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
                        imwrite(double(M), 'currentMask.tif');
                        pause(0.05)
                        phaseMask = imread('currentMask.tif'); % uint8                  
                        if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established                
                            calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM            
                        end
                        pause(0.1);
                        
                        err = calcError(app.liveimg_path,app.output_path,ellipticity,num_Axis,2,app.central_spot,app.selected_ROI,app.background_ROI, app.manual_ring, app.intensity_threshold, app.lineCoordinates);
                        
                        if err<error
                            opt_coefficients(itr) = i;  
                            error = err;
                        end
                        
                        tmp = [err;i];
                        e = [e tmp];
                    end
                    figure(itr);
                    plot(e(2,:),e(1,:));
                    zernike_list(itr) = 0;
                    assignin('base','e',e);
                    fname = [app.output_path 'e' num2str(itr) '.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                end
            end
            
            [~,J] = max(abs(opt_coefficients));
            zernike_selections(J) = 0;
            zernike_list(J) = opt_coefficients(J);
            
            t_itr = toc(tst);
            
            dlmwrite([app.output_path 'testResults.txt'],[app.xVal,app.yVal,app.xshifts(1),app.yshifts(1),app.lens,app.vortexMode(1), app.highPixel_mode, ellipticity, el_tolerence, num_Axis, zernike_list(1), zernike_list(2),zernike_list(3),zernike_list(4),zernike_list(5),zernike_list(6),zernike_list(7),zernike_list(8),zernike_list(9),zernike_list(10),zernike_list(11),zernike_list(12),zernike_list(13),zernike_list(14), app.yspm, app.xspm, app.ao_automated, t_itr],'delimiter','\t','-append');
            
            
            disp('Round 3'); % Run through all the remaining coefficients
            
            opt_coefficients = zernike_list;
            
            for itr = 1:14
                if zernike_selections(itr)
                    tst = tic;
                    start = app.zernike_init(itr) - 0.01;
                    stop = app.zernike_init(itr) + 0.01;
                    stepSize = 0.001;
                    
                    e = [];
                    error = Inf;
                    
                    for i = start:stepSize:stop
                        zernike_list(itr) = i;
                        opts.zernike = zernike_list;
                        [m, ~]=dhot(P, opts, 0);
                        m = add_virtualLens(m, opts);           
                        ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);           
                        ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
                        msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
                        replication = 1/app.target_mult_factor;
                        M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
                        imwrite(double(M), 'currentMask.tif');
                        pause(0.05)
                        phaseMask = imread('currentMask.tif'); % uint8                  
                        if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established                
                            calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM            
                        end
                        pause(0.1);
                        
                        err = calcError(app.liveimg_path,app.output_path,ellipticity,num_Axis,2,app.central_spot,app.selected_ROI,app.background_ROI, app.manual_ring, app.intensity_threshold, app.lineCoordinates);
                        
                        if err<error
                            opt_coefficients(itr) = i;  
                            error = err;
                        end
                        
                        tmp = [err;i];
                        e = [e tmp];
                    end
                    figure(itr);
                    plot(e(2,:),e(1,:));
                    zernike_list(itr) = opt_coefficients(itr);
                    assignin('base','e',e);
                    fname = [app.output_path 'e' num2str(itr) '.mat'];
                    evalin('base',['save(''' fname ''' , ''e'' )']);
                    
                    t_itr = toc(tst);
                    dlmwrite([app.output_path 'testResults.txt'],[app.xVal,app.yVal,app.xshifts(1),app.yshifts(1),app.lens,app.vortexMode(1), app.highPixel_mode, ellipticity, el_tolerence, num_Axis, zernike_list(1), zernike_list(2),zernike_list(3),zernike_list(4),zernike_list(5),zernike_list(6),zernike_list(7),zernike_list(8),zernike_list(9),zernike_list(10),zernike_list(11),zernike_list(12),zernike_list(13),zernike_list(14), app.yspm, app.xspm, app.ao_automated, t_itr],'delimiter','\t','-append');
            
                end
            end
            
            % Use the optimum values to display the the final result 
            opts=dhot_opts();
            opts.vortex_modes= app.vortexMode; 
            opts.niters = app.gsitr;
            opts.lens=app.lens;
            opts.slm_dims = [1536 1536]*app.mult_factor;
            
            x = app.xVal/(0.5*app.slmdim); % convert to [-1, 1] range
            y = app.yVal/(0.5*app.slmdim); % convert to [-1, 1] range
            
            P = [x + app.xshifts, y + app.yshifts];
            
            app.xspm = app.YShiftSpinner.Value;
            app.yspm = app.XShiftSpinner.Value;
            
            opts.abberationCorrection = 1;
            opts.zernike = opt_coefficients;
            
            [m, ~]=dhot(P, opts, 0);
            m = add_virtualLens(m, opts);           
            ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);           
            ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
            msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
            replication = 1/app.target_mult_factor;
            M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
            imwrite(double(M), 'currentMask.tif');
            phaseMask = imread('currentMask.tif'); % uint8 
            if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established                
                calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM            
            end
            
            pause(2);
            tiffiles = dir([app.liveimg_path, app.imgType]);
            Init = imread([app.liveimg_path tiffiles(1).name]);
            IMG = imread([app.liveimg_path tiffiles(end).name]);
            imwrite(IMG, [app.output_path ,'IMG_final.tiff']);
            imwrite(Init, [app.output_path ,'IMG_initial.tiff']);
            figure(15); imshow(Init,[3500,40000]);
            figure(16); imshow(IMG,[3500,40000]);
            
            
        end

        % Value changed function: TipSlider
        function TipSliderValueChanged(app, event)
            c1 = app.TipSlider.Value;
            c2 = app.TiltSlider.Value;
            c3 = app.Astigmatism1Slider.Value;
            c5 = app.Astigmatism2Slider.Value;
            c6 = app.Trefoil1Slider.Value;
            c7 = app.Coma1Slider.Value;
            c8 = app.Coma2Slider.Value;
            c9 = app.Trefoil2Slider.Value;
            c10 = app.Spherical1Slider.Value;
            c11 = app.Spherical2Slider.Value;
            c13 = app.Spherical3Slider.Value;
            c14 = app.Spherical4Slider.Value;
            
            opts=dhot_opts();
            opts.vortex_modes= app.vortexMode; 
            opts.niters = app.gsitr;
            opts.lens=app.lens;
            opts.slm_dims = [1536 1536]*app.mult_factor;
            
            x = app.xVal/(0.5*app.slmdim); % convert to [-1, 1] range
            y = app.yVal/(0.5*app.slmdim); % convert to [-1, 1] range
            
            P = [x + app.xshifts, y + app.yshifts];
            
            app.xspm = app.YShiftSpinner.Value;
            app.yspm = app.XShiftSpinner.Value;
            
            
            % Iterate through all the zernike polynomial coefficients
            
            opts.abberationCorrection = 1;
            
            opts.zernike = [c1 c2 c3 0 c5 c6 c7 c8 c9 c10 c11 0 c13 c14];
%             opts.zernike = [0 0 0.006 0 -0.009 0 0 0 0 0 0 0 -0.009 0];
            [m, ~]=dhot(P, opts, 0);
            m = add_virtualLens(m, opts);           
            ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);           
            ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
            msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
            replication = 1/app.target_mult_factor;
            M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
            imwrite(double(M), 'currentMask.tif');
            phaseMask = imread('currentMask.tif'); % uint8 
            if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established                
                calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM            
            end
            pause(0.1);
        end

        % Value changed function: TiltSlider
        function TiltSliderValueChanged(app, event)
            app.TipSliderValueChanged;
            
        end

        % Value changed function: Astigmatism1Slider
        function Astigmatism1SliderValueChanged(app, event)
            app.TipSliderValueChanged;
            
        end

        % Value changed function: Astigmatism2Slider
        function Astigmatism2SliderValueChanged(app, event)
            app.TipSliderValueChanged;
            
        end

        % Value changed function: Trefoil1Slider
        function Trefoil1SliderValueChanged(app, event)
            app.TipSliderValueChanged;
            
        end

        % Value changed function: Coma1Slider
        function Coma1SliderValueChanged(app, event)
            app.TipSliderValueChanged;
            
        end

        % Value changed function: Coma2Slider
        function Coma2SliderValueChanged(app, event)
            app.TipSliderValueChanged;
            
        end

        % Value changed function: Trefoil2Slider
        function Trefoil2SliderValueChanged(app, event)
            app.TipSliderValueChanged;
            
        end

        % Value changed function: Spherical1Slider
        function Spherical1SliderValueChanged(app, event)
            app.TipSliderValueChanged;
            
        end

        % Value changed function: Spherical2Slider
        function Spherical2SliderValueChanged(app, event)
            app.TipSliderValueChanged;
            
        end

        % Value changed function: Spherical3Slider
        function Spherical3SliderValueChanged(app, event)
            app.TipSliderValueChanged;
            
        end

        % Value changed function: Spherical4Slider
        function Spherical4SliderValueChanged(app, event)
            app.TipSliderValueChanged;
            
        end

        % Button pushed function: EvaluatePointsButton
        function EvaluatePointsButtonPushed(app, event)
            opts=dhot_opts();
            opts.vortex_modes= app.vortexMode; 
            opts.niters = app.gsitr;
            opts.lens=app.lens;
            opts.slm_dims = [1536 1536]*app.mult_factor;
            
            x = app.xVal/(0.5*app.slmdim); % convert to [-1, 1] range
            y = app.yVal/(0.5*app.slmdim); % convert to [-1, 1] range
            
            P = [x + app.xshifts, y + app.yshifts];
            
            error = Inf;
            y_opt = 0;
            xc = 0;
            e = [];
            
%             for yc = -50:5:50
%                 for xc = -50:5:50
%                     app.xspm = xc;
%                     app.yspm = yc;
%                     [m, ~]=dhot(P, opts, 0);
%                     m = add_virtualLens(m, opts);           
%                     ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);           
%                     ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
%                     msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
%                     replication = 1/app.target_mult_factor;
%                     M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
%                     imwrite(double(M), 'currentMask.tif');
%                     pause(0.1)
%                     phaseMask = imread('currentMask.tif'); % uint8                  
%                     if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established                
%                         calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM            
%                     end
%                     pause(0.1);  
%                     err = calcError(app.liveimg_path,app.output_path,0,50,3,app.central_spot,app.selected_ROI,app.background_ROI, app.manual_ring, app.intensity_threshold, app.lineCoordinates);
%                     e = [e, err];
%                 end
%                 yc
%             end
            
            for yc = -250:10:250
                app.xspm = xc;
                app.yspm = yc;
                [m, ~]=dhot(P, opts, 0);
                m = add_virtualLens(m, opts);           
                ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);           
                ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
                msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
                replication = 1/app.target_mult_factor;
                M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
                imwrite(double(M), 'currentMask.tif');
                pause(0.1)
                phaseMask = imread('currentMask.tif'); % uint8                  
                if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established                
                    calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM            
                end
                pause(0.1);  
                err = calcError(app.liveimg_path,app.output_path,0,50,3,app.central_spot,app.selected_ROI,app.background_ROI, app.manual_ring, app.intensity_threshold, app.lineCoordinates);
                e = [e, err];
                if err<error
                    error = err;
                    y_opt = yc;
                end
            end
            figure(1); plot(-250:10:250,e);
            xlabel('Phase Mask Shift in Y');
            ylabel('Error');
            
            e = [];
            x_opt = 0;
            error = Inf;
            yc = y_opt
            
            for xc = -250:10:250
                app.xspm = xc;
                app.yspm = yc;
                [m, ~]=dhot(P, opts, 0);
                m = add_virtualLens(m, opts);           
                ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);           
                ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
                msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
                replication = 1/app.target_mult_factor;
                M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
                imwrite(double(M), 'currentMask.tif');
                pause(0.05)
                phaseMask = imread('currentMask.tif'); % uint8                  
                if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established                
                    calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM            
                end
                pause(0.1); 
                if xc == -250
                    pause(1);
                end
                err = calcError(app.liveimg_path,app.output_path,0,50,3,app.central_spot,app.selected_ROI,app.background_ROI, app.manual_ring, app.intensity_threshold, app.lineCoordinates);
                e = [e, err];
                if err<error
                    error = err;
                    x_opt = xc;
                end
            end
            
            figure(2); plot(-250:10:250,e);
            xlabel('Phase Mask Shift in X');
            ylabel('Error');
            
            e = [];
            y_opt = 0;
            error = Inf;
            xc = x_opt
            
            for yc = -250:10:250
                app.xspm = xc;
                app.yspm = yc;
                [m, ~]=dhot(P, opts, 0);
                m = add_virtualLens(m, opts);           
                ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);           
                ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
                msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
                replication = 1/app.target_mult_factor;
                M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
                imwrite(double(M), 'currentMask.tif');
                pause(0.05)
                phaseMask = imread('currentMask.tif'); % uint8                  
                if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established                
                    calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM            
                end
                pause(0.1); 
                if yc == -250
                    pause(1);
                end
                err = calcError(app.liveimg_path,app.output_path,0,50,3,app.central_spot,app.selected_ROI,app.background_ROI, app.manual_ring, app.intensity_threshold, app.lineCoordinates);
                e = [e, err];
                if err<error
                    error = err;
                   y_opt = yc;
                end
            end
            
            figure(3); plot(-250:10:250,e);
            xlabel('Phase Mask Shift in Y');
            ylabel('Error');
            yc = y_opt       
            
            e = [];
            x_opt = 0;
            error = Inf;
            
            for xc = -250:10:250
                app.xspm = xc;
                app.yspm = yc;
                [m, ~]=dhot(P, opts, 0);
                m = add_virtualLens(m, opts);           
                ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);           
                ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
                msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
                replication = 1/app.target_mult_factor;
                M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
                imwrite(double(M), 'currentMask.tif');
                pause(0.05)
                phaseMask = imread('currentMask.tif'); % uint8                  
                if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established                
                    calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM            
                end
                pause(0.1); 
                if xc == -250
                    pause(1);
                end
                err = calcError(app.liveimg_path,app.output_path,0,50,3,app.central_spot,app.selected_ROI,app.background_ROI, app.manual_ring, app.intensity_threshold, app.lineCoordinates);
                e = [e, err];
                if err<error
                    error = err;
                    x_opt = xc;
                end
            end
            
            figure(4); plot(-250:10:250,e);
            xlabel('Phase Mask Shift in X');
            ylabel('Error');
            
            xc = x_opt
            
        end

        % Button pushed function: ArrayofpointsButton
        function ArrayofpointsButtonPushed(app, event)
            app.LensSpinner.Value = -1*app.lens;
            app.XSpinner.Value = app.xVal;
            app.YSpinner.Value = app.yVal;
            
            d = app.UITable.Data;
            xoff = [];
            yoff = [];
            vm = [];
            selections = [app.CheckBox.Value,app.CheckBox_2.Value,app.CheckBox_3.Value,app.CheckBox_4.Value,app.CheckBox_5.Value];
            
            % Array of points
            start = app.StartEditField.Value;
            stop = app.StopEditField.Value;
            size = app.SizeEditField.Value;
            
            xVals = linspace(start,stop, size)
            yVals = linspace(start,stop, size)
            for itr = 1:size
                for j = 1: size
                    xoff = [xoff; xVals(itr)/1000]; % convert um to mm, need to be calibrated
                    yoff = [yoff; yVals(j)/1000];
                    vm = [vm, 0]; % vortex modes
                end 
            end
            
            app.vortexMode = vm;
            app.xshifts = xoff;
            app.yshifts = yoff;
            
            opts=dhot_opts();
            opts.vortex_modes= app.vortexMode; 
            opts.niters = app.gsitr;
            opts.lens=app.lens;
            opts.slm_dims = [1536 1536]*app.mult_factor;
            
            x = app.xVal/(0.5*app.slmdim); % convert to [-1, 1] range
            y = app.yVal/(0.5*app.slmdim); % convert to [-1, 1] range
            
            P = [x + app.xshifts, y + app.yshifts];
            
            tstart = tic;
            [m, IMG]=dhot(P, opts, app.display_intensity);
            t = toc(tstart);
            fprintf('Time taken for dhot calculations before updating the display is %.2f seconds \n',t);
            
            app.xspm = app.YShiftSpinner.Value;
            app.yspm = app.XShiftSpinner.Value;
            
            app.mask = m;
            
            % 05/09/21 Dulara moved this section to the GUI code for speeding up the calculations
            m = add_virtualLens(app.mask, opts);
            app.mask_v = m;
            
            ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);
            ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
            msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
%             msk = m;
            
%             imshow(msk,'Parent', app.UIAxes);
            app.mask_h.CData = msk;
            
            t = toc(tstart);
            fprintf('Time taken until after updating the phase mask display in the GUI is %.2f seconds \n',t);
            
            
%             if low < high
%                 imshow(IMG,'Parent', app.UIAxes2,'DisplayRange',[low,high]); % DZ 04/26/21 (set limits for better visualization)
%             else
%                 imshow(IMG,'Parent', app.UIAxes2,'DisplayRange',[low,prctile(IMG(:),100)]); % DZ 05/09/21 to handle the error of having (low = high)
%             end


            replication = 1/app.target_mult_factor;
            M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
            imwrite(double(M), 'currentMask.tif');
            
            phaseMask = imread('currentMask.tif'); % uint8
            
            t = toc(tstart); 
            
            fprintf('Total time taken for calculations before sending the phase mask to SLM is %.2f seconds \n',t);
            
            if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established
                calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM
            end
            
            t = toc(tstart); 
            
            fprintf('Total time taken until after sending the phase mask to SLM is %.2f seconds \n',t);


            if app.display_intensity
                if app.Switch.Value == "Simulated"
                    low = 500; % lower threshold for grayscale display of the intensity
                    high = prctile(IMG(:),99.9999); % DZ 05/13/21 to have a more generalized upper threshold
                    if high < low
                        low = 0;
                    end
                
                    IM = mat2gray(IMG,double([low,high])); % DZ 04/26/21 (set limits for better visualization)            
                
                               
                    crop1 = app.mult_factor*app.slmdim*(0.5 - app.zoom_factor/2); % DZ 05/13/21 only show the middle region of the intensity plane for better visualization
                    crop2 = app.mult_factor*app.slmdim*(0.5 + app.zoom_factor/2);
                
                    I = IM(crop1 + 1 : crop2, crop1 + 1 : crop2); % only show 1/6 th of the intensity plane for better visualization
                    imwrite(I, 'currentSimulation.tif');
                else
                    tiffiles = dir([app.liveimg_path, '*.tiff']);
                    I = imread([app.liveimg_path tiffiles(end).name]); 
                    I = mat2gray(I, double([0,255]));
                    
                end
                app.intensity_h.CData = I;
            end
            
            
            t = toc(tstart);
            fprintf('Time taken until after updating the beamlet display in the GUI is %.2f seconds \n',t);
            filename = 'testResults.txt';
            fid = fopen(filename, 'wt');
            fprintf(fid, '%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\n', 'x_center','y_center','xshift', 'yshift', 'lens', 'vortexMode','PM_xshift','PM_yshift','highPixel_mode', 'ellipticity', 'ellipticity_tol', 'num_Axis', 'C2_n2','C2_2','C4_n2','C4_2');  % header
            fclose(fid);
        end

        % Button pushed function: EvaluateVorticesButton
        function EvaluateVorticesButtonPushed(app, event)
            
            tst = tic;
            opts=dhot_opts();
            opts.vortex_modes= app.vortexMode; 
            opts.niters = app.gsitr;
            opts.lens=app.lens;
            opts.slm_dims = [1536 1536]*app.mult_factor;
            
            x = app.xVal/(0.5*app.slmdim); % convert to [-1, 1] range
            y = app.yVal/(0.5*app.slmdim); % convert to [-1, 1] range
            
            P = [x + app.xshifts, y + app.yshifts];
            
            %% optimize for PM centering
            imType = 1; % 1: Hamamatsu
            numBeamlets = 1;
            lb = [70,20];
            ub = [130,80];
            
            A = [];
            b = [];
            Aeq = [];
            beq = [];
            
            %x0 = (lb + ub)/2;
            x0 = [10 30];
%             e = [];
%             
%             for xs = 25:5:95
%                 for ys = -35:5:35
%                     err = errorFun([xs, ys],app.liveimg_path,app.output_path,imType,numBeamlets,P,opts,app.dll_name,app.board_number);
%                     e = [e, err];
%                 end
%             end
%             
%             assignin('base','e',e);
            
            fun = @(x)errorFun(x,app.liveimg_path,app.output_path,imType,numBeamlets,P,opts,app.dll_name,app.board_number);
            
%             options = saoptimset('TolFun',1e-3,'StallIterLimit', 100);
%             optim_xy = simulannealbnd(fun,x0,lb,ub,options);
            
            options = optimoptions('particleswarm','SwarmSize',10,'HybridFcn',@fmincon,'FunctionTolerance',1e-4);
            rng default  % For reproducibility
            nvars = 2;
            optim_xy = particleswarm(fun,nvars,lb,ub,options); % uncomment this
            
%             options = optimoptions('patternsearch','Display','iter');
%             optim_xy = patternsearch(fun,x0,A,b,Aeq,beq,lb,ub,[],options);
            
%             IntCon = [1,2];
%             rng default % For reproducibility
%             nonlcon = [];
%             optim_xy = ga(fun,2,A,b,Aeq,beq,lb,ub,nonlcon,IntCon);
            
%             options = optimoptions('fmincon','Algorithm','sqp','Display','off','FiniteDifferenceStepSize',1,'StepTolerance',1e-3);
%             options = optimoptions('fmincon','FiniteDifferenceStepSize',1,'StepTolerance',1e-3);
%             
%             problem = createOptimProblem('fmincon','objective',fun,'x0',x0,'lb', lb, 'ub', ub,'options',options);
%             gs = GlobalSearch('Display','iter','NumTrialPoints',201);
%             [optim_xy,fval] = run(gs,problem);
%             optim_xy = fmincon(fun,x0,A,b,Aeq,beq,lb,ub,[],options);
            
            xshift = optim_xy(2)
            yshift = optim_xy(1)
            
            app.XShiftSlider.Value = round(xshift);
            app.XShiftSpinner.Value = round(xshift);
            app.YShiftSlider.Value = round(yshift);
            app.YShiftSpinner.Value = round(yshift);
            app.XShiftSliderValueChanged;
            app.YShiftSliderValueChanged;
            
            toc(tst)
%             
%             err = [];
%             for x = 0:30:300
%                 for y = 0:30:300
%                     er = errorFun([x,y],app.liveimg_path,app.output_path,imType,numBeamlets,P,opts,app.dll_name,app.board_number);
%                     err = [err, er];
%                 end
%             end
%             assignin('base','err',err);
            
            disp('Starting AO');
            
            %% AO after centering
            optim_coeff = [0, 0, 0];
            imgFolder = app.liveimg_path;
            liveimg_savepath = app.output_path;
            
            
            % Astigmatism 2
            optim_coeff(1) = Astigmatism2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
            
            % Astigmatism 4
            optim_coeff(2) = Astigmatism4(app,imgFolder,liveimg_savepath,imType,numBeamlets);          
            
            % Coma
            optim_coeff(3) = Coma(app,imgFolder,liveimg_savepath,imType,numBeamlets);
            
%             % Spherical 2
%             optim_coeff(4) = Spherical2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%             
%             % Spherical 4
%             optim_coeff(5) = Spherical4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
            
            [~,ind1] = max(abs(optim_coeff));
            optim_coeff(ind1) = 0; % reset
            
            disp('Round1 Done');
            ind1
            
            if ind1 == 1
                optim_coeff(2) = Astigmatism4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
                optim_coeff(3) = Coma(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                 optim_coeff(4) = Spherical2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                 optim_coeff(5) = Spherical4(app,imgFolder,liveimg_savepath,imType,numBeamlets); 
            elseif ind1 == 2
                optim_coeff(1) = Astigmatism2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
                optim_coeff(3) = Coma(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                 optim_coeff(4) = Spherical2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                 optim_coeff(5) = Spherical4(app,imgFolder,liveimg_savepath,imType,numBeamlets); 
            elseif ind1 == 3
                optim_coeff(1) = Astigmatism2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
                optim_coeff(2) = Astigmatism4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                 optim_coeff(4) = Spherical2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                 optim_coeff(5) = Spherical4(app,imgFolder,liveimg_savepath,imType,numBeamlets); 
%             elseif ind1 == 4
%                 optim_coeff(1) = Astigmatism2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                 optim_coeff(2) = Astigmatism4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                 optim_coeff(3) = Coma(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                 optim_coeff(5) = Spherical4(app,imgFolder,liveimg_savepath,imType,numBeamlets); 
%             else
%                 optim_coeff(1) = Astigmatism2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                 optim_coeff(2) = Astigmatism4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                 optim_coeff(3) = Coma(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                 optim_coeff(4) = Spherical2(app,imgFolder,liveimg_savepath,imType,numBeamlets);   
            end
            
            [~,ind2] = max(abs(optim_coeff));
            optim_coeff(ind2) = 0; % reset
            
            disp('Round2 Done');
            ind2
            
            if ind1 == 1
                if ind2 == 2
                    optim_coeff(3) = Coma(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(4) = Spherical2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(5) = Spherical4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
                elseif ind2 == 3
                    
                    optim_coeff(2) = Astigmatism4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(4) = Spherical2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(5) = Spherical4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
                    
%                 elseif ind2 == 4
%                     optim_coeff(2) = Astigmatism4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(3) = Coma(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     
%                     optim_coeff(5) = Spherical4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     
%                 else
%                     optim_coeff(2) = Astigmatism4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(3) = Coma(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(4) = Spherical2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     
                end
                
            elseif ind1 == 2
                if ind2 == 1
                    %optim_coeff(1) = Astigmatism2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
                    optim_coeff(3) = Coma(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(4) = Spherical2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(5) = Spherical4(app,imgFolder,liveimg_savepath,imType,numBeamlets); 
                    
                    
                elseif ind2 == 3
                    optim_coeff(1) = Astigmatism2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
                    %optim_coeff(3) = Coma(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(4) = Spherical2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(5) = Spherical4(app,imgFolder,liveimg_savepath,imType,numBeamlets); 
                    
%                 elseif ind2 == 4
%                     optim_coeff(1) = Astigmatism2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(3) = Coma(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     %optim_coeff(4) = Spherical2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(5) = Spherical4(app,imgFolder,liveimg_savepath,imType,numBeamlets); 
%                     
%                 else
%                     optim_coeff(1) = Astigmatism2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(3) = Coma(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(4) = Spherical2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     %optim_coeff(5) = Spherical4(app,imgFolder,liveimg_savepath,imType,numBeamlets); 
                    
                end
                 
            elseif ind1 == 3
                if ind2 == 1
                    %optim_coeff(1) = Astigmatism2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
                    optim_coeff(2) = Astigmatism4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(4) = Spherical2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(5) = Spherical4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
                        
                elseif ind2 == 2
                    optim_coeff(1) = Astigmatism2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
                    %optim_coeff(2) = Astigmatism4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(4) = Spherical2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(5) = Spherical4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
                    
%                 elseif ind2 == 4
%                     optim_coeff(1) = Astigmatism2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(2) = Astigmatism4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     %optim_coeff(4) = Spherical2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(5) = Spherical4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     
%                 else
%                     optim_coeff(1) = Astigmatism2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(2) = Astigmatism4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(4) = Spherical2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     %optim_coeff(5) = Spherical4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
                    
                end
                 
%             elseif ind1 == 4
%                 if ind2 == 1
%                     %optim_coeff(1) = Astigmatism2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(2) = Astigmatism4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(3) = Coma(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(5) = Spherical4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                         
%                 elseif ind2 == 2
%                     optim_coeff(1) = Astigmatism2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     %optim_coeff(2) = Astigmatism4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(3) = Coma(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(5) = Spherical4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     
%                 elseif ind2 == 3
%                     optim_coeff(1) = Astigmatism2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(2) = Astigmatism4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     %optim_coeff(3) = Coma(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(5) = Spherical4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                 else
%                     optim_coeff(1) = Astigmatism2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(2) = Astigmatism4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(3) = Coma(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     %optim_coeff(5) = Spherical4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     
%                 end
%                  
%             else
%                 if ind2 == 1
%                     %optim_coeff(1) = Astigmatism2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(2) = Astigmatism4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(3) = Coma(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(4) = Spherical2(app,imgFolder,liveimg_savepath,imType,numBeamlets); 
%                     
%                 elseif ind2 == 2
%                     optim_coeff(1) = Astigmatism2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     %optim_coeff(2) = Astigmatism4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(3) = Coma(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(4) = Spherical2(app,imgFolder,liveimg_savepath,imType,numBeamlets); 
%                     
%                 elseif ind2 == 3
%                     optim_coeff(1) = Astigmatism2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(2) = Astigmatism4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     %optim_coeff(3) = Coma(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(4) = Spherical2(app,imgFolder,liveimg_savepath,imType,numBeamlets); 
%                     
%                 else
%                     optim_coeff(1) = Astigmatism2(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(2) = Astigmatism4(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     optim_coeff(3) = Coma(app,imgFolder,liveimg_savepath,imType,numBeamlets);
%                     %optim_coeff(4) = Spherical2(app,imgFolder,liveimg_savepath,imType,numBeamlets); 
%                     
%                 end
                  
            end
            
            ast2 = app.Astigmatism1Slider.Value
            ast4 = app.Astigmatism2Slider.Value
            coma1 = app.Coma1Slider.Value
%             sph2 = app.Spherical2Slider.Value
%             sph4 = app.Spherical4Slider.Value
            
            toc(tst)
            
            % Reset values after done with AO + centering
            app.Astigmatism1Slider.Value = 0;
            app.Astigmatism2Slider.Value = 0;
            app.Coma1Slider.Value = 0;
%             app.Spherical2Slider.Value = 0;
%             app.Spherical4Slider.Value = 0;


%             error = Inf;
%             y_opt = 0;
%             xc = 0;
%             e = [];
%             
%             for yc = -225:15:225
%                 for xc = -225:15:225
%                     app.xspm = xc;
%                     app.yspm = yc;
%                     [m, ~]=dhot(P, opts, 0);
%                     m = add_virtualLens(m, opts);           
%                     ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);           
%                     ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
%                     msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
%                     replication = 1/app.target_mult_factor;
%                     M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
%                     imwrite(double(M), 'currentMask.tif');
%                     pause(0.1)
%                     phaseMask = imread('currentMask.tif'); % uint8                  
%                     if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established                
%                         calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM            
%                     end
%                     pause(0.1);  
%                     err = calcError(app.liveimg_path,app.output_path,0,50,4,app.central_spot,app.selected_ROI,app.background_ROI, app.manual_ring, app.intensity_threshold, app.lineCoordinates);
%                     e = [e, err];
%                 end
%                 yc
%             end

            %%
            
%             for yc = -200:10:200
%                 app.xspm = xc;
%                 app.yspm = yc;
%                 [m, ~]=dhot(P, opts, 0);
%                 m = add_virtualLens(m, opts);           
%                 ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);           
%                 ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
%                 msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
%                 replication = 1/app.target_mult_factor;
%                 M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
%                 imwrite(double(M), 'currentMask.tif');
%                 pause(0.1)
%                 phaseMask = imread('currentMask.tif'); % uint8                  
%                 if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established                
%                     calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM            
%                 end
%                 pause(0.1);  
%                 err = calcError(app.liveimg_path,app.output_path,0,50,4,app.central_spot,app.selected_ROI,app.background_ROI, app.manual_ring, app.intensity_threshold, app.lineCoordinates);
%                 e = [e, err];
%                 if err<error
%                     error = err;
%                     y_opt = yc;
%                 end
%             end
%             figure(1); plot(-200:10:200,e);
%             xlabel('Phase Mask Shift in Y');
%             ylabel('Error');
%             
%             e = [];
%             x_opt = 0;
%             error = Inf;
%             yc = y_opt
%             
%             for xc = -200:10:200
%                 app.xspm = xc;
%                 app.yspm = yc;
%                 [m, ~]=dhot(P, opts, 0);
%                 m = add_virtualLens(m, opts);           
%                 ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);           
%                 ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
%                 msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
%                 replication = 1/app.target_mult_factor;
%                 M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
%                 imwrite(double(M), 'currentMask.tif');
%                 pause(0.05)
%                 phaseMask = imread('currentMask.tif'); % uint8                  
%                 if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established                
%                     calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM            
%                 end
%                 pause(0.1); 
%                 if xc == -200
%                     pause(1);
%                 end
%                 err = calcError(app.liveimg_path,app.output_path,0,50,4,app.central_spot,app.selected_ROI,app.background_ROI, app.manual_ring, app.intensity_threshold, app.lineCoordinates);
%                 e = [e, err];
%                 if err<error
%                     error = err;
%                     x_opt = xc;
%                 end
%             end
%             
%             figure(2); plot(-200:10:200,e);
%             xlabel('Phase Mask Shift in X');
%             ylabel('Error');
%             
%             e = [];
%             y_opt = 0;
%             error = Inf;
%             xc = x_opt
%             
%             for yc = -200:10:200
%                 app.xspm = xc;
%                 app.yspm = yc;
%                 [m, ~]=dhot(P, opts, 0);
%                 m = add_virtualLens(m, opts);           
%                 ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);           
%                 ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
%                 msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
%                 replication = 1/app.target_mult_factor;
%                 M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
%                 imwrite(double(M), 'currentMask.tif');
%                 pause(0.05)
%                 phaseMask = imread('currentMask.tif'); % uint8                  
%                 if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established                
%                     calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM            
%                 end
%                 pause(0.1); 
%                 if yc == -200
%                     pause(1);
%                 end
%                 err = calcError(app.liveimg_path,app.output_path,0,50,4,app.central_spot,app.selected_ROI,app.background_ROI, app.manual_ring, app.intensity_threshold, app.lineCoordinates);
%                 e = [e, err];
%                 if err<error
%                     error = err;
%                    y_opt = yc;
%                 end
%             end
%             
%             figure(3); plot(-200:10:200,e);
%             xlabel('Phase Mask Shift in Y');
%             ylabel('Error');
%             yc = y_opt       
%             
%             e = [];
%             x_opt = 0;
%             error = Inf;
%             
%             for xc = -200:10:200
%                 app.xspm = xc;
%                 app.yspm = yc;
%                 [m, ~]=dhot(P, opts, 0);
%                 m = add_virtualLens(m, opts);           
%                 ref1 = round(app.slmdim*(app.mult_factor - app.target_mult_factor)/2);           
%                 ref2 = round(app.slmdim*(app.mult_factor + app.target_mult_factor)/2);
%                 msk = m(ref1 + 1 + app.xspm: ref2 + app.xspm, ref1 + 1 + app.yspm: ref2 + app.yspm); % crop the mask to the original size of the SLM
%                 replication = 1/app.target_mult_factor;
%                 M = repelem(msk,replication,replication); % replicate the phase mask to get the desired number of pixels, i.e. 1536x1536
%                 imwrite(double(M), 'currentMask.tif');
%                 pause(0.05)
%                 phaseMask = imread('currentMask.tif'); % uint8                  
%                 if app.slm_on  % DZ 04/22 write the phase mask only if the SLM connection is established                
%                     calllib(app.dll_name, 'Write_overdrive_image', app.board_number, phaseMask); % write to SLM            
%                 end
%                 pause(0.1); 
%                 if xc == -200
%                     pause(1);
%                 end
%                 err = calcError(app.liveimg_path,app.output_path,0,50,4,app.central_spot,app.selected_ROI,app.background_ROI, app.manual_ring, app.intensity_threshold, app.lineCoordinates);
%                 e = [e, err];
%                 if err<error
%                     error = err;
%                     x_opt = xc;
%                 end
%             end
%             
%             figure(4); plot(-200:10:200,e);
%             xlabel('Phase Mask Shift in X');
%             ylabel('Error');
%             
%             xc = x_opt
            
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            disp('Date your calibration (YYYYMMDD)');
            CfileDate=input('Date your calibration (YYYYMMDD): ','s');
            CfileName=[CfileDate,'_CamSLMCalib'];
            disp('Where do you want the SLM metadata saved?');
            Path=uigetdir('.\calibration');
            calibrationFilePath=[Path, CfileName];
            
            disp('Select the point pattern seen by the Camera')
            [file1,path1]=uigetfile(Path,'*.tif');% point pattern seen by the camera
            disp('Select the SLM Theoretical point pattern')
            [file2,path2]=uigetfile(Path, '*.tif');% the Pattern used to calculate the hologram Target intensity pattern for the Gerchburg Saxton algorithm
            cameraPointPatternImg = [path1,file1];% intensity pattern generated by the hologram that generates 4 asymmetric points
            fakePointPatternImg = [path2,file2]; % the Pattern used to calculate the hologram Target intensity pattern for the Gerchburg Saxton algorithm
            
%             app.cameraToSlm= register(cameraPointPatternImg, fakePointPatternImg); %this is actually SLM to camera
            app.cameraToSlm= register(fakePointPatternImg,cameraPointPatternImg); 
            verify_Cam2SLM(app.cameraToSlm,cameraPointPatternImg,fakePointPatternImg);
            trafos = struct;
            trafos.cameraToSlm = app.cameraToSlm;
            
   
            save(calibrationFilePath, 'trafos');
    
        end

        % Button pushed function: SelectTargetsButton
        function SelectTargetsButtonPushed(app, event)
            disp('Select the calibration file')
            [CfileName,Path] = uigetfile('.\calibration', '*.mat');
            calibrationFilePath=[Path, CfileName];
            
            disp('Select Image to target neurons from')
            [file3,path3]=uigetfile([Path,'*.tif']);% Camera image to select neurons from
            CamTargetImg =[path3,file3]; %  Camera image to select neurons from
            
            %exprmnt_name = input('Input a name for your experiment: ','s');
            
            calFile = load(calibrationFilePath);
            app.cameraToSlm = calFile.trafos.cameraToSlm;
            
            disp('Select neurons to target from the image. Double click on the last cell')
            [P_DHOT, ~] = select_neurons_from_cam(CamTargetImg, app.cameraToSlm);
            numPoints = length(P_DHOT);
            data = horzcat(P_DHOT*1000, zeros(numPoints,1));
            
            tableData = [0 0 -1; 0 0 -1; 0 0 -1; 0 0 -1; 0 0 -1;0 0 -1; 0 0 -1; 0 0 -1; 0 0 -1; 0 0 -1]; % initialize
            
            for itr = 1:numPoints
                tableData(itr,:) = data(itr,:); % copy the coordinates returned after applying the transfer function
            end
            
            app.UITable.Data = tableData;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LensSliderLabel = uilabel(app.UIFigure);
            app.LensSliderLabel.Text = 'Lens';
            app.LensSlider = uislider(app.UIFigure);
            app.LensSlider.Limits = [0 6000];
            app.LensSlider.ValueChangedFcn = createCallbackFcn(app, @LensSliderValueChanged, true);
            app.LensSlider.MinorTicks = [];
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.LensSpinnerLabel = uilabel(app.UIFigure);
            app.LensSpinnerLabel.Text = 'Lens';
            app.LensSpinner = uispinner(app.UIFigure);
            app.LensSpinner.Step = 0.5;
            app.LensSpinner.Limits = [0 6000];
            app.LensSpinner.ValueChangedFcn = createCallbackFcn(app, @LensSpinnerValueChanged, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Intensity')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.XShiftSpinnerLabel = uilabel(app.UIFigure);
            app.XShiftSpinnerLabel.Text = 'X Shift';
            app.XShiftSpinner = uispinner(app.UIFigure);
            app.XShiftSpinner.Limits = [-64 64];
            app.XShiftSpinner.ValueDisplayFormat = '%.0f';
            app.XShiftSpinner.ValueChangedFcn = createCallbackFcn(app, @XShiftSpinnerValueChanged, true);
            app.YShiftSpinnerLabel = uilabel(app.UIFigure);
            app.YShiftSpinnerLabel.Text = 'Y Shift';
            app.YShiftSpinner = uispinner(app.UIFigure);
            app.YShiftSpinner.Limits = [-64 64];
            app.YShiftSpinner.ValueDisplayFormat = '%.0f';
            app.YShiftSpinner.ValueChangedFcn = createCallbackFcn(app, @YShiftSpinnerValueChanged, true);
            app.PhaseMaskShiftLabel = uilabel(app.UIFigure);
            app.PhaseMaskShiftLabel.Text = 'Phase Mask Shift';
            app.XShiftSliderLabel = uilabel(app.UIFigure);
            app.XShiftSliderLabel.Text = 'X Shift';
            app.XShiftSlider = uislider(app.UIFigure);
            app.XShiftSlider.Limits = [-64 64];
            app.XShiftSlider.MajorTicks = [-64 -56 -48 -40 -32 -24 -16 -8 0 8 16 24 32 40 48 56 64];
            app.XShiftSlider.ValueChangedFcn = createCallbackFcn(app, @XShiftSliderValueChanged, true);
            app.YShiftSliderLabel = uilabel(app.UIFigure);
            app.YShiftSliderLabel.Text = 'YShift';
            app.YShiftSlider = uislider(app.UIFigure);
            app.YShiftSlider.Limits = [-64 64];
            app.YShiftSlider.MajorTicks = [-64 -56 -48 -40 -32 -24 -16 -8 0 8 16 24 32 40 48 56 64];
            app.YShiftSlider.ValueChangedFcn = createCallbackFcn(app, @YShiftSliderValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X offset (microns)'; 'Y Offset (microns)'; 'Vortex Mode'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Vortex Mode'; 'Vortex: positive integer, Point: 0'; 'Only the selected rows will be considered'};
            app.GerchbergSaxtonIterationsSpinnerLabel = uilabel(app.UIFigure);
            app.GerchbergSaxtonIterationsSpinnerLabel.Text = 'Gerchberg-Saxton Iterations';
            app.GerchbergSaxtonIterationsSpinner = uispinner(app.UIFigure);
            app.GerchbergSaxtonIterationsSpinner.Limits = [1 Inf];
            app.GerchbergSaxtonIterationsSpinner.ValueChangedFcn = createCallbackFcn(app, @GerchbergSaxtonIterationsSpinnerValueChanged, true);
            app.GerchbergSaxtonIterationsSpinner.Value = 3;
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.LoadFromFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFromFileButtonPushed, true);
            app.LoadFromFileButton.Text = 'Load From File';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.UIFigure);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.UIFigure);
            app.CheckBox_5.Text = '';
            app.ZoomButton = uibutton(app.UIFigure, 'state');
            app.ZoomButton.ValueChangedFcn = createCallbackFcn(app, @ZoomButtonValueChanged, true);
            app.ZoomButton.Text = 'Zoom';
            app.ZoomButton_2 = uibutton(app.UIFigure, 'state');
            app.ZoomButton_2.ValueChangedFcn = createCallbackFcn(app, @ZoomButton_2ValueChanged, true);
            app.ZoomButton_2.Text = 'Zoom';
            app.CalculationModeSwitchLabel = uilabel(app.UIFigure);
            app.CalculationModeSwitchLabel.Text = 'Calculation Mode';
            app.CalculationModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.CalculationModeSwitch.Items = {'Low pixel', 'High pixel'};
            app.CalculationModeSwitch.ValueChangedFcn = createCallbackFcn(app, @CalculationModeSwitchValueChanged, true);
            app.CalculationModeSwitch.Value = 'Low pixel';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.OnButton = uibutton(app.UIFigure, 'state');
            app.OnButton.ValueChangedFcn = createCallbackFcn(app, @OnButtonValueChanged, true);
            app.OnButton.Text = 'On';
            app.OnButton.Value = true;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Simulated', 'Live Readout'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Simulated';
            app.AstigmatismCorrectionPanel = uipanel(app.UIFigure);
            app.AstigmatismCorrectionPanel.Title = 'Astigmatism Correction';
            app.EllipticitylongaxisshortaxisEditFieldLabel = uilabel(app.AstigmatismCorrectionPanel);
            app.EllipticitylongaxisshortaxisEditFieldLabel.Text = 'Ellipticity (long axis/ short axis)';
            app.EllipticitylongaxisshortaxisEditField = uieditfield(app.AstigmatismCorrectionPanel, 'numeric');
            app.EllipticitylongaxisshortaxisEditField.Limits = [0 1];
            app.EllipticitytolerenceSpinnerLabel = uilabel(app.AstigmatismCorrectionPanel);
            app.EllipticitytolerenceSpinnerLabel.Text = 'Ellipticity tolerence %';
            app.EllipticitytolerenceSpinner = uispinner(app.AstigmatismCorrectionPanel);
            app.EllipticitytolerenceSpinner.Step = 0.1;
            app.EllipticitytolerenceSpinner.Limits = [0 100];
            app.EllipticityLabel = uilabel(app.AstigmatismCorrectionPanel);
            app.EllipticityLabel.Text = 'Ellipticity';
            app.UniformityLabel = uilabel(app.AstigmatismCorrectionPanel);
            app.UniformityLabel.Text = 'Uniformity';
            app.NumberofaxesSpinnerLabel = uilabel(app.AstigmatismCorrectionPanel);
            app.NumberofaxesSpinnerLabel.Text = 'Number of axes';
            app.NumberofaxesSpinner = uispinner(app.AstigmatismCorrectionPanel);
            app.NumberofaxesSpinner.Limits = [1 Inf];
            app.NumberofaxesSpinner.Value = 1;
            app.LinemaximatolerenceSpinnerLabel = uilabel(app.AstigmatismCorrectionPanel);
            app.LinemaximatolerenceSpinnerLabel.Text = 'Line maxima tolerence %';
            app.LinemaximatolerenceSpinner = uispinner(app.AstigmatismCorrectionPanel);
            app.LinemaximatolerenceSpinner.Step = 0.1;
            app.LinemaximatolerenceSpinner.Limits = [0 100];
            app.ZenikepolynomialcoefficientsLabel = uilabel(app.AstigmatismCorrectionPanel);
            app.ZenikepolynomialcoefficientsLabel.Text = 'Zenike polynomial coefficients';
            app.CalculateAllButton = uibutton(app.AstigmatismCorrectionPanel, 'push');
            app.CalculateAllButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateAllButtonPushed, true);
            app.CalculateAllButton.Text = 'Calculate All';
            app.C1_n1CheckBox = uicheckbox(app.AstigmatismCorrectionPanel);
            app.C1_n1CheckBox.Text = 'C1_n1';
            app.C1_1CheckBox = uicheckbox(app.AstigmatismCorrectionPanel);
            app.C1_1CheckBox.Text = 'C1_1';
            app.C2_n2CheckBox = uicheckbox(app.AstigmatismCorrectionPanel);
            app.C2_n2CheckBox.Text = 'C2_n2';
            app.C2_0CheckBox = uicheckbox(app.AstigmatismCorrectionPanel);
            app.C2_0CheckBox.Text = 'C2_0';
            app.C3_n3CheckBox = uicheckbox(app.AstigmatismCorrectionPanel);
            app.C3_n3CheckBox.Text = 'C3_n3';
            app.C3_n1CheckBox = uicheckbox(app.AstigmatismCorrectionPanel);
            app.C3_n1CheckBox.Text = 'C3_n1';
            app.C4_n4CheckBox = uicheckbox(app.AstigmatismCorrectionPanel);
            app.C4_n4CheckBox.Text = 'C4_n4';
            app.C4_n2CheckBox = uicheckbox(app.AstigmatismCorrectionPanel);
            app.C4_n2CheckBox.Text = 'C4_n2';
            app.C2_2CheckBox = uicheckbox(app.AstigmatismCorrectionPanel);
            app.C2_2CheckBox.Text = 'C2_2';
            app.C3_1CheckBox = uicheckbox(app.AstigmatismCorrectionPanel);
            app.C3_1CheckBox.Text = 'C3_1';
            app.C3_3CheckBox = uicheckbox(app.AstigmatismCorrectionPanel);
            app.C3_3CheckBox.Text = 'C3_3';
            app.C4_0CheckBox = uicheckbox(app.AstigmatismCorrectionPanel);
            app.C4_0CheckBox.Text = 'C4_0';
            app.C4_4CheckBox = uicheckbox(app.AstigmatismCorrectionPanel);
            app.C4_4CheckBox.Text = 'C4_4';
            app.TipTiltLabel = uilabel(app.AstigmatismCorrectionPanel);
            app.TipTiltLabel.Text = 'Tip/Tilt';
            app.AstigmatismLabel = uilabel(app.AstigmatismCorrectionPanel);
            app.AstigmatismLabel.Text = 'Astigmatism';
            app.ComaTrefoilLabel = uilabel(app.AstigmatismCorrectionPanel);
            app.ComaTrefoilLabel.Text = 'Coma/Trefoil';
            app.SphericalLabel = uilabel(app.AstigmatismCorrectionPanel);
            app.SphericalLabel.Text = 'Spherical';
            app.SelectCoefficientsButton = uibutton(app.AstigmatismCorrectionPanel, 'push');
            app.SelectCoefficientsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCoefficientsButtonPushed, true);
            app.SelectCoefficientsButton.Text = 'Select Coefficients';
            app.SelectRangeButton = uibutton(app.AstigmatismCorrectionPanel, 'push');
            app.SelectRangeButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRangeButtonPushed, true);
            app.SelectRangeButton.Text = 'Select Range';
            app.C4_2CheckBox = uicheckbox(app.AstigmatismCorrectionPanel);
            app.C4_2CheckBox.Text = 'C4_2';
            app.CalculateButton_2 = uibutton(app.AstigmatismCorrectionPanel, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.LiveImagePathEditFieldLabel = uilabel(app.UIFigure);
            app.LiveImagePathEditFieldLabel.Text = 'Live Image Path';
            app.LiveImagePathEditField = uieditfield(app.UIFigure, 'text');
            app.OutputFolderEditFieldLabel = uilabel(app.UIFigure);
            app.OutputFolderEditFieldLabel.Text = 'Output Folder';
            app.OutputFolderEditField = uieditfield(app.UIFigure, 'text');
            app.ChangeButton = uibutton(app.UIFigure, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.ChangeButton_2 = uibutton(app.UIFigure, 'push');
            app.ChangeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChangeButton_2Pushed, true);
            app.ChangeButton_2.Text = 'Change';
            app.IgnoreCentralSpotCheckBox = uicheckbox(app.UIFigure);
            app.IgnoreCentralSpotCheckBox.ValueChangedFcn = createCallbackFcn(app, @IgnoreCentralSpotCheckBoxValueChanged, true);
            app.IgnoreCentralSpotCheckBox.Text = 'Ignore Central Spot';
            app.ConsideranROICheckBox = uicheckbox(app.UIFigure);
            app.ConsideranROICheckBox.ValueChangedFcn = createCallbackFcn(app, @ConsideranROICheckBoxValueChanged, true);
            app.ConsideranROICheckBox.Text = 'Consider an ROI';
            app.SelectaRegiontoMinimizeNoiseCheckBox = uicheckbox(app.UIFigure);
            app.SelectaRegiontoMinimizeNoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectaRegiontoMinimizeNoiseCheckBoxValueChanged, true);
            app.SelectaRegiontoMinimizeNoiseCheckBox.Text = 'Select a Region to Minimize Noise';
            app.ManuallydrawringsCheckBox = uicheckbox(app.UIFigure);
            app.ManuallydrawringsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManuallydrawringsCheckBoxValueChanged, true);
            app.ManuallydrawringsCheckBox.Text = 'Manually draw rings';
            app.TipSliderLabel = uilabel(app.UIFigure);
            app.TipSliderLabel.Text = 'Tip';
            app.TipSlider = uislider(app.UIFigure);
            app.TipSlider.Limits = [-0.1 0.1];
            app.TipSlider.ValueChangedFcn = createCallbackFcn(app, @TipSliderValueChanged, true);
            app.TiltSliderLabel = uilabel(app.UIFigure);
            app.TiltSliderLabel.Text = 'Tilt';
            app.TiltSlider = uislider(app.UIFigure);
            app.TiltSlider.Limits = [-0.1 0.1];
            app.TiltSlider.ValueChangedFcn = createCallbackFcn(app, @TiltSliderValueChanged, true);
            app.Astigmatism1SliderLabel = uilabel(app.UIFigure);
            app.Astigmatism1SliderLabel.Text = 'Astigmatism1';
            app.Astigmatism1Slider = uislider(app.UIFigure);
            app.Astigmatism1Slider.Limits = [-0.1 0.1];
            app.Astigmatism1Slider.ValueChangedFcn = createCallbackFcn(app, @Astigmatism1SliderValueChanged, true);
            app.Astigmatism2SliderLabel = uilabel(app.UIFigure);
            app.Astigmatism2SliderLabel.Text = 'Astigmatism2';
            app.Astigmatism2Slider = uislider(app.UIFigure);
            app.Astigmatism2Slider.Limits = [-0.1 0.1];
            app.Astigmatism2Slider.ValueChangedFcn = createCallbackFcn(app, @Astigmatism2SliderValueChanged, true);
            app.Trefoil1SliderLabel = uilabel(app.UIFigure);
            app.Trefoil1SliderLabel.Text = 'Trefoil1';
            app.Trefoil1Slider = uislider(app.UIFigure);
            app.Trefoil1Slider.Limits = [-0.1 0.1];
            app.Trefoil1Slider.ValueChangedFcn = createCallbackFcn(app, @Trefoil1SliderValueChanged, true);
            app.Coma1SliderLabel = uilabel(app.UIFigure);
            app.Coma1SliderLabel.Text = 'Coma1';
            app.Coma1Slider = uislider(app.UIFigure);
            app.Coma1Slider.Limits = [-0.1 0.1];
            app.Coma1Slider.ValueChangedFcn = createCallbackFcn(app, @Coma1SliderValueChanged, true);
            app.Coma2SliderLabel = uilabel(app.UIFigure);
            app.Coma2SliderLabel.Text = 'Coma2';
            app.Coma2Slider = uislider(app.UIFigure);
            app.Coma2Slider.Limits = [-0.1 0.1];
            app.Coma2Slider.ValueChangedFcn = createCallbackFcn(app, @Coma2SliderValueChanged, true);
            app.Trefoil2SliderLabel = uilabel(app.UIFigure);
            app.Trefoil2SliderLabel.Text = 'Trefoil2';
            app.Trefoil2Slider = uislider(app.UIFigure);
            app.Trefoil2Slider.Limits = [-0.1 0.1];
            app.Trefoil2Slider.ValueChangedFcn = createCallbackFcn(app, @Trefoil2SliderValueChanged, true);
            app.Spherical1SliderLabel = uilabel(app.UIFigure);
            app.Spherical1SliderLabel.Text = 'Spherical1';
            app.Spherical1Slider = uislider(app.UIFigure);
            app.Spherical1Slider.Limits = [-0.1 0.1];
            app.Spherical1Slider.ValueChangedFcn = createCallbackFcn(app, @Spherical1SliderValueChanged, true);
            app.Spherical2SliderLabel = uilabel(app.UIFigure);
            app.Spherical2SliderLabel.Text = 'Spherical2';
            app.Spherical2Slider = uislider(app.UIFigure);
            app.Spherical2Slider.Limits = [-0.1 0.1];
            app.Spherical2Slider.ValueChangedFcn = createCallbackFcn(app, @Spherical2SliderValueChanged, true);
            app.Spherical3SliderLabel = uilabel(app.UIFigure);
            app.Spherical3SliderLabel.Text = 'Spherical3';
            app.Spherical3Slider = uislider(app.UIFigure);
            app.Spherical3Slider.Limits = [-0.1 0.1];
            app.Spherical3Slider.ValueChangedFcn = createCallbackFcn(app, @Spherical3SliderValueChanged, true);
            app.Spherical4SliderLabel = uilabel(app.UIFigure);
            app.Spherical4SliderLabel.Text = 'Spherical4';
            app.Spherical4Slider = uislider(app.UIFigure);
            app.Spherical4Slider.Limits = [-0.1 0.1];
            app.Spherical4Slider.ValueChangedFcn = createCallbackFcn(app, @Spherical4SliderValueChanged, true);
            app.EvaluatePointsButton = uibutton(app.UIFigure, 'push');
            app.EvaluatePointsButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluatePointsButtonPushed, true);
            app.EvaluatePointsButton.Text = 'Evaluate Points';
            app.ArrayofpointsButton = uibutton(app.UIFigure, 'push');
            app.ArrayofpointsButton.ButtonPushedFcn = createCallbackFcn(app, @ArrayofpointsButtonPushed, true);
            app.ArrayofpointsButton.Text = 'Array of points';
            app.StartEditFieldLabel = uilabel(app.UIFigure);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.UIFigure, 'numeric');
            app.StopEditFieldLabel = uilabel(app.UIFigure);
            app.StopEditFieldLabel.Text = 'Stop';
            app.StopEditField = uieditfield(app.UIFigure, 'numeric');
            app.SizeEditFieldLabel = uilabel(app.UIFigure);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EvaluateVorticesButton = uibutton(app.UIFigure, 'push');
            app.EvaluateVorticesButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateVorticesButtonPushed, true);
            app.EvaluateVorticesButton.Text = 'EvaluateVortices';
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.SelectTargetsButton = uibutton(app.UIFigure, 'push');
            app.SelectTargetsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTargetsButtonPushed, true);
            app.SelectTargetsButton.Text = 'Select Targets';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LensCalibration(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end