classdef realTimeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeuroARTUIFigure                matlab.ui.Figure
        SynchronyAnalysisCheckBox       matlab.ui.control.CheckBox
        GroupIDDropDown                 matlab.ui.control.DropDown
        GroupIDDropDownLabel            matlab.ui.control.Label
        AdjustwindowsizePanel           matlab.ui.container.Panel
        ApplyButton                     matlab.ui.control.Button
        UseacumulativewindowCheckBox    matlab.ui.control.CheckBox
        CorrelationAnalysisWindowsecondsSlider  matlab.ui.control.Slider
        CorrelationAnalysisWindowsecondsSliderLabel  matlab.ui.control.Label
        kHzLabel                        matlab.ui.control.Label
        FrameNoEditField                matlab.ui.control.NumericEditField
        FrameNoEditFieldLabel           matlab.ui.control.Label
        StimulateButton                 matlab.ui.control.Button
        StartRealTimeAnalysisButton     matlab.ui.control.Button
        TuningAnalysisCheckBox          matlab.ui.control.CheckBox
        RedChannelCheckBox              matlab.ui.control.CheckBox
        CellRankings1Red2Green3Blue45MagentaSelectedcellsYellowLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        RealTimeAnalysisTab             matlab.ui.container.Tab
        Panel_5                         matlab.ui.container.Panel
        LoadButton_2                    matlab.ui.control.Button
        UITable_2                       matlab.ui.control.Table
        Panel_2                         matlab.ui.container.Panel
        LoadButton                      matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        Panel_3                         matlab.ui.container.Panel
        SelectforstimulationCheckBox_2  matlab.ui.control.CheckBox
        UIAxes_selected                 matlab.ui.control.UIAxes
        Panel_4                         matlab.ui.container.Panel
        SelectTopSpinner                matlab.ui.control.Spinner
        SelectTopSpinnerLabel           matlab.ui.control.Label
        SelectforstimulationCheckBox    matlab.ui.control.CheckBox
        UIAxes_best                     matlab.ui.control.UIAxes
        BoxScalingTab                   matlab.ui.container.Tab
        KcEditField                     matlab.ui.control.NumericEditField
        KcEditFieldLabel                matlab.ui.control.Label
        MinneuronsperwindowEditField    matlab.ui.control.NumericEditField
        MinneuronsperwindowEditFieldLabel  matlab.ui.control.Label
        OverlapfactorEditField          matlab.ui.control.NumericEditField
        OverlapfactorEditFieldLabel     matlab.ui.control.Label
        NoofwindowsizesDropDown         matlab.ui.control.DropDown
        NoofwindowsizesDropDownLabel    matlab.ui.control.Label
        CorrelationsDropDown            matlab.ui.control.DropDown
        CorrelationsDropDownLabel       matlab.ui.control.Label
        DistancestepsizeEditField       matlab.ui.control.NumericEditField
        DistancestepsizeEditFieldLabel  matlab.ui.control.Label
        FrameRateEditField              matlab.ui.control.NumericEditField
        FrameRateEditFieldLabel         matlab.ui.control.Label
        typeDropDown                    matlab.ui.control.DropDown
        typeDropDownLabel               matlab.ui.control.Label
        micronsPerPixelEditField        matlab.ui.control.NumericEditField
        micronsPerPixelEditFieldLabel   matlab.ui.control.Label
        dimYEditField                   matlab.ui.control.NumericEditField
        dimYEditFieldLabel              matlab.ui.control.Label
        dimXEditField                   matlab.ui.control.NumericEditField
        dimXEditFieldLabel              matlab.ui.control.Label
        averagingFactorEditField        matlab.ui.control.NumericEditField
        averagingFactorEditFieldLabel   matlab.ui.control.Label
        stopFrameEditField              matlab.ui.control.NumericEditField
        stopFrameEditFieldLabel         matlab.ui.control.Label
        startFrameEditField             matlab.ui.control.NumericEditField
        startFrameEditFieldLabel        matlab.ui.control.Label
        EvaluateScalingButton           matlab.ui.control.Button
        AutoSelectButton                matlab.ui.control.Button
        CenterthewindowCheckBox         matlab.ui.control.CheckBox
        UITable_3                       matlab.ui.control.Table
        HzLabel                         matlab.ui.control.Label
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        StimulationBNSTab               matlab.ui.container.Tab
        IterationsEditField             matlab.ui.control.NumericEditField
        IterationsEditFieldLabel        matlab.ui.control.Label
        DownloadtoSLMButton             matlab.ui.control.Button
        EnableSequenceModeCheckBox      matlab.ui.control.CheckBox
        IntervalmsEditField             matlab.ui.control.NumericEditField
        IntervalmsEditFieldLabel        matlab.ui.control.Label
        PixelsLabel_2                   matlab.ui.control.Label
        PixelsLabel                     matlab.ui.control.Label
        YShiftEditField                 matlab.ui.control.NumericEditField
        YShiftEditFieldLabel            matlab.ui.control.Label
        XShiftEditField                 matlab.ui.control.NumericEditField
        XShiftEditFieldLabel            matlab.ui.control.Label
        PhaseMaskCenteringLabel         matlab.ui.control.Label
        VirtualLensEditField            matlab.ui.control.NumericEditField
        VirtualLensEditFieldLabel       matlab.ui.control.Label
        GSIterationsEditField           matlab.ui.control.NumericEditField
        GSIterationsEditFieldLabel      matlab.ui.control.Label
        VortexModeEditField             matlab.ui.control.NumericEditField
        VortexModeEditFieldLabel        matlab.ui.control.Label
        UIAxes8_2                       matlab.ui.control.UIAxes
        UIAxes8                         matlab.ui.control.UIAxes
        PrairieLinkTab                  matlab.ui.container.Tab
        IdentifyStimulatableCellsPanel  matlab.ui.container.Panel
        ClickStarttoidentifycellsthatrespondtophotostimulationLabel  matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        PhotostimulationSwitch          matlab.ui.control.Switch
        PhotostimulationSwitchLabel     matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        IPaddressEditField              matlab.ui.control.EditField
        IPaddressEditFieldLabel         matlab.ui.control.Label
        DurationEditField               matlab.ui.control.NumericEditField
        DurationEditFieldLabel          matlab.ui.control.Label
        LaserPowerEditField             matlab.ui.control.NumericEditField
        LaserPowerEditFieldLabel        matlab.ui.control.Label
        LaserNameEditField              matlab.ui.control.EditField
        LaserNameEditFieldLabel         matlab.ui.control.Label
        DelaymsEditField_2              matlab.ui.control.NumericEditField
        DelaymsEditField_2Label         matlab.ui.control.Label
        TriggerCountSpinner             matlab.ui.control.Spinner
        TriggerCountSpinnerLabel        matlab.ui.control.Label
        TriggerDropDown                 matlab.ui.control.DropDown
        TriggerDropDownLabel            matlab.ui.control.Label
        SpiralRevolutionsSpinner        matlab.ui.control.Spinner
        SpiralRevolutionsSpinnerLabel   matlab.ui.control.Label
        SpiralSizeSlider                matlab.ui.control.Slider
        SpiralSizeSliderLabel           matlab.ui.control.Label
        PatternDropDown                 matlab.ui.control.DropDown
        PatternDropDownLabel            matlab.ui.control.Label
        ZoffsetEditField                matlab.ui.control.NumericEditField
        ZoffsetEditFieldLabel           matlab.ui.control.Label
        DStimulationDropDown            matlab.ui.control.DropDown
        DStimulationDropDownLabel       matlab.ui.control.Label
        NoofTargetsSpinner              matlab.ui.control.Spinner
        NoofTargetsSpinnerLabel         matlab.ui.control.Label
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public) 
        imTemplate
        coordinates
        DFF 
        npBWout 
        rawFluo 
        roiBW2
        xcRaw 
        ycRaw 
        norm_meanIMG
        symmFLAG
        smoothwin
        r
        dffwindow
        percent
        last_init_frame
        displayWin
        batch_size 
        rank1
        rank2
        rank3
        select1
        select2
        select3
        selectedCellList
        selectedCellsAll
        selectedCellFrameID
        selectQueue
        hidden
        gap
        exptId
        exptVars
        filled
        minNpSubFluo
        maxAdjF
        numInitFrames
        mst
        mstWin
        numAvailFrames
        imagingFreq
        currentFr
        thorSyncFile
        psignalFile
        rawFileHandle
        greenChannel
        numChannels
        scope
        imgType
        ImageFile
        JiSignalInfo
        norm_meanRedIMG
        redChanDisplayed
        meanIMGhandle
        tstack
        xx
        yy
        selectedID
        loadCorrelated
        loadSelected
        groupId
        dll_name
        board_number
        SLM_ON
        SLM;
        stimShutter
        topRankedCellIDs
        target_cellIDs
        debugMode
        debugIndex
        stimulationParams
    end
    
    methods (Access = private)
        
        function [x,y] = selectDataPoints(app)
            roi = drawpoint(app.UIAxes,'Visible',"off");
            x = roi.Position(1);
            y = roi.Position(2);
        end
        
        
        function ImageClickCallback(app)
            c = get(app.UIAxes,'CurrentPoint');
            x = c(1,1);
            y = c(1,2);
            cdiff = app.coordinates - [x y];
            [~,ind] = min(sum(cdiff.^2,2));
            app.selectedID = ind;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, RTparams)
            
            axis(app.UIAxes7,'off');
            app.kHzLabel.Visible = 'off';
            app.imTemplate = RTparams.imTemplate;
            app.DFF = RTparams.DFF;
            app.npBWout = RTparams.npBWout; 
            app.rawFluo =  RTparams.rawFluo;
            app.roiBW2 = RTparams.roiBW2;
            app.xcRaw = RTparams.xcRaw;
            app.ycRaw = RTparams.ycRaw; 
            app.coordinates = [RTparams.xcRaw RTparams.ycRaw];
            app.norm_meanIMG = RTparams.norm_meanIMG;
            app.norm_meanRedIMG = RTparams.norm_meanRedIMG;
            app.symmFLAG = RTparams.symmFLAG;
            app.smoothwin = RTparams.smoothwin;
            app.r = RTparams.r;
            app.dffwindow = RTparams.dffwindow;
            app.percent = RTparams.percent;
            app.last_init_frame = RTparams.last_init_frame;
            app.currentFr = RTparams.last_init_frame-999;
            app.displayWin = RTparams.displayWin;
            app.batch_size = RTparams.batch_size;
            app.rank1 = 0;
            app.rank2 = 0; 
            app.rank3 = 0;
            app.select1 = 0;
            app.select2 = 0;
            app.select3 = 0;
            app.selectedCellList = [];
            app.selectedCellsAll = [];
            app.selectedCellFrameID = [];
            app.hidden = [];
            app.gap = RTparams.gap;
            app.exptId = RTparams.exptId;
            app.exptVars = RTparams.exptVars;
            app.filled = RTparams.filled;
            app.minNpSubFluo = RTparams.minNpSubFluo;
            app.maxAdjF = RTparams.maxAdjF;
            app.numInitFrames = RTparams.numInitFrames;
            app.mst = RTparams.mst;
            app.mstWin = RTparams.mstWin;
            app.numAvailFrames = RTparams.numAvailFrames;
            app.thorSyncFile = RTparams.thorSyncFile;
            app.psignalFile = RTparams.psignalFile;
            app.imagingFreq = RTparams.imagingFreq;
            app.rawFileHandle = RTparams.rawFileHandle;
            app.greenChannel = RTparams.greenChannel;
            app.numChannels = RTparams.numChannels;
            app.scope = RTparams.scope;
            app.imgType = RTparams.imgType;
            app.ImageFile = RTparams.ImageFile;
            app.UseacumulativewindowCheckBox.Value = ~(RTparams.mstWin);
            app.selectQueue = [1 2 3]; % First element of this array is the location to put the next selected DF/F trace
            app.JiSignalInfo = RTparams.JiSignalInfo;
            app.redChanDisplayed = 0;
            app.meanIMGhandle = [];
            app.tstack = RTparams.tstack;
            app.xx = [];
            app.yy = [];
            app.topRankedCellIDs = [];
            app.target_cellIDs = [];
            app.selectedID = 0;
            app.loadCorrelated = 0;
            app.loadSelected = 0;
            app.dll_name = RTparams.dll_name;
            app.board_number = RTparams.board_number;
            app.SLM_ON = RTparams.SLM_ON;
            app.SLM = RTparams.SLM;

            app.debugMode = 0; % Testing stimulation response (PriarieLink)
            app.debugIndex = 0;
            app.stimulationParams = [];

%             app.stimShutter = RTparams.stimShutter;
            % Load mean image
            imshow(app.norm_meanIMG,'Parent', app.UIAxes, 'DisplayRange', [0, 0.8]);
            hold(app.UIAxes,'on');
        end

        % Button pushed function: StartRealTimeAnalysisButton
        function StartRealTimeAnalysisButtonPushed(app, event)
            totStart = tic;

            addpath(genpath('tools'));
            addpath(genpath('Psignal'));
            addpath(genpath('BNS_SLM'));


            warning off;

            loadlibrary('PiUsb', 'PiUsb.h');
            [stsh, ~] = calllib('PiUsb', 'piConnectShutter', 0, 627); % Connect to stimulation USB shutter, Serial# = 627
            app.stimShutter = stsh;

            shutter = 0;
            calllib('PiUsb', 'piSetShutterState', shutter, app.stimShutter); % To make sure that the stimulation USB shutter is closed at the beginning
            
            app.meanIMGhandle = imshow(app.norm_meanIMG,'Parent', app.UIAxes, 'DisplayRange', [0, 0.8]);
            set(app.meanIMGhandle,'ButtonDownFcn',@(h,e) ImageClickCallback(app));
            set(app.meanIMGhandle, 'PickableParts', 'all');
            hold(app.UIAxes,'on');
            
            nROIs = length(app.xcRaw);
            DFFsmooth = app.DFF.smooth;

            maxDFF = max(app.DFF.smooth,[],2);
            inactiveCells = maxDFF < 65; % DZ 031523 to remove inactive cells from the correlation analysis
            
            dimX = app.exptVars.dimX;
            dimY = app.exptVars.dimY;

            T = 1/app.exptVars.frameRate;
            app.groupId = zeros(nROIs,1);
            colors = ['y', 'c', 'k'];
            
            A = max(DFFsmooth,[],2);
            B = sort(A,'ascend');
            upperLim = B(ceil(nROIs*0.90))*1.25; % 0.9 problem with NaN values
            upperLim_patch = B(ceil(nROIs*0.90))*1.5; % this value is based on the initial batch, multiply by a factor of 1.5 to better indicate much higher DF/F values occured during RT analysis
            A = min(DFFsmooth,[],2);
            B = sort(A,'descend');
            lowerLim = B(ceil(nROIs*0.90))*1.25;
            lowerLim_patch = B(ceil(nROIs*0.90));

%             upperLim = 10;
%             lowerLim = -10;
            
            DFFsmooth = horzcat(DFFsmooth,zeros(nROIs,app.batch_size));
            app.rawFluo = horzcat(app.rawFluo,zeros(nROIs,app.batch_size));
            
            offSet = (upperLim-lowerLim)*2.5; % DZ 100422 multiply by 2 to reduce overlap

            x_val = 1:app.displayWin;
            
            nRanks = app.SelectTopSpinner.Value; % number of top ranks to be displayed (default = 5)
            
            if nROIs < 250 && nROIs >= nRanks  % limit the number of allowed ROIs for speed
                h1 = plot(app.UIAxes_best,x_val,NaN(app.displayWin,nRanks));
                % display df/f of top three neurons in red, blue, green; others in magenta
                set(h1, {'color'}, cellstr(num2cell(['r'; 'g'; 'b'; repmat('m',nRanks-3,1)]))); 
                % set(h1, {'color'}, {'r'; 'g'; 'b'; 'm'; 'm'; 'm'; 'm'; 'm'; 'm'; 'm'});
            else
                nRanks = 3;
                h1 = plot(app.UIAxes_best,x_val,NaN(app.displayWin,nRanks));
                set(h1, {'color'}, {'r'; 'g'; 'b'});
            end
            
            xlim(app.UIAxes_best,[1 app.displayWin]); ylim(app.UIAxes_best,[lowerLim,lowerLim + (nRanks+1)*offSet]);
            h2 = plot(app.UIAxes_selected,x_val,NaN(app.displayWin,nROIs),'k');
            xlim(app.UIAxes_selected,[1 app.displayWin]); ylim(app.UIAxes_selected,[lowerLim,lowerLim + 8*offSet]); % DZ 030323, expected number of selected cells = 6, let the user specify this number in future. 
            
            app.UIAxes_selected.Position = [app.UIAxes_selected.Position(1:2) [app.Panel_3.Position(3:4) - 2*app.UIAxes_selected.Position(1:2)]];
            app.UIAxes_best.Position = [app.UIAxes_best.Position(1:2) [app.Panel_4.Position(3:4) - 2*app.UIAxes_best.Position(1:2)]];

            nr = floor((app.batch_size - app.last_init_frame)/app.gap);
            cellRanks = zeros(min(nROIs,nRanks),nr); % cellRanks = zeros(nROIs,1);
            allRanks = zeros(nROIs,nr);
            
            ntheta = 20;
            theta = linspace(0, 2*pi, ntheta)'; % Generate circular patches around the centroids of identified neurons
            app.xx = bsxfun(@plus, app.r .* cos(theta), app.xcRaw');
            app.yy = bsxfun(@plus, app.r .* sin(theta), app.ycRaw');
            ptc = patch(app.UIAxes, app.xx, app.yy, zeros(1,nROIs,3),'ButtonDownFcn',@(h,e) ImageClickCallback(app));

%             syncMap = imagesc(app.UIAxes9, kron(zeros(3,1200), ones(1,1)), [-1 1]); % synchrony analysis
            
            cc = []; % color code
            
            neuron(1) = plot(app.UIAxes,NaN,NaN,'Color','r','LineWidth',2,'ButtonDownFcn',@(h,e) ImageClickCallback(app));
            neuron(2) = plot(app.UIAxes,NaN,NaN,'Color','g','LineWidth',2,'ButtonDownFcn',@(h,e) ImageClickCallback(app));
            neuron(3) = plot(app.UIAxes,NaN,NaN,'Color','b','LineWidth',2,'ButtonDownFcn',@(h,e) ImageClickCallback(app));
            
            if nRanks > 3
                for neu = 4:nRanks
                    neuron(neu) = plot(app.UIAxes,NaN,NaN,'Color','m','LineWidth',2,'ButtonDownFcn',@(h,e) ImageClickCallback(app));
                end
%                 neuron(5) = plot(app.UIAxes,NaN,NaN,'Color','m','LineWidth',2,'ButtonDownFcn',@(h,e) ImageClickCallback(app));
            end
            
            selected = plot(app.UIAxes,1:ntheta,NaN(ntheta,nROIs),'Color','y','LineWidth',2,'ButtonDownFcn',@(h,e) ImageClickCallback(app));
            xlim(app.UIAxes,[1 dimX]); ylim(app.UIAxes,[1 dimY]);

            i = 1;
            
            cb = colorbar(app.UIAxes7);
            cb.Visible = 'off';
            colorbarCreated = 0;

            stimStartFrame = 1; % initialization for Priarielink photostimulation testing
            
            
            frameSize = dimX*dimY;
            itr = app.numChannels*app.last_init_frame;
            offset = 2*frameSize*app.numChannels*app.last_init_frame;
            fh = app.rawFileHandle;
            timing = zeros(10,1);
            
            count = 1;
            timing(1) = toc(totStart);
            
            
            delete(findall(app.UIAxes,'Type','hggroup')); % remove tooltip text
                
            
            while i <= app.batch_size
                
                if i == 3000
                    timing(2) = toc(totStart);
                end
                
                app.currentFr = app.last_init_frame + i;
                
                if (app.scope == 1) || (app.scope == 2) || (app.scope == 3) || (app.scope == 6 && app.imgType == ".raw")
                
                    fseek(fh,0,'eof');
                    numFramesRecorded = floor(ftell(fh)/(frameSize*2));
        
                    if numFramesRecorded > itr % detection of a new frame, assuming the raw file isn't fully preallocated
                        if app.numChannels==1 || mod(itr+1,app.numChannels) == app.greenChannel || (mod(itr+1,app.numChannels) == 0 && app.greenChannel == app.numChannels) % Only read if it belongs to the Green Channel
                            fseek(fh,offset,'bof');
                            currentIMG = reshape(fread(fh, frameSize, 'uint16=>uint16'),[dimX dimY]); % Read the (itr+1)th frame
%                             currentIMG = currentIMG'; %DZ 081822, for MM data manually converted to raw files 
                            count = 1;
                            itr = itr + 1;
                            offset = 2*itr*frameSize; % uint16 --> 2 bytes
                        else
                            count = 1;
                            itr = itr + 1;
                            offset = 2*itr*frameSize; % uint16 --> 2 bytes
                            pause(0.002);
                            continue;
                        end
                    else
                        count = count + 1;
                        if count == 3000 % wait for 1 min
                            disp('No frame was generated in allowed time');
                            count = -1;
                        else
                            pause(0.002);
                            continue;
                        end
                    end
                    
                elseif (app.scope == 2 && app.imgType == ".tif") % Only valid if Bruker spits out tif file sequences with proper indexing
                    [filepath,name,ext] = fileparts(app.ImageFile);
                    fName = fullfile(filepath, [name '_Ch' num2str(app.greenChannel) '_' sprintf('%05d',app.currentFr) '.ome' ext]); % Format of the Bruker tif filenames 
             
                    if isfile(fName) % detection of a new frame
                        currentIMG = imread(fName); % Read the new frame
                        count = 1;
                    else
                        count = count + 1;
                        if count == 3000 % wait for 1 min
                            disp('No frame was generated in allowed time');
                            count = -1;
                        else
                            pause(0.002);
                            continue;
                        end
                    end
                    
                elseif (app.scope == 6 && app.imgType == ".tif")
                    warning('off','all') % Suppress all the tiff warnings
                    
                    numFramesRecorded = 38400; %length(imfinfo(app.ImageFile)) % imfinfo takes around 2s
        
                    if numFramesRecorded > itr % detection of a new frame, assuming the raw file isn't fully preallocated
                      
                        app.tstack.nextDirectory();
                        if app.numChannels==1 || mod(itr+1,app.numChannels) == app.greenChannel || (mod(itr+1,app.numChannels) == 0 && app.greenChannel == app.numChannels) % Only read if it belongs to the Green Channel
                            
                            currentIMG = app.tstack.read();
                            count = 1;
                            itr = itr + 1;
                        else
                            count = 1;
                            itr = itr + 1;
                            pause(0.002);
                            continue;
                        end
                    else
                        count = count + 1;
                        if count == 3000 % wait for 1 min
                            disp('No frame was generated in allowed time');
                            count = -1;
                        else
                            pause(0.002);
                            continue;
                        end
                    end
                    
                else
                    count = -1;
                end
                
                if count == -1 && i == 1
                    close(gcf);
                    break;
                elseif count == -1 && i > 1
                    
                    app.selectedCellList = unique(app.selectedCellList); % remove duplicates
                    hiddenCells = app.hidden;
                    assignin("base","selectedCellList",app.selectedCellList);
                    assignin("base","selectedCellsAll",app.selectedCellsAll);
%                     assignin("base","selectedCellFrameID",app.selectedCellFrameID);
%                     assignin("base","hiddenCells",hiddenCells)
                    assignin("base","DFFsmooth",DFFsmooth);
%                     assignin("base","cellRanks",cellRanks);
%                     assignin("base","allRanks",allRanks);
                    assignin("base","F",app.rawFluo);
%                     assignin("base","mstWin_new",app.mstWin);
%                     evalin('base', ['save(''', app.exptId ''')']);
                    
                    if colorbarCreated
                        assignin("base","tuningInfo",tuningInfo);
                    end
                    
                    promptMessage = sprintf('Save TIFs with labelled cells? (Optional)');
                    button = questdlg(promptMessage, 'Real-time analysis completed', 'Yes', 'No', 'Yes');
                    if strcmpi(button, 'No')
                        close(app.NeuroARTUIFigure); % DZ 030522
                        return; % Or break or continue
                    else
                        lastFrame = saveLabelledNeurons(cellRanks(:,2:end),app.selectedCellList,hiddenCells,app.rawFluo,app.xcRaw,app.ycRaw,app.norm_meanIMG,app.numInitFrames,app.r,app.gap,app.exptId);
                    end
                    
                    promptMessage = sprintf('Images saved successfully!');
                    msgbox(promptMessage,'Success','help');
                    
                    pause(3);
                    close(app.NeuroARTUIFigure); % DZ 030522

                    unloadlibrary('PiUsb'); % unload library for USB shutter control
                    
%                     promptMessage = sprintf('Do you want to manually select ROIs?');
%                     button = questdlg(promptMessage, 'Real-time analysis completed', 'Yes', 'No', 'Yes');
%                     if strcmpi(button, 'No')
%                         return; % Or break or continue
%                     else
%                         showFrame(lastFrame);
%                     end
                
                    break
                end
    
    
                %% Register the current frame (Using Sam's motion correction code)
    
                [currentRegIMG, ~, ~] = regseqRT(app.imTemplate,currentIMG);

                if i == 3000
                    timing(3) = toc(totStart);
                end
    
                % Compute DF/F of the current frame 
                
                [newDFF, rawFluoNew,~,~,~,~] = RTcomputeDFF(app.roiBW2,app.npBWout,currentRegIMG,app.dffwindow,app.percent, app.rawFluo(:,app.currentFr-1-app.dffwindow:app.currentFr-1), T, app.symmFLAG, app.smoothwin,app.minNpSubFluo,app.maxAdjF,app.filled); %return updated DFF trace
                
                assignin("base","newDFF",newDFF);
                

                if i == 3000
                    timing(4) = toc(totStart);
                end
                
                % append new DFF and raw fluorescence value
    
                DFFsmooth(:,app.currentFr-app.smoothwin+1:app.currentFr) = newDFF;
                app.rawFluo(:,app.currentFr-app.smoothwin+1:app.currentFr) = rawFluoNew;
    
                %% Minimum spanning tree / correlation based approach to find the most population-correlated cells
                %% update grayscale patches to indicate DF/F
    
                %% Indicate the most critical cells for excitation --> Top 3: green,magenta,blue 4,5: blue (update the figure every 30 frames)
    
                if app.currentFr > app.numAvailFrames && mod(i,app.gap)==0 && i>app.smoothwin % update every 15 frames (if app.gap = 15), avoid displaying frames that were acquired during cell finding

                    app.FrameNoEditField.Value = app.currentFr;

                    if app.debugMode % do not update cell rankings while identifying stimulatable neurons (debug mode)
                       
                        if app.debugIndex == length(app.target_cellIDs) %  finished testing all the considered neurons
                            % find which neurons responded (based on DF/F) to photostimulation
                            dff = DFFsmooth(:,1:app.currentFr); % DF/F traces upto current frame
                            stimDFF = DFFsmooth(:,stimStartFrame:app.currentFr); % DF/F traces corresponding to the duration of photostimulation
                            responses = findStimulatableNeurons(app.target_cellIDs,dff,stimDFF); % evaluate whether the neurons responded to photostimulation
                            
                            app.UITable2.Data = table(app.xcRaw(app.target_cellIDs),app.ycRaw(app.target_cellIDs),responses); % update table to indicate responded cells (response = 1)
                            app.debugMode = 0; % reset variables after finishing testing
                            app.debugIndex = 0; % reset variables after finishing testing
                            app.ClickStarttoidentifycellsthatrespondtophotostimulationLabel.Text = "Testing completed!";

                        else
                            if app.PhotostimulationSwitch.Value == "Simultaneous"
                                stimStartFrame = app.currentFr;
                                app.debugIndex = length(app.target_cellIDs);
                                target_cells = horzcat(app.xcRaw(app.target_cellIDs),app.ycRaw(app.target_cellIDs)); % stimulate all neurons simultaneously
                            else % sequential stimulation mode
                                if (app.debugIndex == 0); stimStartFrame = app.currentFr; end % frame number where we start stimulation
                                app.debugIndex = app.debugIndex + 1;
                                target_cells = [app.xcRaw(app.target_cellIDs(app.debugIndex)),app.ycRaw(app.target_cellIDs(app.debugIndex))]; % one neuron at a time
                            end
                            stimulatePrairieLink(target_cells, app.stimulationParams, app.exptVars.dimX, app.exptVars.dimY); % send commands to PrairieLink
                        end
                    else
                        if (~app.mstWin)
                            cellRank = selectCorrNeuron(DFFsmooth(:,1:app.currentFr-app.smoothwin+2),app.mst,inactiveCells); %cumulative
                        else
                            cellRank = selectCorrNeuron(DFFsmooth(:,app.currentFr-app.smoothwin+3-app.mstWin:app.currentFr-app.smoothwin+2),app.mst,inactiveCells); % sliding window  
                        end
                        allRanks(:,i/app.gap) = cellRank;
                                       
                        numElements = min(nROIs,nRanks);
                        cellRanks(:,i/app.gap) = cellRank(1:numElements); % Each column contains ids of top nRanks (e.g. 5) ranked cells during each GUI update
    
                        cellRank = cellRank(1:numElements);
                        app.topRankedCellIDs = cellRank;

                        %% Tuning analysis
      
                        if app.TuningAnalysisCheckBox.Value % Run this if the tuning analysis checkbox is selected
                            
                            if mod(i,30*app.gap)==0
                                
                                if isempty(app.JiSignalInfo)
                                    tuningInfo = getTuningRealTimeFunction(DFFsmooth(:,1:app.currentFr-app.smoothwin+2),app.exptVars,app.psignalFile,app.thorSyncFile);
                                else
                                    tuningInfo = getTuningRealTimeFunction_Ji(DFFsmooth(:,1:app.currentFr-app.smoothwin+2),app.exptVars,app.JiSignalInfo);
                                end
                                if isempty(cc)
                                    cc(1,:,:) = jet(numel(tuningInfo.kHzVals));
                                end
                                
                                set(ptc,'CData',cc(:,tuningInfo.BFval,:));
                                
                                if ~colorbarCreated
                                    cc1 = jet(numel(tuningInfo.kHzVals));
                                    colormap(app.UIAxes7,cc1)
                                    stepsize = 1/numel(tuningInfo.kHzVals);
                                    cb.Ticks = stepsize/2:stepsize:1-stepsize/2;
                                    cb.TickLabels = tuningInfo.kHzVals;
                                    colorbarCreated = 1;
                                    
                                end
                                app.kHzLabel.Visible = 'on';
                                cb.Visible = 'on';
                                
                            end
                            
                        else
                            cb.Visible = 'off';
                            app.kHzLabel.Visible = 'off';
                            set(ptc,'CData',(newDFF(:,3)-lowerLim_patch)./((upperLim_patch-lowerLim_patch))); % update DF/F values in grayscale patches
                        end
    
                        %% Synchrony analysis (Babadi Lab)
    
                        if app.SynchronyAnalysisCheckBox.Value % Run this if the synchrony analysis checkbox is selected                        
                            
                            
                            if mod(i,30*app.gap)==0
                                
                                frameID = i + app.last_init_frame; % don't use app.currentFr that will increase during the analysis
                                % spike deconvolution of top 10 ranked neurons
                                syncAnalysisWinSize = 900; % number of frames considered at a time for spike deconvolution + synchrony analysis
                                cellIDtop10 = allRanks(1:9,i/app.gap);
    
                                F10 = app.rawFluo(cellIDtop10,frameID-syncAnalysisWinSize+1:frameID); % fluorescence traces of top10 neurons
                                
    
                                % synchrony analysis
                               
                                Rmax = 4;
                                Wi = 10;
                                Wa = 15;
                                Ws = syncAnalysisWinSize/(Wi*Wa);
                                [JstatIm,ii] = SyncAnalysisOnline(F10,Wi,Wa,Ws,Rmax);
    
                                % Plot the Jstats to indicate the order of synchrony
    
                                % 'redblue' colormap to use instead of colormap('redblue') function that generates a blank figure
    
                                cmap = redblue(256); 
                                
    %                             set(syncMap,'CData', kron(JstatIm(2:end,:), ones(1,1)));
                                app.UIAxes9.Visible = 'on';
                                set(app.UIAxes9,'Colormap', cmap);
                                imagesc(app.UIAxes9, kron(JstatIm(2:end,:), ones(1,1)), [-1 1]); %colormap(app.UIAxes9,'redblue');
                                yticks(app.UIAxes9, 1:Rmax-1 ); yticklabels(app.UIAxes9, num2str( [2:Rmax]' ) );
                                tspacing = Wi;
                                T0 = (ii-Ws)*Wi*Wa;
                                xlim(app.UIAxes9,[0, syncAnalysisWinSize-T0]/Wa); xticks(app.UIAxes9,[0:tspacing*Wa:(syncAnalysisWinSize-T0)]/Wa); xticklabels(app.UIAxes9,num2str([T0:tspacing*Wa:syncAnalysisWinSize]') );                           
                                
                            end
                            
                        else
                            app.UIAxes9.Visible = 'off';
                        end
                    
                        % DZ 030522
                        % cellRank --> column vector of top ranked cell ids. length could be < 5 (because of removing bad ROIs)
                        
                        for idx = 1:length(cellRank)
                            set(neuron(idx),'XData',app.xx(:,cellRank(idx)),'YData',app.yy(:,cellRank(idx)));
                        end
                        
                        if app.selectedID
                            if ~ismember(app.selectedID,app.selectedCellList)
                                app.groupId(app.selectedID) = str2double(app.GroupIDDropDown.Value);
                                app.selectedCellList = [app.selectedCellList app.selectedID];
                                app.selectedCellsAll = [app.selectedCellsAll app.selectedID];
                                app.selectedCellFrameID = [app.selectedCellFrameID app.currentFr];
                                set(selected(app.selectedID),'XData',app.xx(:,app.selectedID),'YData',app.yy(:,app.selectedID),'Color', colors(app.groupId(app.selectedID)));
                            else
                                app.selectedCellList = setdiff(app.selectedCellList,app.selectedID);
                                set(selected(app.selectedID),'XData',NaN,'YData',NaN);
                                app.hidden = app.selectedID;
                                app.groupId(app.selectedID) = 0;
                            end
                            app.selectedID = 0; 
                        end
                    
                        if app.loadCorrelated % load x, y coordinates of top ranked cells
                            xc = app.xcRaw(cellRank);
                            yc = app.ycRaw(cellRank);
                            group = ones(length(cellRank),1);
                            app.UITable.Data = table(xc,yc,group);
                            app.loadCorrelated = 0;
                        end
                        
                        if app.loadSelected % load x, y coordinates of the selected cells
                            xc = app.xcRaw(app.selectedCellList);
                            yc = app.ycRaw(app.selectedCellList);
                            group = app.groupId(app.selectedCellList);
                            app.UITable_2.Data = table(xc,yc,group);
                            app.loadSelected = 0;
                        end
                    
                    end
                                       
                    for idx = 1:length(cellRank)
                        set(h1(idx), {'YData'}, num2cell(offSet*(idx-1) + DFFsmooth(cellRank(idx),app.currentFr-app.smoothwin-app.displayWin+3:app.currentFr-app.smoothwin+2),2));
                    end                  
                    
                    if ~isempty(app.selectedCellList)
                        for idx = 1:length(app.selectedCellList)
                            set(h2(app.selectedCellList(idx)), {'YData'}, num2cell(1.5*offSet*(mod(idx-1,10)) + DFFsmooth(app.selectedCellList(idx),app.currentFr-app.smoothwin-app.displayWin+3:app.currentFr-app.smoothwin+2),2));
                        end  
                    end
                    
                    % Remove the graph of the recently deselected cell
                    
                    if ~isempty(app.hidden)
                        set(h2(app.hidden),'YData', NaN);
                        app.hidden = [];
                    end
                                        
                     
                    drawnow limitrate; % update GUI figures and plots
                    
                end            
    
    
                i = i + 1;
    
            end

            if i == 1
                f = app.NeuroARTUIFigure;
                mess = "No frame was generated in allowed time";
                title = "Missing real time input";    
                uialert(f, mess, title,'CloseFcn',@(h,e) close(f));
                return;
            end
            
            tstop = toc(totStart)

            app.selectedCellList = unique(app.selectedCellList); % remove duplicates
            hiddenCells = app.hidden;
            
            assignin("base","selectedCellList",app.selectedCellList);
            assignin("base","selectedCellsAll",app.selectedCellsAll);
            assignin("base","selectedCellFrameID",app.selectedCellFrameID);
%             assignin("base","hiddenCells",hiddenCells)
            assignin("base","DFFsmooth",DFFsmooth);
            assignin("base","cellRanks",cellRanks);
            assignin("base","allRanks",allRanks);
            assignin("base","F",app.rawFluo);
%             assignin("base","mstWin_new",app.mstWin);
%             assignin("base","groupId",app.groupId);
%             evalin('base', ['save(''', app.exptId ''')']);
            
            if colorbarCreated
                assignin("base","tuningInfo",tuningInfo);
            end

            unloadlibrary('PiUsb'); % unload library for USB shutter control
            
            promptMessage = sprintf('Save TIFs with labelled cells? (Optional)');
            button = questdlg(promptMessage, 'Real-time analysis completed', 'Yes', 'No', 'Yes');
            if strcmpi(button, 'Yes')
                lastFrame = saveLabelledNeurons(cellRanks(:,2:end),app.selectedCellList,hiddenCells,app.rawFluo,app.xcRaw,app.ycRaw,app.norm_meanIMG,app.numInitFrames,app.r,app.gap,app.exptId);
                promptMessage = sprintf('Images saved successfully!');
                msgbox(promptMessage,'Success','help');
            end
                    
%             pause(2);
                    
%             promptMessage = sprintf('Do you want to save tuning analysis data?');
%             button = questdlg(promptMessage, 'Real-time analysis completed', 'Yes', 'No', 'Yes');
%             if strcmpi(button, 'No')
%                 return; % Or break or continue
%             else
%                 [BFval, BFkHzVals] = saveTuningData(DFFsmooth,app.exptVars,app.psignalFile,app.thorSyncFile,app.JiSignalInfo, app.xcRaw,app.ycRaw,app.norm_meanIMG,app.numInitFrames,app.r,app.gap,app.exptId);
%                 assignin("base","BFval",BFval);
%                 assignin("base","BFkHzVals",BFkHzVals);
%                 promptMessage = sprintf('Images saved successfully!');
%                 msgbox(promptMessage,'Success','help');
%             end
                
        end

        % Button pushed function: StimulateButton
        function StimulateButtonPushed(app, event)
            

            if app.SelectforstimulationCheckBox.Value && app.SelectforstimulationCheckBox_2.Value
                cellIDs_selected = find(app.groupId == 1); % hard coded for now. change this to pick the selected groupID for stimulation
                cellIDs_correlated = app.topRankedCellIDs;
                cellIDs = union(cellIDs_correlated,cellIDs_selected); % to avoid duplicates
            elseif app.SelectforstimulationCheckBox_2.Value
                cellIDs = find(app.groupId == 1); % hard coded for now. change this to pick the selected groupID for stimulation
            elseif app.SelectforstimulationCheckBox.Value
                cellIDs = app.topRankedCellIDs;
            else
                return;
            end
    
            cellIDs_group2 = find(app.groupId == 2); % cell IDs of group 2 if available

            target_cells = horzcat(app.xcRaw(cellIDs),app.ycRaw(cellIDs));
            target_cells_group2 = horzcat(app.xcRaw(cellIDs_group2),app.ycRaw(cellIDs_group2));

            if size(target_cells_group2,1) > 0 % In the case of stimulation of two groups (one phase mask per group)
                nTargets = 2;
            else
                nTargets = size(target_cells,1);
            end

            stimParams = struct();
            stimParams.seqMode = app.EnableSequenceModeCheckBox.Value;
            stimParams.interval = app.IntervalmsEditField.Value;
            stimParams.niter = app.IterationsEditField.Value;

            if ~(stimParams.seqMode) % In sequence mode these calculations are done once the user clicks the "download to SLM" button           
                
                stimParams.VMode = app.VortexModeEditField.Value;
                stimParams.VLens = app.VirtualLensEditField.Value;
                stimParams.GS = app.GSIterationsEditField.Value;
                stimParams.yShift = app.XShiftEditField.Value;
                stimParams.xShift = app.YShiftEditField.Value;
                stimParams.dll_name = app.dll_name;
                stimParams.board_number = app.board_number;
                
                assignin("base","target_cells",target_cells);
                writematrix(target_cells,'stimulationTargets.csv');
            end

            if app.SLM_ON && app.SLM == "BNS" % Boulder Nonlinear Systems SLM
                if stimParams.seqMode
                    disp('seqMode');

                    timerObj = timer('Name','MyTimer',               ...
                       'Period',stimParams.interval/1000,            ... 
                       'StartDelay',0,                 ... 
                       'TasksToExecute',stimParams.niter,           ... 
                       'ExecutionMode','fixedSpacing', ...
                       'TimerFcn',@(~,~)stimulate_RT(nTargets,app.dll_name,app.board_number)); 

                    start(timerObj);

%                     prfcn = parfeval(backgroundPool,@stimulate_RT,1,nTargets,app.dll_name,app.board_number);
%                     j = batch(@stimulate_RT,1,{nTargets,app.dll_name,app.board_number});
%                     stimulate_RT(nTargets,app.dll_name,app.board_number);

%                     for iter = 1:8
%                         calllib('PiUsb', 'piSetShutterState', 1, app.stimShutter); % open USB shutter
%                         for idx = 1:nTargets
%                             seq = idx*ones(1,15);
%                             target_sequence_error = calllib(app.dll_name, 'Load_target_sequence_list', app.board_number, 3, seq);
%                             pause(0.3);
%                         end
%                         calllib('PiUsb', 'piSetShutterState', 0, app.stimShutter); % close USB shutter
%                         pause(10);
%                     end
                    
                    %seq = [3,3,3,3,3,3,3,3,3,3,3,3,3,3];
                    %target_sequence_error1 = calllib(app.dll_name, 'Load_target_sequence_list', app.board_number, 3, seq);
                    %disp('seqMode');pause(0.05);
                    
%                     calllib('PiUsb', 'piSetShutterState', 0, app.stimShutter); % close USB shutter

                else
                    [~,msk,I] = stimulateBNS_SLM(target_cells, stimParams); % send commands to BNS SLM
                    for iter = 1:stimParams.niter
                        calllib('PiUsb', 'piSetShutterState', 1, app.stimShutter); % open USB shutter
                        pause(0.03); % stim. duration = 300ms (should be a user specified parameter in future)
                        calllib('PiUsb', 'piSetShutterState', 0, app.stimShutter); % close USB shutter
                        pause(stimParams.interval/1000); % specified by the user in milliseconds (convert to seconds)
                    end

                    imshow(msk,'Parent', app.UIAxes8);
                    imshow(I,'Parent', app.UIAxes8_2);
                end

            elseif app.SLM == "PrairieLink"

%                 app.StimulationBNSTab. % how to diable BNS tab in this mode?
     
                stimParams.DStim = app.DStimulationDropDown.Value;
                stimParams.zOffset = app.ZoffsetEditField.Value;
                stimParams.isSpiral = 'False';
                if app.PatternDropDown.Value == "Spiral"
                    stimParams.isSpiral = 'True';
                end
                stimParams.spiralSize = app.SpiralSizeSlider.Value;
                stimParams.spiralRevolutions = app.SpiralRevolutionsSpinner.Value;
                stimParams.trigger = app.TriggerDropDown.Value;
                stimParams.triggerCount = app.TriggerCountSpinner.Value;
                stimParams.delay = app.DelaymsEditField_2.Value;
                stimParams.setSize = min(app.NoofTargetsSpinner.Value,nTargets);
                stimParams.duration = app.DurationEditField.Value;
                stimParams.LaserName = app.LaserNameEditField.Value;
                stimParams.power = app.LaserPowerEditField.Value;
                stimParams.IPaddress = app.IPaddressEditField.Value;
                stimulatePrairieLink(target_cells, stimParams, app.exptVars.dimX, app.exptVars.dimY); % send commands to PrairieLink
                
            else
                disp('SLM not available');
            end
            evalin('base', ['save(''', 'targets_', app.exptId ''',', '''', 'target_cells' ''')']); % save the coordinates to a .mat file
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if app.UseacumulativewindowCheckBox.Value
                app.mstWin = 0; % The value that indicates cumulative window
            else
                wsize = floor(app.CorrelationAnalysisWindowsecondsSlider.Value *app.imagingFreq);
                if wsize < app.currentFr + 3 - app.smoothwin
                    app.mstWin = wsize;
                else
                    promptMessage = sprintf('Window size for correlation analysis exceeds the available number of frames');
                    msgbox(promptMessage,'Error','error');
                end
            end
        end

        % Value changed function: RedChannelCheckBox
        function RedChannelCheckBoxValueChanged(app, event)
            value = app.RedChannelCheckBox.Value;
            if value
               set(app.meanIMGhandle, 'CData', app.norm_meanRedIMG);
            else
               set(app.meanIMGhandle, 'CData', app.norm_meanIMG);
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.loadCorrelated = 1;
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            app.loadSelected = 1;
        end

        % Button pushed function: AutoSelectButton
        function AutoSelectButtonPushed(app, event)
            dimX = app.dimXEditField.Value;
            dimY = app.dimYEditField.Value;
            minDim = min(dimX,dimY);
            if app.NoofwindowsizesDropDown.Value == '5'
                d = ones(5,2);
                d(:,1) = round([minDim/4; minDim/2; minDim*2/3; minDim*5/6; minDim]);
            elseif app.NoofwindowsizesDropDown.Value == '8'
                d = ones(8,2);   
                d(:,1) = round(minDim/8 *(1:8)');
            elseif app.NoofwindowsizesDropDown.Value == "10"
                d = ones(10,2);   
                d(:,1) = round(minDim/10 *(1:10)');
            else
                d = ones(25,2);   
                d(:,1) = round(minDim/25 *(1:25)');
            end
            
            app.UITable_3.Data = d;
        end

        % Button pushed function: EvaluateScalingButton
        function EvaluateScalingButtonPushed(app, event)
            app.UIAxes_2.Visible = 0;
            app.UIAxes_3.Visible = 0;
            d = app.UITable_3.Data;
            [ind,~] = find(d(:,2)); % Only the non-zero entries in the selection column will be considered
            params = struct;
            
            params.start = app.startFrameEditField.Value;
            params.stop = app.stopFrameEditField.Value;

            F_win = app.rawFluo(:,params.start:min(app.currentFr, params.stop)); % fluorescence traces of all neurons within the considered window
            nCells = size(F_win,1);
            s = zeros(size(F_win,2),nCells);  
            for cellInd = 1:nCells
                [~, s(:,cellInd), ~] = deconvolveCa(F_win(cellInd,:)');
            end
                                        
            Spks = double(s > 0.005); % convert to binary, 1: spike 0: no spike

            params.DFF = Spks'; % NxT deconvolved spikes
            params.coordinates = app.coordinates; % [x,y] coordinate matrix

            params.avgStepSize = app.averagingFactorEditField.Value;
            params.dimX = app.dimXEditField.Value;
            params.dimY = app.dimYEditField.Value;
            params.micronsPerPixel = app.micronsPerPixelEditField.Value;
            params.distStepSize = app.DistancestepsizeEditField.Value;
            params.type = app.typeDropDown.Value;
            params.frameRate = app.FrameRateEditField.Value;
            params.minNeuronsPerWin = app.MinneuronsperwindowEditField.Value;
            params.windowSizes = d(ind,1);
            params.overlapFactor = app.OverlapfactorEditField.Value;
            params.correlations = app.CorrelationsDropDown.Value;
            params.centerWindow = app.CenterthewindowCheckBox.Value;
            
            [indices_norm,normalizedCorr_norm]  = getAvgcorrelations(params);
            maxInd_norm = zeros(length(ind)+1,1); % add (0,0) point
            for i = 1:length(ind)
                [~,maxInd_norm(i+1)] = max(cumsum(normalizedCorr_norm(:,i)));
            end
            maxInd_norm = maxInd_norm + 1;
            winSizes = [0; params.windowSizes]; % to include (0,0) point
            
            % Plot Correlation length vs Windoe Size for original data
            plot(app.UIAxes_2,winSizes*params.micronsPerPixel,indices_norm(maxInd_norm)*params.micronsPerPixel,'o-');
            xlabel(app.UIAxes_2,'Window size (um)');
            ylabel(app.UIAxes_2,'Correlation Length (um)');
            xticks(app.UIAxes_2,'auto');
            yticks(app.UIAxes_2,'auto');
            
            % Plot C(r) for original data
            plot(app.UIAxes_3,indices_norm(2:51)*params.micronsPerPixel,normalizedCorr_norm(1:50,:),'.-');
            xlabel(app.UIAxes_3,'r (um)');
            ylabel(app.UIAxes_3,'C(r)');
            xticks(app.UIAxes_3,'auto');
            yticks(app.UIAxes_3,'auto');
            
            legends = cell(1,length(winSizes)-1);
            for i = 1:length(winSizes)-1
                legends{i} = ['W = ', num2str(winSizes(i+1))];
            end
            legend(app.UIAxes_3,legends)

            % Calculate Kappa C
            
            Wi = winSizes(2:end); % window sizes
            rWi = indices_norm(maxInd_norm); % coorelation length for each window size
            rWi = rWi(2:end); % remove the value corresponding to Wi = 0
            
            ai = zeros(length(Wi),1);
            bi = zeros(length(Wi),1);
            
            for idx = 1:length(Wi)
                ai(idx) = rWi(idx)/Wi(idx);
            end
            
            for idx = 2:length(Wi)
                bi(idx) = (rWi(idx) - rWi(1))/log(Wi(idx)/Wi(1));
            end
            
            bi = bi(2:end); % avoid the first element, otherwise there will be a division by zero for the first bi value
            
            CVs = std(bi)/mean(bi);
            CVc = std(ai)/mean(ai);
            
            Kc = (CVs^2)/(CVc^2 + CVs^2);
            
            if ~isnan(Kc)
                app.KcEditField.Value = Kc;
            else
                app.KcEditField.Value = -1;
            end
            
%             csvwrite('indices_norm.csv',indices_norm);
%             csvwrite('normalizedCorr_norm.csv',normalizedCorr_norm);
%             csvwrite('maxInd_norm.csv',maxInd_norm);
%             if app.ReshufflingCheckBox.Value
%                 csvwrite('indices_reshuf.csv',indices_reshuf);
%                 csvwrite('normalizedCorr_reshuf.csv',normalizedCorr_reshuf);
%                 csvwrite('maxInd_reshuf.csv',maxInd_reshuf);
%             end
%             if app.CircularshiftCheckBox.Value
%                 csvwrite('indices_circ.csv',indices_circ);
%                 csvwrite('normalizedCorr_circ.csv',normalizedCorr_circ);
%                 csvwrite('maxInd_circ.csv',maxInd_circ);
%             end
            
            app.UIAxes_2.Visible = 1;
            app.UIAxes_3.Visible = 1;
            
            hold(app.UIAxes_2,'off');
            hold(app.UIAxes_3,'off');
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % Determine which tab was selected
            selectedTab = app.TabGroup.SelectedTab;

            disablePrairieLink = 0;
            disableBNS = 0;

            if app.SLM == "BNS"
                disablePrairieLink = 1;
            elseif app.SLM == "PrairieLink"
                disableBNS = 1;
            end

            % Each "case" is the title of a Tab and contains the pair checkbox.
            switch selectedTab.Title
                case 'Real Time Analysis'
                    disableTab = 0;
                case 'Box Scaling'
                    disableTab = 0;
                case 'Stimulation (BNS)'
                    disableTab = disableBNS;
                case 'PrairieLink'
                    disableTab = disablePrairieLink;
                otherwise
                    error('Unknown tab: %s.',selectedTab.Title)
            end

            % If wrong stimulation tab is selected, return to the previously selected tab
            if disableTab 
                app.TabGroup.SelectedTab = event.OldValue;
            end
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.debugMode = 1;
            app.debugIndex = 0;
            app.ClickStarttoidentifycellsthatrespondtophotostimulationLabel.Text = "Photostimulation started, please wait ...";
            
            if app.SelectforstimulationCheckBox.Value && app.SelectforstimulationCheckBox_2.Value
                cellIDs_selected = find(app.groupId == 1); % hard coded for now. change this to pick the selected groupID for stimulation
                cellIDs_correlated = app.topRankedCellIDs;
                cellIDs = union(cellIDs_correlated,cellIDs_selected); % to avoid duplicates
            elseif app.SelectforstimulationCheckBox_2.Value
                cellIDs = find(app.groupId == 1); % hard coded for now. change this to pick the selected groupID for stimulation
            elseif app.SelectforstimulationCheckBox.Value
                cellIDs = app.topRankedCellIDs;
            else
                return;
            end

            app.target_cellIDs = cellIDs;
            responses = zeros(length(cellIDs),1); % Initial condition: none of the cells respond to stimulation
            app.UITable2.Data = table(app.xcRaw(cellIDs),app.ycRaw(cellIDs),responses);

            params = struct();
            params.DStim = app.DStimulationDropDown.Value;
            params.zOffset = app.ZoffsetEditField.Value;
            params.isSpiral = 'False';
            if app.PatternDropDown.Value == "Spiral"
                params.isSpiral = 'True';
            end
            params.spiralSize = app.SpiralSizeSlider.Value;
            params.spiralRevolutions = app.SpiralRevolutionsSpinner.Value;
            params.trigger = app.TriggerDropDown.Value;
            params.triggerCount = app.TriggerCountSpinner.Value;
            params.delay = app.DelaymsEditField_2.Value;
            params.setSize = min(app.NoofTargetsSpinner.Value,nTargets);
            params.duration = app.DurationEditField.Value;
            params.LaserName = app.LaserNameEditField.Value;
            params.power = app.LaserPowerEditField.Value;
            params.IPaddress = app.IPaddressEditField.Value;

            app.stimulationParams = params; % parameters to be used during stimulation

        end

        % Button pushed function: DownloadtoSLMButton
        function DownloadtoSLMButtonPushed(app, event)
            if app.SelectforstimulationCheckBox.Value && app.SelectforstimulationCheckBox_2.Value
                cellIDs_selected = find(app.groupId == 1); % hard coded for now. change this to pick the selected groupID for stimulation
                cellIDs_correlated = app.topRankedCellIDs;
                cellIDs = union(cellIDs_correlated,cellIDs_selected); % to avoid duplicates
            elseif app.SelectforstimulationCheckBox_2.Value
                cellIDs = find(app.groupId == 1); % hard coded for now. change this to pick the selected groupID for stimulation
            elseif app.SelectforstimulationCheckBox.Value
                cellIDs = app.topRankedCellIDs;
            else
                return;
            end

            cellIDs_group2 = find(app.groupId == 2); % cell IDs of group 2 if available

            target_cells = horzcat(app.xcRaw(cellIDs),app.ycRaw(cellIDs));
            target_cells_group2 = horzcat(app.xcRaw(cellIDs_group2),app.ycRaw(cellIDs_group2));
            
            nTargets = size(target_cells,1);
            
            stimParams.VMode = app.VortexModeEditField.Value;
            stimParams.VLens = app.VirtualLensEditField.Value;
            stimParams.GS = app.GSIterationsEditField.Value;
            stimParams.yShift = app.XShiftEditField.Value;
            stimParams.xShift = app.YShiftEditField.Value;
            stimParams.delay = app.IntervalmsEditField.Value;
            stimParams.dll_name = app.dll_name;
            stimParams.board_number = app.board_number;
            stimParams.seqMode = app.EnableSequenceModeCheckBox.Value;
            
            assignin("base","target_cells",target_cells);
            writematrix(target_cells,'stimulationTargets.csv');

            if app.SLM_ON && app.SLM == "BNS" % Boulder Nonlinear Systems
                if stimParams.seqMode
%                     phaseMasks = cell(1,size(target_cells,1));
%                     for i = 1:size(target_cells,1)
%                         [phaseMasks{i},~,~] = stimulateBNS_SLM(target_cells(i,:), stimParams); % send commands to BNS SLM
%                     end
                    if size(target_cells_group2,1) > 0 % if there are two groups for stimulation

                        nTargets = 2; % hard coded for now, assuming there are only two groups

                        [phaseMask1,msk1,I1] = stimulateBNS_SLM(target_cells, stimParams); % send commands to BNS SLM
                        pause(1)
                        [phaseMask2,~,~] = stimulateBNS_SLM(target_cells_group2, stimParams); % send commands to BNS SLM
                        pause(1)

                        image_size = size(phaseMask1);
    
                        images_flat(:,1) = reshape(phaseMask1,image_size(1)*image_size(2),1);
                        images_flat(:,2) = reshape(phaseMask2,image_size(1)*image_size(2),1);
                        
                        image_sequence = reshape(images_flat,image_size(1)*image_size(2)*2,1); % stimulate 2 groups of cells in sequence
    
                        disp('Downloading images... please wait.');
                        max_OD_frames = 20;
                        download_error = calllib(app.dll_name, 'Download_image_sequence', app.board_number, nTargets, image_sequence, max_OD_frames);
                        if download_error
                            disp('Failed to download images.');
                        else
                            disp('Done downloading images.');
                            app.StimulateButton.Enable = 1;
                            imshow(msk1,'Parent', app.UIAxes8); % for now only display the phase mask corresponding to the first vortex
                            imshow(I1,'Parent', app.UIAxes8_2);
                        end

                    else
                    
                        [phaseMask1,msk1,I1] = stimulateBNS_SLM(target_cells(1,:), stimParams); % send commands to BNS SLM
                        pause(1)
                        [phaseMask2,~,~] = stimulateBNS_SLM(target_cells(2,:), stimParams); % send commands to BNS SLM
                        pause(1)
                        if nTargets > 2
                            [phaseMask3,~,~] = stimulateBNS_SLM(target_cells(3,:), stimParams); % send commands to BNS SLM
                            if nTargets == 4
                                [phaseMask4,~,~] = stimulateBNS_SLM(target_cells(4,:), stimParams); % send commands to BNS SLM
                            end
                        end
    
                        image_size = size(phaseMask1);
    
                        images_flat(:,1) = reshape(phaseMask1,image_size(1)*image_size(2),1);
                        images_flat(:,2) = reshape(phaseMask2,image_size(1)*image_size(2),1);
    
                        if nTargets == 4
                            images_flat(:,3) = reshape(phaseMask3,image_size(1)*image_size(2),1);
                            images_flat(:,4) = reshape(phaseMask4,image_size(1)*image_size(2),1);
                            image_sequence = reshape(images_flat,image_size(1)*image_size(2)*4,1); % stimulate 4 cells in sequence
                        elseif nTargets == 3
                            images_flat(:,3) = reshape(phaseMask3,image_size(1)*image_size(2),1);
                            image_sequence = reshape(images_flat,image_size(1)*image_size(2)*3,1); % stimulate 3 cells in sequence
                        else
                            image_sequence = reshape(images_flat,image_size(1)*image_size(2)*2,1); % stimulate 2 cells in sequence
                        end
    
                        disp('Downloading images... please wait.');
                        max_OD_frames = 20;
                        download_error = calllib(app.dll_name, 'Download_image_sequence', app.board_number, nTargets, image_sequence, max_OD_frames);
                        if download_error
                            disp('Failed to download images.');
                        else
                            disp('Done downloading images.');
                            app.StimulateButton.Enable = 1;
                            imshow(msk1,'Parent', app.UIAxes8); % for now only display the phase mask corresponding to the first vortex
                            imshow(I1,'Parent', app.UIAxes8_2);
                        end
                    end
                end
            end
        end

        % Value changed function: EnableSequenceModeCheckBox
        function EnableSequenceModeCheckBoxValueChanged(app, event)
            value = app.EnableSequenceModeCheckBox.Value;
            if value
                app.StimulateButton.Enable = 0;
            else
                app.StimulateButton.Enable = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NeuroARTUIFigure = uifigure('Visible', 'off');
            app.NeuroARTUIFigure.Name = 'NeuroART';
            app.UIAxes = uiaxes(app.NeuroARTUIFigure);
            app.UIAxes.Visible = 'off';
            app.UIAxes7 = uiaxes(app.NeuroARTUIFigure);
            app.UIAxes7.XTick = [];
            app.UIAxes7.YTick = [];
            app.UIAxes9 = uiaxes(app.NeuroARTUIFigure);
            title(app.UIAxes9, 'Synchrony')
            xlabel(app.UIAxes9, 'Time')
            ylabel(app.UIAxes9, 'Order')
            app.UIAxes9.XTick = [];
            app.UIAxes9.YTick = [];
            app.UIAxes9.Visible = 'off';
            app.TabGroup = uitabgroup(app.NeuroARTUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.RealTimeAnalysisTab = uitab(app.TabGroup);
            app.RealTimeAnalysisTab.Title = 'Real Time Analysis';
            app.Panel_4 = uipanel(app.RealTimeAnalysisTab);
            app.UIAxes_best = uiaxes(app.Panel_4);
            title(app.UIAxes_best, 'DF/F (Most correlated  cells)')
            app.UIAxes_best.YTick = [];
            app.SelectforstimulationCheckBox = uicheckbox(app.Panel_4);
            app.SelectforstimulationCheckBox.Text = 'Select for stimulation';
            app.SelectTopSpinnerLabel = uilabel(app.Panel_4);
            app.SelectTopSpinnerLabel.Text = 'Select Top';
            app.SelectTopSpinner = uispinner(app.Panel_4);
            app.SelectTopSpinner.Limits = [3 Inf];
            app.SelectTopSpinner.ValueDisplayFormat = '%.0f';
            app.SelectTopSpinner.Value = 5;
            app.Panel_3 = uipanel(app.RealTimeAnalysisTab);
            app.UIAxes_selected = uiaxes(app.Panel_3);
            title(app.UIAxes_selected, 'DF/F (Selected cells)')
            app.UIAxes_selected.YTick = [];
            app.SelectforstimulationCheckBox_2 = uicheckbox(app.Panel_3);
            app.SelectforstimulationCheckBox_2.Text = 'Select for stimulation';
            app.SelectforstimulationCheckBox_2.Value = true;
            app.Panel_2 = uipanel(app.RealTimeAnalysisTab);
            app.UITable = uitable(app.Panel_2);
            app.UITable.ColumnName = {'X'; 'Y'; 'ID'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false true];
            app.LoadButton = uibutton(app.Panel_2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Panel_5 = uipanel(app.RealTimeAnalysisTab);
            app.UITable_2 = uitable(app.Panel_5);
            app.UITable_2.ColumnName = {'X'; 'Y'; 'ID'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = [false false true];
            app.LoadButton_2 = uibutton(app.Panel_5, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.BoxScalingTab = uitab(app.TabGroup);
            app.BoxScalingTab.Title = 'Box Scaling';
            app.UIAxes_2 = uiaxes(app.BoxScalingTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.BoxScalingTab);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.Visible = 'off';
            app.HzLabel = uilabel(app.BoxScalingTab);
            app.HzLabel.Text = 'Hz';
            app.UITable_3 = uitable(app.BoxScalingTab);
            app.UITable_3.ColumnName = {'Window Sizes'; 'Select'};
            app.UITable_3.RowName = {};
            app.UITable_3.ColumnEditable = true;
            app.CenterthewindowCheckBox = uicheckbox(app.BoxScalingTab);
            app.CenterthewindowCheckBox.Text = 'Center the window';
            app.AutoSelectButton = uibutton(app.BoxScalingTab, 'push');
            app.AutoSelectButton.ButtonPushedFcn = createCallbackFcn(app, @AutoSelectButtonPushed, true);
            app.AutoSelectButton.Text = 'Auto Select';
            app.EvaluateScalingButton = uibutton(app.BoxScalingTab, 'push');
            app.EvaluateScalingButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateScalingButtonPushed, true);
            app.EvaluateScalingButton.Text = 'Evaluate Scaling';
            app.startFrameEditFieldLabel = uilabel(app.BoxScalingTab);
            app.startFrameEditFieldLabel.Text = 'startFrame';
            app.startFrameEditField = uieditfield(app.BoxScalingTab, 'numeric');
            app.startFrameEditField.Limits = [1 Inf];
            app.startFrameEditField.ValueDisplayFormat = '%.0f';
            app.startFrameEditField.Value = 1;
            app.stopFrameEditFieldLabel = uilabel(app.BoxScalingTab);
            app.stopFrameEditFieldLabel.Text = 'stopFrame';
            app.stopFrameEditField = uieditfield(app.BoxScalingTab, 'numeric');
            app.stopFrameEditField.Limits = [100 Inf];
            app.stopFrameEditField.ValueDisplayFormat = '%.0f';
            app.stopFrameEditField.Value = 2000;
            app.averagingFactorEditFieldLabel = uilabel(app.BoxScalingTab);
            app.averagingFactorEditFieldLabel.Text = 'averagingFactor';
            app.averagingFactorEditField = uieditfield(app.BoxScalingTab, 'numeric');
            app.averagingFactorEditField.Limits = [1 Inf];
            app.averagingFactorEditField.ValueDisplayFormat = '%.0f';
            app.averagingFactorEditField.Value = 1;
            app.dimXEditFieldLabel = uilabel(app.BoxScalingTab);
            app.dimXEditFieldLabel.Text = 'dimX';
            app.dimXEditField = uieditfield(app.BoxScalingTab, 'numeric');
            app.dimXEditField.Limits = [1 Inf];
            app.dimXEditField.ValueDisplayFormat = '%.0f';
            app.dimXEditField.Value = 512;
            app.dimYEditFieldLabel = uilabel(app.BoxScalingTab);
            app.dimYEditFieldLabel.Text = 'dimY';
            app.dimYEditField = uieditfield(app.BoxScalingTab, 'numeric');
            app.dimYEditField.Limits = [1 Inf];
            app.dimYEditField.ValueDisplayFormat = '%.0f';
            app.dimYEditField.Value = 512;
            app.micronsPerPixelEditFieldLabel = uilabel(app.BoxScalingTab);
            app.micronsPerPixelEditFieldLabel.Text = 'micronsPerPixel';
            app.micronsPerPixelEditField = uieditfield(app.BoxScalingTab, 'numeric');
            app.micronsPerPixelEditField.Limits = [0 Inf];
            app.micronsPerPixelEditField.Value = 0.8;
            app.typeDropDownLabel = uilabel(app.BoxScalingTab);
            app.typeDropDownLabel.Text = 'type';
            app.typeDropDown = uidropdown(app.BoxScalingTab);
            app.typeDropDown.Items = {'Linear', 'Log'};
            app.typeDropDown.Value = 'Linear';
            app.FrameRateEditFieldLabel = uilabel(app.BoxScalingTab);
            app.FrameRateEditFieldLabel.Text = 'Frame Rate ';
            app.FrameRateEditField = uieditfield(app.BoxScalingTab, 'numeric');
            app.FrameRateEditField.Limits = [1 Inf];
            app.FrameRateEditField.Value = 30;
            app.DistancestepsizeEditFieldLabel = uilabel(app.BoxScalingTab);
            app.DistancestepsizeEditFieldLabel.Text = 'Distance step size';
            app.DistancestepsizeEditField = uieditfield(app.BoxScalingTab, 'numeric');
            app.DistancestepsizeEditField.Limits = [1 Inf];
            app.DistancestepsizeEditField.ValueDisplayFormat = '%.0f';
            app.DistancestepsizeEditField.Value = 5;
            app.CorrelationsDropDownLabel = uilabel(app.BoxScalingTab);
            app.CorrelationsDropDownLabel.Text = 'Correlations';
            app.CorrelationsDropDown = uidropdown(app.BoxScalingTab);
            app.CorrelationsDropDown.Items = {'Instantaneous', 'Pearson'};
            app.CorrelationsDropDown.Tooltip = {'Recommended option: instantaneous'};
            app.CorrelationsDropDown.Value = 'Instantaneous';
            app.NoofwindowsizesDropDownLabel = uilabel(app.BoxScalingTab);
            app.NoofwindowsizesDropDownLabel.Text = 'No: of window sizes';
            app.NoofwindowsizesDropDown = uidropdown(app.BoxScalingTab);
            app.NoofwindowsizesDropDown.Items = {'5', '8', '10', '25'};
            app.NoofwindowsizesDropDown.Value = '5';
            app.OverlapfactorEditFieldLabel = uilabel(app.BoxScalingTab);
            app.OverlapfactorEditFieldLabel.Text = 'Overlap factor';
            app.OverlapfactorEditField = uieditfield(app.BoxScalingTab, 'numeric');
            app.OverlapfactorEditField.Limits = [0 1];
            app.OverlapfactorEditField.Value = 0.5;
            app.MinneuronsperwindowEditFieldLabel = uilabel(app.BoxScalingTab);
            app.MinneuronsperwindowEditFieldLabel.Text = 'Min neurons per window';
            app.MinneuronsperwindowEditField = uieditfield(app.BoxScalingTab, 'numeric');
            app.MinneuronsperwindowEditField.Limits = [2 100];
            app.MinneuronsperwindowEditField.ValueDisplayFormat = '%.0f';
            app.MinneuronsperwindowEditField.Value = 5;
            app.KcEditFieldLabel = uilabel(app.BoxScalingTab);
            app.KcEditFieldLabel.Text = 'Kc';
            app.KcEditField = uieditfield(app.BoxScalingTab, 'numeric');
            app.StimulationBNSTab = uitab(app.TabGroup);
            app.StimulationBNSTab.Title = 'Stimulation (BNS)';
            app.UIAxes8 = uiaxes(app.StimulationBNSTab);
            title(app.UIAxes8, 'Phase Mask')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes8.XTick = [];
            app.UIAxes8.YTick = [];
            app.UIAxes8_2 = uiaxes(app.StimulationBNSTab);
            title(app.UIAxes8_2, 'Simulated Pattern')
            zlabel(app.UIAxes8_2, 'Z')
            app.UIAxes8_2.XTick = [];
            app.UIAxes8_2.YTick = [];
            app.VortexModeEditFieldLabel = uilabel(app.StimulationBNSTab);
            app.VortexModeEditFieldLabel.Text = 'Vortex Mode';
            app.VortexModeEditField = uieditfield(app.StimulationBNSTab, 'numeric');
            app.VortexModeEditField.Value = 7;
            app.GSIterationsEditFieldLabel = uilabel(app.StimulationBNSTab);
            app.GSIterationsEditFieldLabel.Text = 'GS Iterations';
            app.GSIterationsEditField = uieditfield(app.StimulationBNSTab, 'numeric');
            app.GSIterationsEditField.Value = 3;
            app.VirtualLensEditFieldLabel = uilabel(app.StimulationBNSTab);
            app.VirtualLensEditFieldLabel.Text = 'Virtual Lens';
            app.VirtualLensEditField = uieditfield(app.StimulationBNSTab, 'numeric');
            app.VirtualLensEditField.Value = 400;
            app.PhaseMaskCenteringLabel = uilabel(app.StimulationBNSTab);
            app.PhaseMaskCenteringLabel.Text = 'Phase Mask Centering:';
            app.XShiftEditFieldLabel = uilabel(app.StimulationBNSTab);
            app.XShiftEditFieldLabel.Text = 'XShift';
            app.XShiftEditField = uieditfield(app.StimulationBNSTab, 'numeric');
            app.XShiftEditField.Value = 22;
            app.YShiftEditFieldLabel = uilabel(app.StimulationBNSTab);
            app.YShiftEditFieldLabel.Text = 'YShift';
            app.YShiftEditField = uieditfield(app.StimulationBNSTab, 'numeric');
            app.YShiftEditField.Value = 172;
            app.PixelsLabel = uilabel(app.StimulationBNSTab);
            app.PixelsLabel.Text = 'Pixels';
            app.PixelsLabel_2 = uilabel(app.StimulationBNSTab);
            app.PixelsLabel_2.Text = 'Pixels';
            app.IntervalmsEditFieldLabel = uilabel(app.StimulationBNSTab);
            app.IntervalmsEditFieldLabel.Text = 'Interval (ms)';
            app.IntervalmsEditField = uieditfield(app.StimulationBNSTab, 'numeric');
            app.IntervalmsEditField.Limits = [0 Inf];
            app.IntervalmsEditField.Value = 2000;
            app.EnableSequenceModeCheckBox = uicheckbox(app.StimulationBNSTab);
            app.EnableSequenceModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableSequenceModeCheckBoxValueChanged, true);
            app.EnableSequenceModeCheckBox.Text = 'Enable Sequence Mode';
            app.DownloadtoSLMButton = uibutton(app.StimulationBNSTab, 'push');
            app.DownloadtoSLMButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadtoSLMButtonPushed, true);
            app.DownloadtoSLMButton.Text = 'Download to SLM';
            app.IterationsEditFieldLabel = uilabel(app.StimulationBNSTab);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.StimulationBNSTab, 'numeric');
            app.IterationsEditField.Limits = [1 Inf];
            app.IterationsEditField.ValueDisplayFormat = '%.0f';
            app.IterationsEditField.Value = 50;
            app.PrairieLinkTab = uitab(app.TabGroup);
            app.PrairieLinkTab.Title = 'PrairieLink';
            app.NoofTargetsSpinnerLabel = uilabel(app.PrairieLinkTab);
            app.NoofTargetsSpinnerLabel.Text = 'No: of Targets';
            app.NoofTargetsSpinner = uispinner(app.PrairieLinkTab);
            app.NoofTargetsSpinner.Limits = [1 100];
            app.NoofTargetsSpinner.ValueDisplayFormat = '%.0f';
            app.NoofTargetsSpinner.Value = 1;
            app.DStimulationDropDownLabel = uilabel(app.PrairieLinkTab);
            app.DStimulationDropDownLabel.Text = '3D Stimulation';
            app.DStimulationDropDown = uidropdown(app.PrairieLinkTab);
            app.DStimulationDropDown.Items = {'True', 'False'};
            app.DStimulationDropDown.Value = 'False';
            app.ZoffsetEditFieldLabel = uilabel(app.PrairieLinkTab);
            app.ZoffsetEditFieldLabel.Text = 'Z offset';
            app.ZoffsetEditField = uieditfield(app.PrairieLinkTab, 'numeric');
            app.PatternDropDownLabel = uilabel(app.PrairieLinkTab);
            app.PatternDropDownLabel.Text = 'Pattern';
            app.PatternDropDown = uidropdown(app.PrairieLinkTab);
            app.PatternDropDown.Items = {'Spiral', 'Donut', 'Other'};
            app.PatternDropDown.Value = 'Spiral';
            app.SpiralSizeSliderLabel = uilabel(app.PrairieLinkTab);
            app.SpiralSizeSliderLabel.Text = 'Spiral Size';
            app.SpiralSizeSlider = uislider(app.PrairieLinkTab);
            app.SpiralSizeSlider.Limits = [0 1];
            app.SpiralRevolutionsSpinnerLabel = uilabel(app.PrairieLinkTab);
            app.SpiralRevolutionsSpinnerLabel.Text = 'Spiral Revolutions';
            app.SpiralRevolutionsSpinner = uispinner(app.PrairieLinkTab);
            app.SpiralRevolutionsSpinner.Limits = [1 Inf];
            app.SpiralRevolutionsSpinner.ValueDisplayFormat = '%.0f';
            app.SpiralRevolutionsSpinner.Value = 1;
            app.TriggerDropDownLabel = uilabel(app.PrairieLinkTab);
            app.TriggerDropDownLabel.Text = 'Trigger';
            app.TriggerDropDown = uidropdown(app.PrairieLinkTab);
            app.TriggerDropDown.Items = {'None', 'PFI1', 'PFI8', 'TrigIn'};
            app.TriggerDropDown.Value = 'None';
            app.TriggerCountSpinnerLabel = uilabel(app.PrairieLinkTab);
            app.TriggerCountSpinnerLabel.Enable = 'off';
            app.TriggerCountSpinnerLabel.Text = 'Trigger Count';
            app.TriggerCountSpinner = uispinner(app.PrairieLinkTab);
            app.TriggerCountSpinner.Limits = [0 Inf];
            app.TriggerCountSpinner.ValueDisplayFormat = '%.0f';
            app.TriggerCountSpinner.Enable = 'off';
            app.DelaymsEditField_2Label = uilabel(app.PrairieLinkTab);
            app.DelaymsEditField_2Label.Text = 'Delay (ms)';
            app.DelaymsEditField_2 = uieditfield(app.PrairieLinkTab, 'numeric');
            app.LaserNameEditFieldLabel = uilabel(app.PrairieLinkTab);
            app.LaserNameEditFieldLabel.Text = 'Laser Name';
            app.LaserNameEditField = uieditfield(app.PrairieLinkTab, 'text');
            app.LaserNameEditField.Value = 'PC-Stim';
            app.LaserPowerEditFieldLabel = uilabel(app.PrairieLinkTab);
            app.LaserPowerEditFieldLabel.Text = 'Laser Power';
            app.LaserPowerEditField = uieditfield(app.PrairieLinkTab, 'numeric');
            app.LaserPowerEditField.Limits = [0 Inf];
            app.LaserPowerEditField.Value = 50;
            app.DurationEditFieldLabel = uilabel(app.PrairieLinkTab);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.PrairieLinkTab, 'numeric');
            app.IPaddressEditFieldLabel = uilabel(app.PrairieLinkTab);
            app.IPaddressEditFieldLabel.Text = 'IP address';
            app.IPaddressEditField = uieditfield(app.PrairieLinkTab, 'text');
            app.IPaddressEditField.Value = '10.99.5.51';
            app.IdentifyStimulatableCellsPanel = uipanel(app.PrairieLinkTab);
            app.IdentifyStimulatableCellsPanel.Title = 'Identify Stimulatable Cells';
            app.UITable2 = uitable(app.IdentifyStimulatableCellsPanel);
            app.UITable2.ColumnName = {'X'; 'Y'; 'Response'};
            app.UITable2.RowName = {};
            app.PhotostimulationSwitchLabel = uilabel(app.IdentifyStimulatableCellsPanel);
            app.PhotostimulationSwitchLabel.Text = 'Photostimulation';
            app.PhotostimulationSwitch = uiswitch(app.IdentifyStimulatableCellsPanel, 'slider');
            app.PhotostimulationSwitch.Items = {'Sequential', 'Simultaneous'};
            app.PhotostimulationSwitch.Value = 'Sequential';
            app.StartButton = uibutton(app.IdentifyStimulatableCellsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ClickStarttoidentifycellsthatrespondtophotostimulationLabel = uilabel(app.IdentifyStimulatableCellsPanel);
            app.ClickStarttoidentifycellsthatrespondtophotostimulationLabel.Text = 'Click "Start" to identify cells that respond to  photostimulation';
            app.CellRankings1Red2Green3Blue45MagentaSelectedcellsYellowLabel = uilabel(app.NeuroARTUIFigure);
            app.CellRankings1Red2Green3Blue45MagentaSelectedcellsYellowLabel.Text = 'Cell Rankings: 1) Red 2) Green 3) Blue 4)5) Magenta;   Selected cells - Yellow';
            app.RedChannelCheckBox = uicheckbox(app.NeuroARTUIFigure);
            app.RedChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @RedChannelCheckBoxValueChanged, true);
            app.RedChannelCheckBox.Text = 'Red Channel';
            app.TuningAnalysisCheckBox = uicheckbox(app.NeuroARTUIFigure);
            app.TuningAnalysisCheckBox.Text = 'Tuning Analysis';
            app.StartRealTimeAnalysisButton = uibutton(app.NeuroARTUIFigure, 'push');
            app.StartRealTimeAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StartRealTimeAnalysisButtonPushed, true);
            app.StartRealTimeAnalysisButton.Text = 'Start Real Time Analysis';
            app.StimulateButton = uibutton(app.NeuroARTUIFigure, 'push');
            app.StimulateButton.ButtonPushedFcn = createCallbackFcn(app, @StimulateButtonPushed, true);
            app.StimulateButton.Text = 'Stimulate';
            app.FrameNoEditFieldLabel = uilabel(app.NeuroARTUIFigure);
            app.FrameNoEditFieldLabel.Text = 'Frame No:';
            app.FrameNoEditField = uieditfield(app.NeuroARTUIFigure, 'numeric');
            app.FrameNoEditField.ValueDisplayFormat = '%.0f';
            app.FrameNoEditField.Editable = 'off';
            app.kHzLabel = uilabel(app.NeuroARTUIFigure);
            app.kHzLabel.Text = 'kHz';
            app.AdjustwindowsizePanel = uipanel(app.NeuroARTUIFigure);
            app.AdjustwindowsizePanel.Title = 'Adjust window size';
            app.CorrelationAnalysisWindowsecondsSliderLabel = uilabel(app.AdjustwindowsizePanel);
            app.CorrelationAnalysisWindowsecondsSliderLabel.Text = 'Correlation Analysis Window (seconds)';
            app.CorrelationAnalysisWindowsecondsSlider = uislider(app.AdjustwindowsizePanel);
            app.CorrelationAnalysisWindowsecondsSlider.Limits = [5 30];
            app.CorrelationAnalysisWindowsecondsSlider.MajorTicks = [5 10 15 20 25 30];
            app.CorrelationAnalysisWindowsecondsSlider.Value = 5;
            app.UseacumulativewindowCheckBox = uicheckbox(app.AdjustwindowsizePanel);
            app.UseacumulativewindowCheckBox.Text = 'Use a cumulative window';
            app.UseacumulativewindowCheckBox.Value = true;
            app.ApplyButton = uibutton(app.AdjustwindowsizePanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.GroupIDDropDownLabel = uilabel(app.NeuroARTUIFigure);
            app.GroupIDDropDownLabel.Text = 'Group ID';
            app.GroupIDDropDown = uidropdown(app.NeuroARTUIFigure);
            app.GroupIDDropDown.Items = {'1', '2', '3'};
            app.GroupIDDropDown.Value = '1';
            app.SynchronyAnalysisCheckBox = uicheckbox(app.NeuroARTUIFigure);
            app.SynchronyAnalysisCheckBox.Text = 'Synchrony Analysis';
            % Show the figure after all components are created
            app.NeuroARTUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = realTimeApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NeuroARTUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeuroARTUIFigure)
        end
    end
end